classdef ciCameraWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ISETComputationalImagingCameraControlPanelUIFigure  matlab.ui.Figure
        camInfo                 matlab.ui.control.Table
        CameraLabel             matlab.ui.control.Label
        sceneInfo               matlab.ui.control.Table
        opticsInfo              matlab.ui.control.Table
        sensorInfo              matlab.ui.control.Table
        SceneLabel              matlab.ui.control.Label
        OpticsLabel             matlab.ui.control.Label
        SensorLabel             matlab.ui.control.Label
        objMotionInfo           matlab.ui.control.Table
        camMotionInfo           matlab.ui.control.Table
        HDRFramesLabel          matlab.ui.control.Label
        HDRFramesSlider         matlab.ui.control.Slider
        CameraControlsLabel     matlab.ui.control.Label
        BurstFramesLabel        matlab.ui.control.Label
        BurstFramesSlider       matlab.ui.control.Slider
        FocusFramesSliderLabel  matlab.ui.control.Label
        FocusFramesSlider       matlab.ui.control.Slider
        ImagePreviewButton      matlab.ui.control.Button
        TakePictureButton       matlab.ui.control.Button
        PhotoModeDropDownLabel  matlab.ui.control.Label
        PhotoModeDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        ourCamera; % a camera of some form
        ourScene;       
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, ourCamera, ourScene)
            
            % Check if we're already running?
            %runningApp = getRunningApp(app);
            %not how to use this cleanly to allow us to refresh if it
            %happens?

            % Eventually we should implement property editing:
            camProps = ourCamera.showInfo();
            ourCamData = cell2table(camProps);
            app.camInfo.Data = ourCamData;
            
            sceneProps = ourScene.showInfo();
            ourSceneData = cell2table(sceneProps);
            app.sceneInfo.Data = ourSceneData;
            
            % just show first moving object
            if ~isempty(ourScene.objectMotion)
                motionInfo = [];
                for ii = 1:numel(ourScene.objectMotion)
                    motionInfo = [motionInfo ourScene.objectMotion{ii}];
                end
                app.objMotionInfo.Data = cell2table(motionInfo);
            end  

            if ~isempty(ourScene.cameraMotion)
                app.camMotionInfo.Data = cell2table(ourScene.cameraMotion{1});
            end
            % simple conversion to a struct isn't very helpful:
            %ourCamStruct = struct(ourCamera);
            %app.camInfo.Data = struct2table(ourCamStruct);
            app.CameraLabel.Text = "Camera Type: " + class(ourCamera);
            
            %ourSceneStruct = struct(ourScene);
            %app.sceneInfo.Data = struct2table(ourSceneStruct);
            app.SceneLabel.Text = "Scene: " + ourScene.sceneName;

            ourOptics = ourCamera.cmodules(1).oi.optics;
            iePTable(ourOptics, 'uitable', app.opticsInfo,'format', 'embed');
                        
            ourSensor = ourCamera.cmodules(1).sensor;
            iePTable(ourSensor, 'uitable', app.sensorInfo,'format', 'embed');
            
            % fill in camera control defaults
            if exist('ourCamera.numBurstFrames')
                app.BurstFramesSlider.Value = ourCamera.numBurstFrames;
            end
            if exist('ourCamera.numBurstFrames')
                app.HDRFramesSlider.Value = ourCamera.numHDRFrames;
            end
            if exist('ourCamera.numBurstFrames')
                app.FocusFramesSlider.Value = ourCamera.numFocusFrames;
            end
            
            app.PhotoModeDropDown.Items = ourCamera.supportedIntents;
            app.PhotoModeDropDown.Value = ourCamera.supportedIntents{1};

        end

        % Button pushed function: TakePictureButton
        function TakePictureButtonPushed(app, event)
            app.ourCamera.TakePicture(app.PhotoModeDropDown.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ISETComputationalImagingCameraControlPanelUIFigure = uifigure('Visible', 'off');
            app.ISETComputationalImagingCameraControlPanelUIFigure.Name = 'ISET Computational Imaging Camera Control Panel';
            app.ISETComputationalImagingCameraControlPanelUIFigure.Icon = 'Camera-n-brush-pd.png';
            app.camInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.camInfo.ColumnName = {'Property'; 'Default Value'};
            app.camInfo.RowName = {};
            app.CameraLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.CameraLabel.Text = 'Camera:';
            app.sceneInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.sceneInfo.ColumnName = {'Property'; 'Value'};
            app.sceneInfo.RowName = {};
            app.opticsInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.opticsInfo.ColumnName = {'Property'; 'Value'};
            app.opticsInfo.RowName = {};
            app.sensorInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.sensorInfo.ColumnName = {'Property'; 'Value'};
            app.sensorInfo.RowName = {};
            app.SceneLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.SceneLabel.Text = 'Scene:';
            app.OpticsLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.OpticsLabel.Text = 'Optics:';
            app.SensorLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.SensorLabel.Text = 'Sensor:';
            app.objMotionInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.objMotionInfo.ColumnName = {'Object Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.objMotionInfo.RowName = {};
            app.camMotionInfo = uitable(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.camMotionInfo.ColumnName = {'Camera Name'; 'Translation (x y z)'; 'Rotation (degrees)'};
            app.camMotionInfo.RowName = {};
            app.HDRFramesLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.HDRFramesLabel.Text = 'HDR Frames';
            app.HDRFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.HDRFramesSlider.Limits = [3 9];
            app.HDRFramesSlider.MajorTicks = [3 5 7 9];
            app.HDRFramesSlider.MinorTicks = [];
            app.HDRFramesSlider.Value = 3;
            app.CameraControlsLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.CameraControlsLabel.Text = 'Camera Controls:';
            app.BurstFramesLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.BurstFramesLabel.Text = 'Burst Frames';
            app.BurstFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.BurstFramesSlider.Limits = [3 9];
            app.BurstFramesSlider.MajorTicks = [3 5 7 9];
            app.BurstFramesSlider.MinorTicks = [];
            app.BurstFramesSlider.Value = 3;
            app.FocusFramesSliderLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.FocusFramesSliderLabel.Text = {'Focus'; 'Frames'};
            app.FocusFramesSlider = uislider(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.FocusFramesSlider.Limits = [3 12];
            app.FocusFramesSlider.MajorTicks = [3 5 7 9 11];
            app.FocusFramesSlider.MinorTicks = [];
            app.FocusFramesSlider.Value = 3;
            app.ImagePreviewButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.ImagePreviewButton.Text = 'Image Preview';
            app.TakePictureButton = uibutton(app.ISETComputationalImagingCameraControlPanelUIFigure, 'push');
            app.TakePictureButton.ButtonPushedFcn = createCallbackFcn(app, @TakePictureButtonPushed, true);
            app.TakePictureButton.Icon = 'Crystal_Clear_device_camera.png';
            app.TakePictureButton.Text = 'Take Picture';
            app.PhotoModeDropDownLabel = uilabel(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDownLabel.Text = 'Photo Mode:';
            app.PhotoModeDropDown = uidropdown(app.ISETComputationalImagingCameraControlPanelUIFigure);
            app.PhotoModeDropDown.Items = {'Auto', 'HDR', 'Burst', 'Focus'};
            app.PhotoModeDropDown.Value = 'Auto';
            % Show the figure after all components are created
            app.ISETComputationalImagingCameraControlPanelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ciCameraWindow(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ISETComputationalImagingCameraControlPanelUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ISETComputationalImagingCameraControlPanelUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ISETComputationalImagingCameraControlPanelUIFigure)
        end
    end
end