classdef displayWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menuFile                     matlab.ui.container.Menu
        menuLoadDisplayModel         matlab.ui.container.Menu
        menuSaveDisplayModel         matlab.ui.container.Menu
        menuFileLoadImage            matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuClose                    matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditRenameDisplay        matlab.ui.container.Menu
        menuEditNew                  matlab.ui.container.Menu
        menuDeleteCurrent            matlab.ui.container.Menu
        menuCopyCurrent              matlab.ui.container.Menu
        menuEditChangeFontSize       matlab.ui.container.Menu
        menuPlot                     matlab.ui.container.Menu
        menuPlotDisplaySPD           matlab.ui.container.Menu
        menuPlotGamma                matlab.ui.container.Menu
        subpixeImage                 matlab.ui.container.Menu
        menuDisplay                  matlab.ui.container.Menu
        menuCRT                      matlab.ui.container.Menu
        menuLCD                      matlab.ui.container.Menu
        menuLCDVerticalStripesRGB    matlab.ui.container.Menu
        menuLCDVerticalStripesBGR    matlab.ui.container.Menu
        menuLCDHorizontalStripesRGB  matlab.ui.container.Menu
        menuLCDHorizontalStripesBGR  matlab.ui.container.Menu
        menuOLED                     matlab.ui.container.Menu
        menuAnalyze                  matlab.ui.container.Menu
        menuPlotGamut                matlab.ui.container.Menu
        menuPlotGamut3D              matlab.ui.container.Menu
        menuAnalyzeScene             matlab.ui.container.Menu
        menuAnalyzeSceneSubpixel     matlab.ui.container.Menu
        menuAnalyzeOutputImage       matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuProductHelp              matlab.ui.container.Menu
        menuAbout                    matlab.ui.container.Menu
        uipanelEdit                  matlab.ui.container.Panel
        txtMaxLum                    matlab.ui.control.Label
        editMaxLum                   matlab.ui.control.EditField
        editVar                      matlab.ui.control.EditField
        txtPosVar                    matlab.ui.control.Label
        txtAmp                       matlab.ui.control.Label
        editPPI                      matlab.ui.control.EditField
        uipanelSubpixel              matlab.ui.container.Panel
        imagePixel                   matlab.ui.control.Image
        uipanelSummary               matlab.ui.container.Panel
        txtSummary                   matlab.ui.control.Label
        SelecteddisplayPanel         matlab.ui.container.Panel
        popupSelectDisplay           matlab.ui.control.DropDown
        txtMessage                   matlab.ui.control.Label
        Panel_2                      matlab.ui.container.Panel
        pushbutton9                  matlab.ui.control.Button
        displayImage                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function SubpixelScale_Callback(app, hObject, eventdata, handles)
            % hObject    handle to SubpixelScale (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            answer = inputdlg('New samples per pixel (10 ~ 30)');
            if isempty(answer), return; end
            answer = str2double(answer);
            assert(answer > 9 && answer < 31, 'samples per pixel out of range');
            d = vcGetObject('display', ind);
            psfs = displayGet(d, 'psfs');
            d = displaySet(d, 'psfs', imresize(psfs, [answer answer]));
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end


        %{
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            % app.figure1.KeyPressFcn = 'ctDisplayKeyPress';
        end
        %}
    end
    
    methods (Access = public)
        
        function refresh(app)
            % Fill a display window fields based on the current display
            % information
            
            % If there is no display, add the default to the database
            thisD = ieGetObject('DISPLAY');
            if isempty(thisD)
                thisD = displayCreate;
                ieAddObject(thisD);
            end

            % Fill in the dropdown from the display name
            thisName = displayGet(thisD,'name');
            idx = strcmpi(thisName,app.popupSelectDisplay.Items);
            app.popupSelectDisplay.Value = app.popupSelectDisplay.Items{idx};

            % Fill in the ext boxes on right
            xyz = displayGet(thisD, 'white xyz');
            app.editMaxLum.Value = num2str(xyz(2));
            app.editVar.Value = 'N/A';
            app.editPPI.Value = sprintf('%s dpi',num2str(displayGet(thisD, 'dpi')));            
            
            % Description box on upper right
            % This will become a table, like the other windows.
            app.txtSummary.Text = displayDescription(thisD);
            
            %% Redraw the main image
            displayShowImage(thisD, app);
            
            %% Set subpixel figure
            dixel_image = displayGet(thisD, 'dixel image');
            if ~isempty(dixel_image)
                if size(dixel_image,3) == 3
                    % Image is RGB
                    app.imagePixel.ImageSource =  dixel_image / max(dixel_image(:));
                else
                    % Image is an SPD
                    gam = 1;
                    wave = displayGet(thisD, 'wave');
                    photons = vcReadImage(dixel_image, 'rgb', thisD);
                    app.imagePixel.ImageSource = imageSPD(photons, wave, gam, [], [], -1);
                end
            end
        end
        
        % -----------
        function displayClose(app)
            % Clear the database and delete the app
            displayW = ieSessionGet('display window');
            if isequal(app,displayW)
                % If this app is the one in the database, clear it.
                ieSessionSet('display window',[]);
            end
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function displayWindow_OpeningFcn(app, varargin)
            
            % see if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.displayImage.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.displayImage.Toolbar = [];
            end
            % Initialize with the reflectance display
            thisD = ieGetObject('display');
            if isempty(thisD)
                thisD = displayCreate;
                ieAddObject(thisD);
            end
            thisName = displayGet(thisD,'name');
            idx = strcmpi(app.popupSelectDisplay.Items,thisName);
            app.popupSelectDisplay.Value = app.popupSelectDisplay.Items{idx};
            
            % Refresh
            app.refresh;
        end

        % Value changed function: editMaxLum
        function editMaxLum_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit box for max luminance
            thisD = ieGetObject('display');

            xyz = displayGet(thisD, 'white xyz');
            newLum = str2double(app.editMaxLum.Value);
            spd = displayGet(thisD, 'spd') * newLum / xyz(2);
            thisD = displaySet(thisD, 'spd', spd);
            ieReplaceObject(thisD);
            app.refresh;
        end

        % Value changed function: editPPI
        function editPPI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            newPPI = str2double(get(handles.editPPI, 'String'));
            d = displaySet(d, 'dpi', newPPI);
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Value changed function: editVar
        function editVar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            disp('Not yet implemented')
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            displayClose(app);
        end

        % Menu selected function: menuAbout
        function menuAbout_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            warning('NYI')
        end

        % Menu selected function: menuAnalyzeOutputImage
        function menuAnalyzeOutputImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Output image (by units)
            ind = vcGetSelectedObject('display');
            d = vcGetObject('display', ind);
            if isempty(ind), disp('No display selected'); return; end
            
            h = get(handles.axes1, 'Children');
            I = get(h, 'CData');
            
            % select region
            set(handles.txtMessage, 'String', 'Select Region in Image');
            rect = floor(getrect);
            set(handles.txtMessage, 'String', 'Original Image');
            
            % round width and height to multiple of pixelsperdixel
            ppd = displayGet(d, 'pixels per dixel');
            rect(3:4) = ppd .* ceil(rect(3:4) ./ ppd);
            I = I(rect(2): rect(2)+rect(4)-1, rect(1) : rect(1) + rect(3)-1, :);
            
            % create a scene
            % For rgb image, we can use displayCompute the generate the output image
            % However, the output image will have same number of primaries as the
            % display (could be more than 3) and then imshow will not work. Thus, we
            % compute the scene radiance and get the srgb of the scene and show to the
            % window
            scene = sceneFromFile(I, 'rgb', [], d, [], 1, [], [20 20]);
            ieNewGraphWin; imshow(sceneGet(scene, 'rgb image'));
            
        end

        % Menu selected function: menuAnalyzeSceneSubpixel
        function menuAnalyzeSceneSubpixel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Scene
            % Opens up a Scene Window
            ind = vcGetSelectedObject('display');
            d = vcGetObject('display', ind);
            
            % Get current image
            global vcSESSION;
            if isfield(vcSESSION, 'imgData')
                I = vcSESSION.imgData;
            else
                warning('No image set');
                return;
            end
            
            % select region
            set(handles.txtMessage, 'String', 'Select Region in Image');
            rect = floor(getrect);
            set(handles.txtMessage, 'String', 'Original Image');
            % round width and height to multiple of pixelsperdixel
            ppd = displayGet(d, 'pixels per dixel');
            rect(3:4) = ppd .* ceil(rect(3:4) ./ ppd);
            I = I(rect(2): rect(2)+rect(4)-1, rect(1) : rect(1) + rect(3)-1, :);
            
            % Get scene info
            overSample = displayGet(d, 'over sample');
            answer = inputdlg({'Scene name', 'Up sample (col)', 'Up sample (row)'}, ...
                'Scene Info', 1, ...
                {sprintf('scene-%s', displayGet(d, 'name')), ...
                num2str(overSample(1)), ...
                num2str(overSample(2))});
            if isempty(answer)
                return;
            else
                name = answer{1};
                overSample = [str2double(answer{2}) str2double(answer{3})];
            end
            
            % Generate scene
            if isempty(I), disp('No image set'); return; end
            scene = sceneFromFile(I, 'rgb', [], d, [], 1, [], overSample);
            scene = sceneSet(scene, 'name', name);
            
            % Compute scene size
            [r,c,~] = size(I);
            vDist = sceneGet(scene, 'distance');
            fov   = atand(max(r,c) * displayGet(d, 'metersperdot')/vDist);
            scene = sceneSet(scene, 'fov', fov);
            
            
            % Show scene window
            vcAddAndSelectObject('scene', scene);
            sceneWindow;
        end

        % Menu selected function: menuAnalyzeScene
        function menuAnalyzeScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Scene
            % Opens up a Scene Window
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            
            % Get current image
            global vcSESSION;
            if isfield(vcSESSION, 'imgData')
                I = vcSESSION.imgData;
            else
                warning('No image set');
                return;
            end
            
            % Get scene info
            name = inputdlg({'Scene name'}, 'Scene Info', 1, ...
                {sprintf('scene-%s', displayGet(d, 'name'))});
            if isempty(name), return; end
            
            % Generate scene
            if isempty(I),  warning('No image set'); return; end
            scene = sceneFromFile(I, 'rgb', [], d, [], 0);
            scene = sceneSet(scene, 'name', name{1});
            
            % Compute scene size
            [r,c,~] = size(I);
            vDist = sceneGet(scene, 'distance');
            fov   = atand(max(r,c)  * displayGet(d,'metersperdot')/vDist);
            scene = sceneSet(scene, 'fov', fov);
            
            % Show scene window
            vcAddAndSelectObject('scene', scene);
            sceneWindow;
        end

        % Menu selected function: menuCRT
        function menuCRT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | CRT
            d = displayCreate('CRT-Dell');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuClose
        function menuClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Close
            displayClose(app);
        end

        % Menu selected function: menuCopyCurrent
        function menuCopyCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            dname = displayGet(d, 'name');
            d = displaySet(d, 'name', ['copy - ' dname]);
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuDeleteCurrent
        function menuDeleteCurrent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete
            vcDeleteSelectedObject('display');
            refresh(app);

        end

        % Menu selected function: menuEditChangeFontSize
        function menuEditChangeFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % ctFontChangeSize(handles.figure1);
            answer = inputdlg('New Font Size (7~25)');
            if isempty(answer), return; end
            answer = str2double(answer);
            assert(answer > 6 && answer < 26, 'Front size out of range');
            set(handles.text61, 'FontSize', answer);
            set(handles.uipanelSummary, 'FontSize', answer);
            set(handles.txtSummary, 'FontSize', answer);
            set(handles.txtMaxLum, 'FontSize', answer);
            set(handles.txtPosVar, 'FontSize', answer);
            set(handles.txtAmp, 'FontSize', answer);
            set(handles.uipanel2, 'FontSize', answer);
        end

        % Menu selected function: menuEditNew
        function menuEditNew_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | New Model
            d = displayCreate('LCD-Apple');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuEditRenameDisplay
        function menuEditRenameDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            answer = inputdlg('New Display Name');
            if isempty(answer), return; end
            d = vcGetObject('display', ind);
            d = displaySet(d, 'name', answer{1});
            vcDeleteSelectedObject('display');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuFileLoadImage
        function menuFileLoadImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load Image
            %
            fname = uigetfile('*.*', 'Choose Image File');
            if fname == 0, return; end
            I = im2double(imread(fname));
            thisD = ieGetObject('display');
            thisD = displaySet(thisD,'rgb',I);
            ieReplaceObject(thisD);
            
            % global vcSESSION;
            % vcSESSION.imgData = I;
            
            % Refresh other components
            app.refresh;
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            app.refresh;
        end

        % Menu selected function: menuLCDHorizontalStripesBGR
        function menuLCDHorizontalStripesBGR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | LCD | Vertical BGR
            % displayGD   = ctGetObject('display');
            %
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'h','bgr');
            %
            % displayGD   = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDHorizontalStripesRGB
        function menuLCDHorizontalStripesRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % displayGD   = ctGetObject('display');
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'h','rgb');
            %
            % displayGD   = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDVerticalStripesBGR
        function menuLCDVerticalStripesBGR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Display | LCD | Vertical BGR
            % displayGD  = ctGetObject('display');
            %
            % dpi = 72;
            % dSpacing = 0.001; % sample spacing in mm
            % vDisplayLCD = vDisplayCreate('lcd',dpi,dSpacing,'v','bgr');
            %
            % displayGD  = ctDisplaySet(displayGD,'vDisplay',vDisplayLCD);  % Add?  Replace?
            % ctSetObject('display', displayGD);
            % ctdpRefreshGUIWindow(hObject);
            warning('NYI');
        end

        % Menu selected function: menuLCDVerticalStripesRGB
        function menuLCDVerticalStripesRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            d = displayCreate('LCD-Dell');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuLoadDisplayModel
        function menuLoadDisplayModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Load Display
            %
            fname = uigetfile('*.mat', 'Load Display From MAT file');
            if fname == 0, return; end
            tmp = load(fname);
            if isfield(tmp, 'd'), d = tmp.d; else error('Not display file'); end
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuOLED
        function menuOLED_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuOLED (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            d = displayCreate('OLED-Sony');
            vcAddAndSelectObject('display', d);
            app.refresh;
        end

        % Menu selected function: menuPlotDisplaySPD
        function menuPlotDisplaySPD_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'spd');
        end

        % Menu selected function: menuPlotGamma
        function menuPlotGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamma');
        end

        % Menu selected function: menuPlotGamut3D
        function menuPlotGamut3D_Callback(app, event)
           
            % hObject    handle to menuPlotGamut3D (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamut3d');
        end

        % Menu selected function: menuPlotGamut
        function menuPlotGamut_Callback(app, event)
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = vcGetObject('display', ind);
            displayPlot(d, 'gamut');
        end

        % Menu selected function: menuProductHelp
        function menuProductHelp_Callback(app, event)
            warning('NYI');
        end

        % Menu selected function: menuSaveDisplayModel
        function menuSaveDisplayModel_Callback(app, event)
            % File | Save Display
            warning('Needs a lot of checking.')
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('no display selected'); return; end
            d = ieGetObject('display', ind); %#ok
            fname = uiputfile('*.mat');
            if fname == 0, return; end
            save(fname, 'd');
        end

        % Value changed function: popupSelectDisplay
        function popupSelectDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if strcmp(app.popupSelectDisplay.Value,'New')
                % Create the default scene
                ieAddObject(displayCreate);
            else
                % The scenes in Items are numbered for uniqueness of the
                % Value.
                val = find(strcmp(app.popupSelectDisplay.Items,app.popupSelectDisplay.Value));
                vcSetSelectedObject('DISPLAY',val-1);
            end
            
            % Called when the 'Selected Display' popup is chosen
            app.refresh;
        end

        % Button pushed function: pushbutton9
        function pushbutton9_Callback(app, event)
            menuAnalyzeSceneSubpixel_Callback(app);
        end

        % Menu selected function: subpixeImage
        function subpixeImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [~, ~, ~] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Sub pixel image
            ind = vcGetSelectedObject('display');
            if isempty(ind), disp('No display selected'); return; end
            d = vcGetObject('display', ind);
            psfs = displayGet(d, 'dixel image');
            if isempty(psfs)
                disp('no psfs in display model');
                return;
            else
                ieNewGraphWin;
                if size(psfs,3) == 3
                    imshow(psfs / max(psfs(:)));
                else
                    gam = 1;
                    wave = displayGet(d, 'wave');
                    photons = vcReadImage(psfs, 'rgb', d);
                    imageSPD(photons, wave, gam, [], [], 1);
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET-Display';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuLoadDisplayModel = uimenu(app.menuFile);
            app.menuLoadDisplayModel.MenuSelectedFcn = createCallbackFcn(app, @menuLoadDisplayModel_Callback, true);
            app.menuLoadDisplayModel.Text = 'Load Display';
            app.menuLoadDisplayModel.Tag = 'menuLoadDisplayModel';
            app.menuSaveDisplayModel = uimenu(app.menuFile);
            app.menuSaveDisplayModel.MenuSelectedFcn = createCallbackFcn(app, @menuSaveDisplayModel_Callback, true);
            app.menuSaveDisplayModel.Text = 'Save Display';
            app.menuSaveDisplayModel.Tag = 'menuSaveDisplayModel';
            app.menuFileLoadImage = uimenu(app.menuFile);
            app.menuFileLoadImage.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadImage_Callback, true);
            app.menuFileLoadImage.Separator = 'on';
            app.menuFileLoadImage.Text = 'Load image';
            app.menuFileLoadImage.Tag = 'menuFileLoadImage';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuClose = uimenu(app.menuFile);
            app.menuClose.MenuSelectedFcn = createCallbackFcn(app, @menuClose_Callback, true);
            app.menuClose.Separator = 'on';
            app.menuClose.Text = 'Close';
            app.menuClose.Tag = 'menuClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditRenameDisplay = uimenu(app.menuEdit);
            app.menuEditRenameDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuEditRenameDisplay_Callback, true);
            app.menuEditRenameDisplay.Text = 'Rename';
            app.menuEditRenameDisplay.Tag = 'menuEditRenameDisplay';
            app.menuEditNew = uimenu(app.menuEdit);
            app.menuEditNew.MenuSelectedFcn = createCallbackFcn(app, @menuEditNew_Callback, true);
            app.menuEditNew.Accelerator = 'N';
            app.menuEditNew.Text = 'New Display';
            app.menuEditNew.Tag = 'menuEditNew';
            app.menuDeleteCurrent = uimenu(app.menuEdit);
            app.menuDeleteCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuDeleteCurrent_Callback, true);
            app.menuDeleteCurrent.Separator = 'on';
            app.menuDeleteCurrent.Text = 'Delete';
            app.menuDeleteCurrent.Tag = 'menuDeleteCurrent';
            app.menuCopyCurrent = uimenu(app.menuEdit);
            app.menuCopyCurrent.MenuSelectedFcn = createCallbackFcn(app, @menuCopyCurrent_Callback, true);
            app.menuCopyCurrent.Text = 'Copy';
            app.menuCopyCurrent.Tag = 'menuCopyCurrent';
            app.menuEditChangeFontSize = uimenu(app.menuEdit);
            app.menuEditChangeFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditChangeFontSize_Callback, true);
            app.menuEditChangeFontSize.Text = 'Font Size';
            app.menuEditChangeFontSize.Tag = 'menuEditChangeFontSize';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlotDisplaySPD = uimenu(app.menuPlot);
            app.menuPlotDisplaySPD.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDisplaySPD_Callback, true);
            app.menuPlotDisplaySPD.Text = 'SPD Primaries ';
            app.menuPlotDisplaySPD.Tag = 'menuPlotDisplaySPD';
            app.menuPlotGamma = uimenu(app.menuPlot);
            app.menuPlotGamma.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamma_Callback, true);
            app.menuPlotGamma.Text = 'Gamma';
            app.menuPlotGamma.Tag = 'menuPlotGamma';
            app.subpixeImage = uimenu(app.menuPlot);
            app.subpixeImage.MenuSelectedFcn = createCallbackFcn(app, @subpixeImage_Callback, true);
            app.subpixeImage.Text = 'Sub-pixel PSF';
            app.subpixeImage.Tag = 'subpixeImage';
            app.menuDisplay = uimenu(app.figure1);
            app.menuDisplay.Text = 'Display';
            app.menuDisplay.Tag = 'menuDisplay';
            app.menuCRT = uimenu(app.menuDisplay);
            app.menuCRT.MenuSelectedFcn = createCallbackFcn(app, @menuCRT_Callback, true);
            app.menuCRT.Text = 'CRT';
            app.menuCRT.Tag = 'menuCRT';
            app.menuLCD = uimenu(app.menuDisplay);
            app.menuLCD.Text = 'LCD ';
            app.menuLCD.Tag = 'menuLCD';
            app.menuLCDVerticalStripesRGB = uimenu(app.menuLCD);
            app.menuLCDVerticalStripesRGB.MenuSelectedFcn = createCallbackFcn(app, @menuLCDVerticalStripesRGB_Callback, true);
            app.menuLCDVerticalStripesRGB.Text = 'Vertical Stripes RGB';
            app.menuLCDVerticalStripesRGB.Tag = 'menuLCDVerticalStripesRGB';
            app.menuLCDVerticalStripesBGR = uimenu(app.menuLCD);
            app.menuLCDVerticalStripesBGR.MenuSelectedFcn = createCallbackFcn(app, @menuLCDVerticalStripesBGR_Callback, true);
            app.menuLCDVerticalStripesBGR.Text = 'Vertical Stripes BGR';
            app.menuLCDVerticalStripesBGR.Tag = 'menuLCDVerticalStripesBGR';
            app.menuLCDHorizontalStripesRGB = uimenu(app.menuLCD);
            app.menuLCDHorizontalStripesRGB.MenuSelectedFcn = createCallbackFcn(app, @menuLCDHorizontalStripesRGB_Callback, true);
            app.menuLCDHorizontalStripesRGB.Text = 'Horizontal Stripes RGB';
            app.menuLCDHorizontalStripesRGB.Tag = 'menuLCDHorizontalStripesRGB';
            app.menuLCDHorizontalStripesBGR = uimenu(app.menuLCD);
            app.menuLCDHorizontalStripesBGR.MenuSelectedFcn = createCallbackFcn(app, @menuLCDHorizontalStripesBGR_Callback, true);
            app.menuLCDHorizontalStripesBGR.Text = 'Horizontal Stripes BGR';
            app.menuLCDHorizontalStripesBGR.Tag = 'menuLCDHorizontalStripesBGR';
            app.menuOLED = uimenu(app.menuDisplay);
            app.menuOLED.MenuSelectedFcn = createCallbackFcn(app, @menuOLED_Callback, true);
            app.menuOLED.Text = 'OLED';
            app.menuOLED.Tag = 'menuOLED';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuPlotGamut = uimenu(app.menuAnalyze);
            app.menuPlotGamut.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamut_Callback, true);
            app.menuPlotGamut.Text = 'Gamut (xy)';
            app.menuPlotGamut.Tag = 'menuPlotGamut';
            app.menuPlotGamut3D = uimenu(app.menuAnalyze);
            app.menuPlotGamut3D.MenuSelectedFcn = createCallbackFcn(app, @menuPlotGamut3D_Callback, true);
            app.menuPlotGamut3D.Text = 'Gamut (Lab)';
            app.menuPlotGamut3D.Tag = 'menuPlotGamut3D';
            app.menuAnalyzeScene = uimenu(app.menuAnalyze);
            app.menuAnalyzeScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeScene_Callback, true);
            app.menuAnalyzeScene.Separator = 'on';
            app.menuAnalyzeScene.Text = 'Scene (Pixel)';
            app.menuAnalyzeScene.Tag = 'menuAnalyzeScene';
            app.menuAnalyzeSceneSubpixel = uimenu(app.menuAnalyze);
            app.menuAnalyzeSceneSubpixel.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeSceneSubpixel_Callback, true);
            app.menuAnalyzeSceneSubpixel.Text = 'Scene (Subpixel)';
            app.menuAnalyzeSceneSubpixel.Tag = 'menuAnalyzeSceneSubpixel';
            app.menuAnalyzeOutputImage = uimenu(app.menuAnalyze);
            app.menuAnalyzeOutputImage.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeOutputImage_Callback, true);
            app.menuAnalyzeOutputImage.Text = 'Subpixel image';
            app.menuAnalyzeOutputImage.Tag = 'menuAnalyzeOutputImage';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuProductHelp = uimenu(app.menuHelp);
            app.menuProductHelp.MenuSelectedFcn = createCallbackFcn(app, @menuProductHelp_Callback, true);
            app.menuProductHelp.Text = 'Product help';
            app.menuProductHelp.Tag = 'menuProductHelp';
            app.menuAbout = uimenu(app.menuHelp);
            app.menuAbout.MenuSelectedFcn = createCallbackFcn(app, @menuAbout_Callback, true);
            app.menuAbout.Text = 'About';
            app.menuAbout.Tag = 'menuAbout';
            app.uipanelEdit = uipanel(app.figure1);
            app.uipanelEdit.Title = 'Edit';
            app.uipanelEdit.Tag = 'uipanel1';
            app.txtMaxLum = uilabel(app.uipanelEdit);
            app.txtMaxLum.Tag = 'txtMaxLum';
            app.txtMaxLum.Text = 'Max lum (cd/m2)';
            app.editMaxLum = uieditfield(app.uipanelEdit, 'text');
            app.editMaxLum.ValueChangedFcn = createCallbackFcn(app, @editMaxLum_Callback, true);
            app.editMaxLum.Tag = 'editMaxLum';
            app.editMaxLum.Value = '100';
            app.editVar = uieditfield(app.uipanelEdit, 'text');
            app.editVar.ValueChangedFcn = createCallbackFcn(app, @editVar_Callback, true);
            app.editVar.Tag = 'editVar';
            app.editVar.Value = '0';
            app.txtPosVar = uilabel(app.uipanelEdit);
            app.txtPosVar.Tag = 'txtPosVar';
            app.txtPosVar.Tooltip = 'Sub-pixel position variance in microns';
            app.txtPosVar.Text = 'Position var';
            app.txtAmp = uilabel(app.uipanelEdit);
            app.txtAmp.Tag = 'txtAmp';
            app.txtAmp.Tooltip = 'Pixel Per Inch';
            app.txtAmp.Text = 'Display PPI';
            app.editPPI = uieditfield(app.uipanelEdit, 'text');
            app.editPPI.ValueChangedFcn = createCallbackFcn(app, @editPPI_Callback, true);
            app.editPPI.Tag = 'editPPI';
            app.editPPI.Value = '0';
            app.uipanelSubpixel = uipanel(app.figure1);
            app.uipanelSubpixel.Title = 'SubPixel';
            app.uipanelSubpixel.Tag = 'uipanel2';
            app.imagePixel = uiimage(app.uipanelSubpixel);
            app.uipanelSummary = uipanel(app.figure1);
            app.uipanelSummary.Title = 'Summary';
            app.uipanelSummary.Tag = 'uipanelSummary';
            app.txtSummary = uilabel(app.uipanelSummary);
            app.txtSummary.Tag = 'txtSummary';
            app.txtSummary.Text = '';
            app.SelecteddisplayPanel = uipanel(app.figure1);
            app.SelecteddisplayPanel.Title = 'Selected display';
            app.popupSelectDisplay = uidropdown(app.SelecteddisplayPanel);
            app.popupSelectDisplay.Items = {'New', 'reflectance', 'LCD-Apple', 'Dell-Chevron', 'LCD-Samsung-RGBW', 'OLED-Samsung', 'OLED-Samsung-Note3'};
            app.popupSelectDisplay.ValueChangedFcn = createCallbackFcn(app, @popupSelectDisplay_Callback, true);
            app.popupSelectDisplay.Tag = 'popupSelectDisplay';
            app.popupSelectDisplay.Value = 'New';
            app.txtMessage = uilabel(app.SelecteddisplayPanel);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.Panel_2 = uipanel(app.figure1);
            app.pushbutton9 = uibutton(app.Panel_2, 'push');
            app.pushbutton9.ButtonPushedFcn = createCallbackFcn(app, @pushbutton9_Callback, true);
            app.pushbutton9.Tag = 'pushbutton9';
            app.pushbutton9.Text = 'Convert to Scene';
            app.displayImage = uiaxes(app.Panel_2);
            app.displayImage.XLim = [0 1];
            app.displayImage.YLim = [0 1];
            app.displayImage.ZLim = [0 1];
            app.displayImage.CLim = [0 1];
            app.displayImage.ALim = [0 1];
            app.displayImage.XTickLabel = '';
            app.displayImage.YTick = zeros(1,0);
            app.displayImage.YTickLabel = '';
            app.displayImage.ZTick = [0 0.5 1];
            app.displayImage.ZTickLabel = '';
            app.displayImage.Tag = 'axes1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = displayWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)displayWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end