classdef ipWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        menuFile                     matlab.ui.container.Menu
        menuFileLoad                 matlab.ui.container.Menu
        menuFileSaveProcData         matlab.ui.container.Menu
        menuFileLoadImage            matlab.ui.container.Menu
        menuFileSave                 matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuFileClose                matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditName                 matlab.ui.container.Menu
        menuEditCreate               matlab.ui.container.Menu
        menuEditCopyImage            matlab.ui.container.Menu
        menuEditDelete               matlab.ui.container.Menu
        menuEditDeleteSome           matlab.ui.container.Menu
        menuImageWhite               matlab.ui.container.Menu
        menuEditResetWhite           matlab.ui.container.Menu
        menuScaleChooseMax           matlab.ui.container.Menu
        menuScaleDisplay             matlab.ui.container.Menu
        menuEditFontSize             matlab.ui.container.Menu
        menuEditClearMessage         matlab.ui.container.Menu
        menuEditViewer               matlab.ui.container.Menu
        menuPlot                     matlab.ui.container.Menu
        menuPlImTrueSize             matlab.ui.container.Menu
        multipleImageRGB             matlab.ui.container.Menu
        SingleWindowMenu             matlab.ui.container.Menu
        MultipleWindowsMenu          matlab.ui.container.Menu
        menuPlotDisplay              matlab.ui.container.Menu
        plotDisplaySPD               matlab.ui.container.Menu
        plotGamut                    matlab.ui.container.Menu
        plotColorProcessingMatrices  matlab.ui.container.Menu
        plotMCCOverOff               matlab.ui.container.Menu
        menuDisplay                  matlab.ui.container.Menu
        menuReadSPD                  matlab.ui.container.Menu
        menuDisplayWindow            matlab.ui.container.Menu
        menuDisplayViewD             matlab.ui.container.Menu
        menuAnalyze                  matlab.ui.container.Menu
        menuAnColor                  matlab.ui.container.Menu
        menuAnROI                    matlab.ui.container.Menu
        menuAnLum                    matlab.ui.container.Menu
        menuAnChrom                  matlab.ui.container.Menu
        menuAnColorLAB               matlab.ui.container.Menu
        menuAnLUV                    matlab.ui.container.Menu
        menuRGBHist                  matlab.ui.container.Menu
        menuAnROIvSNR                matlab.ui.container.Menu
        chartClearcornersMenu        matlab.ui.container.Menu
        MCCMenu                      matlab.ui.container.Menu
        LuminancenoiseMenu           matlab.ui.container.Menu
        ColormetricssRGBMenu         matlab.ui.container.Menu
        VisualcompareD65Menu         matlab.ui.container.Menu
        menuAnLine                   matlab.ui.container.Menu
        menuAnLineH                  matlab.ui.container.Menu
        menuAnLineV                  matlab.ui.container.Menu
        LineluminanceMenu            matlab.ui.container.Menu
        HorizontalMenu               matlab.ui.container.Menu
        VerticalMenu                 matlab.ui.container.Menu
        menuAnalyzeCreateSB          matlab.ui.container.Menu
        menuAnalyzeISO12233          matlab.ui.container.Menu
        menuMetricsWindow            matlab.ui.container.Menu
        menuAnComputeFromSensor      matlab.ui.container.Menu
        menuComputeFromOI            matlab.ui.container.Menu
        menuAnComputeFromScene       matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuHelpProcessorOnline      matlab.ui.container.Menu
        menuHelpMetricsPG            matlab.ui.container.Menu
        menuHelpISETOnline           matlab.ui.container.Menu
        menuHelpAppNotes             matlab.ui.container.Menu
        btnCompute                   matlab.ui.control.Button
        DemosaicPanel                matlab.ui.container.Panel
        popDemosaic                  matlab.ui.control.DropDown
        ColortransformsPanel         matlab.ui.container.Panel
        txtSensor                    matlab.ui.control.Label
        txtIlluminant                matlab.ui.control.Label
        popBalance                   matlab.ui.control.DropDown
        txtICS                       matlab.ui.control.Label
        popColorSpace                matlab.ui.control.DropDown
        txtMethod                    matlab.ui.control.Label
        popColorConversionM          matlab.ui.control.DropDown
        popTransform                 matlab.ui.control.DropDown
        ipTable                      matlab.ui.control.Table
        ProcessedimagePanel          matlab.ui.container.Panel
        popupRender                  matlab.ui.control.DropDown
        MaxbrightSwitch              matlab.ui.control.Switch
        MaxbrightSwitchLabel         matlab.ui.control.Label
        txtMessage                   matlab.ui.control.Label
        editGamma                    matlab.ui.control.EditField
        text10                       matlab.ui.control.Label
        btnPrev                      matlab.ui.control.Button
        btnNext                      matlab.ui.control.Button
        popSelect                    matlab.ui.control.DropDown
        ipImage                      matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
    end
    
    methods (Access = private)
        function btnCustomOnOff_Callback(app)
            % On Custom button press
            
            vci = ieGetObject('vci');
            
            val = get(hObject,'Value');  % returns toggle state of btnCustomOnOff
            vci = ipSet(vci,'customRender',val);
            
            ieReplaceObject(vci);
            app.refresh;
            
        end
        
        function menuAnColorMacbeth_Callback(app)
            % Analyze | ???
            vci = vcimageSRGB;
            ieAddObject(vci)
            app.refresh;
        end
        
        function menuAnalyzeSMTF_Callback(app)
            % Not currently used
            
            % Computes the responses to a series of harmonics. This routine assumes the
            % current parameters of the optics, isa and vcimage. It passes a set of
            % harmonics through these objects and analyzes the contrast of the
            % resulting displayed image. We have an ISO standard that we should
            % implement here.
            [rContrast,freq,fNames,rImages,sImages] = vcimageSystemMTF([],[],[],[],0);
            
            % Bad computation returns empty value.
            if isempty(rContrast), return;
            else
                
                % Plot the response contrast on the screen for each of the three display
                % primaries.  These two plotting/imaging calls should be in a separate
                % routine and they should be improved.
                ieNewGraphWin;
                
                rContrast = rContrast';
                plot(freq,rContrast(:,1),'ro-',freq,rContrast(:,2),'go-',freq,rContrast(:,3),'bo-')
                str = sprintf('System MTF (sensor %s)',fNames);
                title(str);
                xlabel('Spatial frequency (cpd)'); ylabel('Relative modulation');
                grid on
                line([0,freq(end)],[0,0],'color',[0,0,0]);
                
                uData.freq = freq;
                uData.rContrast = rContrast;
                uData.fNames = fNames;
                uData.rImages = rImages;
                uData.sImages = sImages;
                set(gca,'UserData',uData);
            end
            
        end
        
        function menuCIELUV_Callback(app)
            % Analyze | Color and luminance | ROI | CIELUV
            warning('Not yet implemented.')
        end
        
        function menuCIE_Callback(app)
        end
        
        function menuDisProp_Callback(app)
        end
        
        function menuDisplayDPI_Callback(app)
            % Display | Adjust | DPI
            vci = ieGetObject('vci');
            
            val = ipGet(vci,'display DPI');
            val = ieReadNumber('Dots per inch (dpi)',val,'%.2f');
            vci = ipSet(vci,'display DPI',val);
            
            ieReplaceObject(vci);
        end
        
        function menuEditIPDefault_Callback(app)
            [ip,val] = ieGetObject('ip');
            vci.renderingMethod = 'imageval';
            ieReplaceObject(vci,val);
        end
        
        function menuIm_Callback(app)
        end
        
        function menuImportFile_Callback(app)
            warning('Import display from file not yet implemented.');
            
        end
        
        function menuPattern_Callback(app)
        end
        
        function menuSetLum_Callback(app)
            
            [ip,val] = ieGetObject('ip');
            ip = displaySetMaxLuminance(ip);
            ieReplaceObject(ip,val);
            app.refresh;
        end
        
        function menuWhitePoint_Callback(app)
            [ip,val] = ieGetObject('ip');
            ip = displaySetWhitePoint(ip,'XYZ');
            ieReplaceObject(ip,val);
            app.refresh;
        end
        
        %{
        function popCustomPath_Callback(app)
            % Managing the custom render popup.  It is initialized with
            % ipComputer, Add Custom, Delete Custom, -----
            % We add and delete routines from the vcSESSION.CUSTOM.procMethod list.
            
            contents = get(handles.popCustomPath,'String');
            method = contents{get(handles.popCustomPath,'Value')};
            
            [ip,val] = ieGetObject('ip');
            ip = ipSet(ip,'render method',);
            ieReplaceObject(ip,val);
            app.refresh;
        end
        %}
        
        function popCustomPath_CreateFcn(app)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function rbCustomPath_Callback(app)
            % Hint: get(hObject,'Value') returns toggle state of rbCustomPath
            app.refresh;
        end
        
        function vcImageDelete(app)
            % Edit | Delte Current Image
            
            vcDeleteSelectedObject('VCIMAGE');
            
            % What condition does this wolve?
            ip = ieGetObject('ip');
            if isempty(ip)
                ip = ipCreate('default');
                ieAddObject(ip);
            end
            app.refresh;
        end
        
        % Update components that require runtime configuration
        %{
        function addRuntimeConfigurations(app)
            % Set component properties that require runtime configuration
            %             app.figure1.CloseRequestFcn  = 'vcimageClose';
            %             app.figure1.KeyPressFcn       = 'vcimageKeyPress';
            %             app.figure1.WindowKeyPressFcn = 'ipKeyPress';
        end
        %}
        function ipOpen(app)
            % Initialize ipWindow
            %
            %   ipOpen(app)
            
            dbApp = ieSessionGet('ip window');
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('ip window',app);
            end
            app.figure1.Name = sprintf('ISET-IP %s',datestr(now,'HH:MM:SS'));
            
            %  Check the preferences for ISET and adjust the font size.
            % I think we do this twice!
            ieFontInit(app.figure1);
        end
        
        function ipClose(app)
            % If we are closing a selected window in the database, remove
            % it from the database.
            ipW = ieSessionGet('ip window');
            if isequal(ipW,app)
                disp('Clearing this ip window from the database.')
                ieSessionSet('ip window',[]);
            end
            delete(app);
        end
        
    end
    
    methods (Access = public)
        
        function ipDisplayData(app, ip)
            iePTable(ip,'uitable',app.ipTable,'format','embed');
        end
        
        function refresh(app,ip)
            % Main refresh
            %
            % Synopsis
            %   refresh(app,[ip])
            %
            % Brief
            %  Updates the Image Processor image window fields, and also
            %  updates the selected IP with any changes in the gamma and
            %  render flag selections.
            %
            %
            % Inputs:
            %   app:   ipWindow_App object
            %   ip:    Selected ip (optional)
            %
            % Output
            %   N/A
            %
            % Description
            %  A variety of text boxes, display data, and compute methods are updated in
            %  the vcimage window.
            %
            %  The status of the window is updated to conform to the entries in the ip
            %  structure.
            %
            %  This routine is called in many places within ipWindow via the
            %  ipRefresh function.
            %
            % See also:
            %   imageShowImage
            %
            
            % Get an existing ip or make one up.  Not sure we should make
            % one up. 
            if ieNotDefined('ip'), ip = ieGetObject('ip'); end
            if isempty(ip)
                ip = ipCreate;
                ieAddObject(ip);
            end
                        
            % Clear the text message in the window
            ieInWindowMessage('',app);
            
            % Display the table
            app.ipDisplayData(ip);
            
            %% Set the select IP popup at the top.
            nameList = vcGetObjectNames('ip');
            selectList = {'New'};
            for ii=1:length(nameList)
                selectList{ii+1} = sprintf('%d-%s',ii,nameList{ii});
            end
            
            % Sometimes we get an error here, but not all the time.  I don't know why.
            app.popSelect.Items = selectList;
            app.popSelect.Value = app.popSelect.Items{vcGetSelectedObject('ip')+1};
            
            %% Manage the transformations at the right of the window
            
            % Demosiac
            app.popDemosaic.Value = lower(ipGet(ip,'demosaic method'));
            
            % Sensor transform
            % app.popTransform.Items
            app.popTransform.Value = lower(ipGet(ip,'Transform method'));
            
            % Sensor conversion method
            app.popColorConversionM.Value = lower(ipGet(ip,'conversion method sensor'));
            
            % Internal color space
            app.popColorSpace.Value = lower(ipGet(ip,'internalcs'));
            
            % Illuminant correction method
            app.popBalance.Value = lower(ipGet(ip,'illuminant correction method'));
            
            %% Adjust the screen panels depending on the type of Transform.
            % In the L3 case we eliminate most of the popups
            if strncmpi(ip.name,'l3',2)
                % L3 method case.  Turn off most of the popups because they don't apply
                % in this case.
                app.setPopupVisibility('l3');
            else
                app.setPopupVisibility(ipGet(ip,'transform method'));
            end
            
            
            %% Read the gamma value from the window

            % Display the image with the proper gamma           
            gam = str2double(app.editGamma.Value);
            ip.render.gamma = gam;

            % Also, with the proper render flag
            switch app.popupRender.Value
                case 'rgb'
                    ip.render.renderflag = 1;
                case 'hdr'
                    ip.render.renderflag = 2;
                case 'gray'
                    ip.render.renderflag = 3;
            end

            % Because gamma and render flag might have changed
            ieReplaceObject(ip);

            %% Checks button for maximum brightness 
            trueSizeFlag = false;

            % The ip.render.renderflag determines which type of render.
            imageShowImage(ip,gam,trueSizeFlag,app);
            
            % Make sure it is visible on the screen.
            figure(app.figure1);


        end
        
        %-------------------------------------------------------
        function setPopupVisibility(app, tMethod)
            % Adjust the visibility of the popups on the right hand panel depending on
            % various conditions
            %
            % Demosaic panel
            %         set(handles.panelDemosaic,'Visible','on')
            %         set(handles.popDemosaic,'Visible','on')
            %         set(handles.txtDemosaic,'Visible','on');
            
            % Text colors when option is off or on
            colorOff = [.6 .6 .6];
            colorOn = [ 0 0 0];
            
            switch lower(tMethod)
                
                case {'new'}
                    
                    % Demosaic panel
                    % app.txtDemosaic.FontColor = colorOn;
                    app.popDemosaic.Visible = 'on';
                    
                    % Transform panel
                    % app.txtTransform.FontColor = colorOn;
                    app.popTransform.Visible='on';
                    
                    % Sensor panel
                    % app.txtSensor.FontColor= colorOff;
                    % app.txtMethod.FontColor= colorOff;
                    % app.txtICS.FontColor= colorOff;
                    app.popColorSpace.Visible= 'off';
                    app.popColorConversionM.Visible= 'off';
                    
                    % Illuminant panel
                    app.popBalance.Visible= 'off';
                    app.txtIlluminant.FontColor= colorOff;
                    
                case {'current'}
                    % Nice to gray stuff out rather than make it go away
                    
                    % Demosaic panel
                    % app.txtDemosaic.FontColor= colorOn;
                    app.popDemosaic.Visible= 'on';
                    
                    % Transform panel
                    % app.txtTransform.FontColor= colorOn;
                    app.popTransform.Visible= 'on';
                    
                    % Sensor panel
                    % set(handles.panelSensor.Visible= 'off')
                    % app.txtSensor.FontColor= colorOff;
                    % app.txtMethod.FontColor= colorOff;
                    % app.txtICS.FontColor= colorOff;
                    app.popColorConversionM.Visible= 'off';
                    app.popColorSpace.Visible= 'off';
                    
                    % Illuminant panel
                    app.popBalance.Visible= 'off';
                    app.txtIlluminant.FontColor= colorOff;
                    
                case {'adaptive'}
                    % These two methods might be about the same.  If
                    % not, I should break out an rgbw restormer case.s

                    % Demosaic panel
                    % app.textDemosaic.FontColor = colorOn;
                    app.popDemosaic.Visible = 'on';
                    
                    app.txtSensor.Visible = 'on';
                    app.txtIlluminant.Visible = 'on';
                    app.txtICS.Visible = 'on';

                    % Transform panel
                    % app.txtTransform.FontColor = colorOn;
                    app.popTransform.Visible = 'on';
                    
                    % Sensor panel                  
                    app.txtSensor.FontColor= colorOn;
                    app.txtMethod.FontColor= colorOn;
                    app.txtICS.FontColor= colorOn;
                    
                    app.popColorConversionM.Visible= 'on';
                    app.popColorSpace.Visible= 'on';
                    app.popColorConversionM.FontColor= colorOn;
                    app.popColorSpace.FontColor= colorOn;
                    
                    % Illuminant panel
                    app.popBalance.Visible= 'on';
                    app.txtIlluminant.FontColor= colorOn;
                    app.popBalance.FontColor= colorOn;
                    
                case 'l3'
                    % In this case, most of the routines are irrelevant so the whole
                    % set of panels are grayed out.
                    
                    % Demosaic panel
                    app.txtDemosaic.FontColor= colorOff;
                    app.popDemosaic.Visible= 'off';
                    
                    % Transform panel
                    app.txtTransform.FontColor= colorOff;
                    app.popTransform.Visible= 'off';
                    
                    % Sensor panel
                    app.txtSensor.FontColor= colorOff;
                    app.txtMethod.FontColor= colorOff;
                    app.txtICS.FontColor= colorOff;
                    app.popColorSpace.Visible= 'off';
                    app.popColorConversionM.Visible= 'off';
                    
                    % Illuminant panel
                    app.popBalance.Visible= 'off';
                    app.txtIlluminant.FontColor= colorOff;
                    
                case {'rgbwrestormer'}
                    app.popDemosaic.Visible = 'off';
                    
                    % Transform panel
                    % Show rgbw restormer
                    app.popTransform.Visible = 'on';
                    
                    % Do not show internal color space, illuminant
                    % transform, sensor conversion
                    app.txtSensor.Visible = 'off'; 
                    app.txtIlluminant.Visible = 'off';
                    app.txtICS.Visible = 'off';

                    app.popColorConversionM.Visible= 'off';
                    app.popColorSpace.Visible= 'off';
                    app.popColorConversionM.FontColor= colorOn;
                    app.popColorSpace.FontColor= colorOn;
                    
                    % Illuminant panel
                    app.popBalance.Visible= 'off';
                    app.popBalance.FontColor= colorOn;

                otherwise
                    error('Unknown transform method %s\n',tMethod);
            end
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function ipWindow_OpeningFcn(app, varargin)
            % Add runtime required configuration - Added by Migration Tool
            % addRuntimeConfigurations(app);
            
            app.figure1.Visible = false;
            
            % see if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.ipImage.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.ipImage.Toolbar = [];
            end
            
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{5})
                    s = get(0);
                    if numel(wPos{5}) >= 2
                        if wPos{5}(1) < 1 % we have old-style
                            app.figure1.Position(1:2) = round(wPos{5}(1:2) .* s.ScreenSize(3:4));
                        else
                            app.figure1.Position(1:2) = wPos{5}(1:2);
                        end
                        % this code was here, but is completely different from other wpos
                        % initializations, so I've changed this one to match the others.
                        %                        windowPosition = round([screenSize(3), screenSize(4), ...
                        %                            screenSize(3), screenSize(4)].*wPos{5});
                        %                        app.figure1.Position = windowPosition;
                    end
                end
            end
            % Show font slightly smaller than surrounding.
            % we can set row striping, but for now just use main background
            % color
            
            ipOpen(app);
            
            %% Refresh font size
            % ieFontSizeSet(app,0);
            
            app.refresh();
            
            % still need this here as refresh forces fonts to default
            app.ipTable.FontSize = getpref('ISET', 'fontSize', 12) - 3;
            app.ipTable.FontName = 'Courier';
            app.ipTable.BackgroundColor = [app.figure1.Color;app.figure1.Color];
                        
            app.figure1.Visible = true;
        end

        % Button pushed function: btnCompute
        function btnCompute_Callback(app, event)
            % Compute button
            [ip,val] = ieGetObject('ip');
            sensor  = ieGetObject('sensor');
            
            % We require the sensor data
            if isempty(sensorGet(sensor,'dvorvolts'))
                ieInWindowMessage('No sensor voltage data.',app);
            else, ieInWindowMessage('',app);
            end
            
            ip = ipCompute(ip,sensor);
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Show next ip data (button next to popSelect)
            s  = ieSessionGet('selected','ip');
            nS = ieSessionGet('nobjects','ip');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ip',s);

            % Refresh updates the ip with the gamma and rendering
            % parameters.
            app.refresh;            
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Show previous ip data (button next to popSelect)
            s  = ieSessionGet('selected','ip');
            nS = ieSessionGet('nobjects','ip');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ip',s);

            % Refresh updates the ip with the gamma and rendering
            % parameters.
            app.refresh;            
        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            % The gamma edit box is read by the refresh routine during the re-display
            % of the image data.  The display gamma value is not stored in any data
            % objects.
            [ip,val] = ieGetObject('ip');
            gam = str2double(app.editGamma.Value);

            % This set calls app.refresh
            ip = ipSet(ip,'render gamma',gam);
            ieReplaceObject(ip,val);
            % app.refresh;
        end

        % Menu selected function: menuAnChrom
        function menuAnChrom_Callback(app, event)
            % Analyze | Color and luminance | ROI | Chromaticity (xy)
            ip = ieGetObject('vcimage');
            ipPlot(ip,'chromaticity');
        end

        % Menu selected function: LuminancenoiseMenu
        function menuAnColLumNoise_Callback(app, event)
            % Analyze | Color and Luminance | MCC Luminance Noise
            ip = ieGetObject('ip');
            ip = macbethLuminanceNoise(ip);
            ieReplaceObject(ip);
        end

        % Callback function
        function menuAnColMCCEst_Callback(app, event)
            % Analyze | Color | MCC Color Metrics (custom display)
            macbethColorError(ieGetObject('VCIMAGE'),[],[],'custom');
        end

        % Menu selected function: ColormetricssRGBMenu
        function menuAnColMCCsRGB_Callback(app, event)
            % Analyze | Color | MCC Color Metrics (sRGB)
            macbethColorError(ieGetObject('VCIMAGE'),[],[],'sRGB');            
        end

        % Menu selected function: VisualcompareD65Menu
        function menuAnColVisualComp_Callback(app, event)
            % Analyze | Color and Luminance | Visual Compare (D65)
            ip = ieGetObject('ip');
            macbethCompareIdeal(ip);
        end

        % Menu selected function: menuAnColorLAB
        function menuAnColorLAB_Callback(app, event)
            % Seems deprecated.
            ip = ieGetObject('VCIMAGE');
            ipPlot(ip,'cielab');
        end

        % Menu selected function: menuAnColor
        function menuAnColor_Callback(app, event)
            
        end

        % Menu selected function: menuAnComputeFromScene
        function menuAnComputeFromScene_Callback(app, event)
            % Recompute the OI, ISA and VCIMAGE
            scene = ieGetObject('scene');
            if isempty(scene)
                warndlg('Creating default scene');
                scene = sceneCreate; val = 1;
                vcReplaceAndSelectObject(scene,val);
            end
            
            [val,oi] = vcGetSelectedObject('oi');
            if isempty(oi)
                warndlg('Creating default OI');
                oi = oiCreate; val = 1;
            end
            oi = oiCompute(scene,oi);
            vcReplaceAndSelectObject(oi,val);
            
            [sensor,val] = ieGetObject('isa');
            if isempty(sensor)
                warndlg('Creating default sensor');
                sensor = sensorCreate; val = 1;
            end
            sensor = sensorCompute(sensor,oi);
            vcReplaceAndSelectObject(sensor,val);
            
            btnCompute_Callback(app);
            
        end

        % Menu selected function: menuAnComputeFromSensor
        function menuAnComputeFromSensor_Callback(app, event)
            btnCompute_Callback(app);
        end

        % Menu selected function: menuAnLUV
        function menuAnLUV_Callback(app, event)
            ip = ieGetObject('VCIMAGE');
            ipPlot(ip,'cieluv');
            
        end

        % Menu selected function: menuAnLineH
        function menuAnLineH_Callback(app, event)
            % Analyze | Line intensities | Horizontal
            ip = ieGetObject('ip');
            ipPlot(ip,'horizontal line');
            % plotDisplayLine(ip,'h');
        end

        % Menu selected function: menuAnLineV
        function menuAnLineV_Callback(app, event)
            % Analyze | Line intensities | Vertical
            ip = ieGetObject('ip');   % Image proessor, same as vci
            ipPlot(ip,'vertical line');
        end

        % Menu selected function: menuAnLine
        function menuAnLine_Callback(app, event)
            % Analyze | Line intensities
        end

        % Menu selected function: menuAnLum
        function menuAnLum_Callback(app, event)
            ip = ieGetObject('vcimage');
            ipPlot(ip,'luminance');
        end

        % Menu selected function: menuAnROI
        function menuAnROI_Callback(app, event)
            
        end

        % Menu selected function: menuAnROIvSNR
        function menuAnROIvSNR_Callback(app, event)
            % Analyze | Color and Luminance | vSNR (0.5 m)
            
            ip  = ieGetObject('ip');
            dpi  = ipGet(ip,'display DPI');
            dist = ipGet(ip,'display Viewing Distance');
            
            vSNR = vcimageVSNR(ip,dpi,dist);
            fprintf('\n***vSNR***\n');
            fprintf('Assumed dpi: %.1f and viewing distance %.2f (m)\n',dpi,dist);
            fprintf('Uniform patch Visual SNR (vSNR): %f\n',vSNR)
        end

        % Menu selected function: menuAnalyzeCreateSB
        function menuAnalyzeCreateSB_Callback(app, event)
            % Analyze | Create slanted bar
            
            % The new version of ISO MTF relies on camera structure.  So we create one.
            if exist('cameraCreate','file')
                camera = cameraCreate;
                sensor = ieGetObject('sensor');
                if isempty(sensor)
                    h = warndlg('No sensor defined.  Using default');
                    sensor = sensorCreate;
                end
                camera = cameraSet(camera,'sensor',sensor);
            else
                camera = [];
            end
            
            vci = vcimageISOMTF(camera);
            
            ieAddObject(vci);
            app.refresh;
            
            % If we opened the warning dialog, close it.
            if exist('h','var'), close(h); end
        end

        % Menu selected function: menuAnalyzeISO12233
        function menuAnalyzeISO12233_Callback(app, event)
            % Analyze | ISO 12233 (Slanted Bar)
            % Read a slanted bar in the display window and plot the ISO 12233 graph
            %
            ip = ieGetObject('VCIMAGE');
            
            % UPDATE THIS TO USE THE NEW iso functions
            ieInWindowMessage('Choose rectangular ROI (short sides must intersect edge)',app);
            
            % Have the user select the edge.  Need more comments to the user in the
            % window.
            [roiLocs,roi] = ieROISelect(ip);
            rect = round(roi.Position);
            % rect is [col,row,colWidth,rowHeight]
            
            % Extract the data after demosaicking but before any image
            % processing.
            barImage = vcGetROIData(ip,roiLocs,'sensor space');
            c = rect(3)+1;
            r = rect(4)+1;
            barImage = reshape(barImage,r,c,3);
            % figure; imagesc(barImage(:,:,1)); axis image; colormap(gray);
            
            sensor = ieGetObject('ISA');
            if ~isempty(sensor)
                pixel = sensorGet(sensor,'pixel');
                dx = pixelGet(pixel,'width','mm');  % Pixel width in mm
            else
                fprintf('Assuming 2 um pixel');
                dx = [];
            end
            
            % Run the ISO 12233 code.  The results are stored in the window
            [results, ~, ~, fig] = ISO12233(barImage,dx);
            results.rect = rect;
            set(fig,'userdata',results);
            
            ieInWindowMessage('',app);
        end

        % Menu selected function: menuAnalyze
        function menuAnalyze_Callback(app, event)
            
        end

        % Menu selected function: menuComputeFromOI
        function menuComputeFromOI_Callback(app, event)
            % Don't recompute the OI.  Just Recompute the ISA and the vcimage.
            
            oi = ieGetObject('oi');
            if isempty(oi), errordlg('No optical image'); end
            
            [sensor,val] = ieGetObject('sensor');
            if isempty(sensor)
                warndlg('Creating default sensor');
                sensor = sensorCreate; val = 1;
            end
            sensor = sensorCompute(sensor,oi);
            vcReplaceAndSelectObject(sensor,val);
            
            btnCompute_Callback(app);
            
        end

        % Menu selected function: menuDisplayViewD
        function menuDisplayViewD_Callback(app, event)
            % Display | Adjust | Viewing Distance
            vci = ieGetObject('vci');
            
            val = ipGet(vci,'displayViewingDistance');
            val = ieReadNumber('Viewing distance (m)',val,'%0.2f');
            vci = ipSet(vci,'displayViewingDistance',val);
            
            ieReplaceObject(vci);
        end

        % Menu selected function: menuDisplayWindow
        function menuDisplayWindow_Callback(app, event)
            % Display | Display window
            
            % Place the display for this IP in the ISET database
            ip = ieGetObject('ip');
            d  = ipGet(ip,'display');
            ieAddObject(d);
            
            % Place the rendered srgb data into the vcSESSION.
            % This seems like a mistake to me, and we should be attaching it to the
            % display object. I think we should have
            %   d = displaySet(d,'rgb image');
            % Check with HJ why he did it this way
            global vcSESSION
            vcSESSION.imgData = imageShowImage(ip,[],[],0);
            
            % Bring up the display window.
            displayWindow;
        end

        % Menu selected function: menuDisplay
        function menuDisplay_Callback(app, event)
            
        end

        % Menu selected function: menuEditCopyImage
        function menuEditCopyImage_Callback(app, event)
            warndlg('Not yet implemented');
        end

        % Menu selected function: menuEditCreate
        function menuEditCreate_Callback(app, event)
            % Edit | Create new image
            %
            % Create a new image processor that is a copy of the current one but with
            % the data cleared.
            
            ip = ieGetObject('ip');
            ip = vcimageClearData(ip);
            
            % We use this name for now and over-write when we compute
            ip = ipSet(ip,'name',sprintf('copy %s',ipGet(ip,'name')));
            ieAddObject(ip);
            app.refresh;
        end

        % Menu selected function: menuEditDeleteSome
        function menuEditDeleteSome_Callback(app, event)
            % Edit | Delete Some Images
            vcDeleteSomeObjects('VCI');
            app.refresh;
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            vcImageDelete(app);
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Edit | Change Font Size
            ieFontSizeSet(app.figure1);
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            % Edit | (Re-) Name image
            [ip,val] = ieGetObject('ip');
            newName = ieReadString('New vcimage name','new-vcimage');
            
            if isempty(newName),  return;
            else                  ip = ipSet(ip,'name',newName);
            end
            
            vcReplaceAndSelectObject(vci,val)
            app.refresh;
        end

        % Menu selected function: menuEditResetWhite
        function menuEditResetWhite_Callback(app, event)
            % Edit | Reset White
            %
            % Put the image white point equal to the display white point.  This allows
            % the user to go back to a default position.
            
            [ip,val] = ieGetObject('ip');
            
            wp = ipGet(ip,'display whitepoint');
            ip = ipSet(ip,'data white point',wp);
            
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            vci = ieGetObject('vcimage');
            result = ipGet(vci,'result');
            ieViewer(result);
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            ipClose(app);
        end

        % Menu selected function: menuFileLoadImage
        function menuFileLoadImage_Callback(app, event)
            % File | Load Image Data
            % Maybe we should put up some kind of a warning.
            
            % Start with defaults of current image
            ip = ieGetObject('vcimage');
            
            fullName = vcSelectDataFile('stayput','r');
            if isempty(fullName), return; end
            scene = sceneFromFile(fullName,'rgb');
            srgb = sceneGet(scene,'rgb');
            % ieNewGraphWin; imagesc(srgb)
            
            % By setting result close to lrgb, the image rendered in the display window
            % is about what we see from just visualization the original image.
            % But this might defeat the purpose of reading in the image file.
            % lrgb = srgb2lrgb(srgb);
            
            [ip,fname] = fileparts(fullName);
            ip = ipSet(ip,'name',fname);
            ip = ipSet(ip,'input',[]);   % Not sure what this should be.
            ip = ipSet(ip,'result',srgb);
            ip = ipSet(ip,'gamma',2);
            
            ieAddObject(ip);
            app.refresh;
        end

        % Menu selected function: menuFileLoad
        function menuFileLoadProcData_Callback(app, event)
            % File | Load  (.mat)
            
            fullName = vcSelectDataFile([],'r','mat','Processor data');
            if isempty(fullName), return; end
            
            load(fullName,'vci');
            
            ieAddObject(vci)
            app.refresh;
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            app.refresh;
        end

        % Menu selected function: menuFileSaveProcData
        function menuFileSaveProcData_Callback(app, event)
            % File |  Save (.mat)
            vci = ieGetObject('vcimage');
            
            fullName = vcSelectDataFile([],'w','mat','Processor data');
            if isempty(fullName), return; end
            
            save(fullName,'vci');
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            % File | Save Image
            %
            % Write the rgb data in the ip result to a file containing an RGB image.
            % Output is in 8-bit png format.
            
            ip = ieGetObject('ip');
            
            fullName = vcSelectDataFile('stayPut','w');
            if isempty(fullName), return; end
            [p,n,e] = fileparts(fullName);
            if isempty(e), e = '.png'; fullName = fullfile(p,[n, e]);  end % Add ext
            
            ipSaveImage(ip,fullName);
            
            fprintf('Saved image file %s\n',fullName);
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            
        end

        % Menu selected function: menuHelpAppNotes
        function menuHelpAppNotes_Callback(app, event)
            % Help | Documentation (web)
            ieManualViewer('imageval code');
            
        end

        % Menu selected function: menuHelpISETOnline
        function menuHelpISETOnline_Callback(app, event)
            % Help | ISET functions
            ieManualViewer('iset functions');
        end

        % Menu selected function: menuHelpMetricsPG
        function menuHelpMetricsPG_Callback(app, event)
            % Help | Metrics functions
            ieManualViewer('metrics functions');
        end

        % Menu selected function: menuHelpProcessorOnline
        function menuHelpProcessorOnline_Callback(app, event)
            % Help | Processor functions
            ieManualViewer('ip functions');
            
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            
        end

        % Menu selected function: menuImageWhite
        function menuImageWhite_Callback(app, event)
            % Edit | Image White Point (ROI)
            %
            %  Allow the user to pick a region of the image that will be used as the
            %  image white point in metrics calculations.
            %  This white point is the logical one to use for deltaE
            %  type calculations, as opposed to the white point of the monitor.
            
            [ip,val] = ieGetObject('ip');
            roiLocs = vcROISelect(ip);
            dataXYZ = imageDataXYZ(ip,roiLocs);
            ip.data.wp = mean(dataXYZ);
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Menu selected function: menuMetricsWindow
        function menuMetricsWindow_Callback(app, event)
            metricsWindow;
        end

        % Menu selected function: menuPlImTrueSize
        function menuPlImTrueSize_Callback(app, event)
            % Plot | True size image
            ip = ieGetObject('ip');
            imageShowImage(ip,ipGet(ip,'gamma'),true,ieNewGraphWin);
        end

        % Menu selected function: menuPlotDisplay
        function menuPlotDisplay_Callback(app, event)
            
        end

        % Menu selected function: menuPlot
        function menuPlot_Callback(app, event)
            
        end

        % Menu selected function: menuRGBHist
        function menuRGBHist_Callback(app, event)
            % Analyze | Color and luminance | ROI | RGB Histogram
            ip = ieGetObject('ip');
            ipPlot(ip,'RGB histogram');
        end

        % Menu selected function: menuReadSPD
        function menuReadSPD_Callback(app, event)
            % Display | Load
            %
            % Load a new display structure that will be attached to the ip.
            %
            
            % Chooses a new default display
            [val,ip] = vcGetSelectedObject('ip');
            dName = vcSelectDataFile('displays');
            if isempty(dName), disp('User canceled'); return; end
            
            d   = displayCreate(dName);
            ip = ipSet(ip,'display',d);
            
            ip = vcimageClearData(ip);
            ieReplaceObject(ip,val);
            refresh(app)
        end

        % Menu selected function: menuScaleChooseMax
        function menuScaleChooseMax_Callback(app, event)
            %  Edit | Scale data (ROI)
            %
            %  Let the user choose a region of the image (ROI).  Find the mean of this region.
            %  Scale the data values in vci.data.results so that the largest of the
            %  [R,G,B] values in the ROI is set to 1 (i.e. max display intensity).
            %
            %  The data may be clipped by this procedure; hence, this function cannot
            %  be inverted.
            
            [ip,val] = ieGetObject('ip');
            
            % Get the data from an ROI
            d = vcGetROIData(vci,vcROISelect(vci),'result');
            mn = mean(d(:));
            
            img = ipGet(ip,'result')/mn;
            img = ieClip(img,0,1);
            ip = ipSet(ip,'result',img);
            
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Menu selected function: menuScaleDisplay
        function menuScaleDisplay_Callback(app, event)
            % Edit | Scale data (max)
            %
            %  Scale the data in ip.data.result setting the largest value in the image
            %  to the maximum display range.  The display values are almost always in
            %  the [0,1] range.
            %
            
            [ip,val] = ieGetObject('ip');
            
            % Scale the displayed data so the max value is the max of the display.
            ip = ipSet(ip,'result',ipGet(ip,'result scaled to max'));
            
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Menu selected function: multipleImageRGB
        function multipleImageRGB_Callback(app, event)
            %
        end

        % Menu selected function: plotColorProcessingMatrices
        function plotColorProcessingMatrices_Callback(app, event)
            % Plot | Color Processing matrices
            
            ip = ieGetObject('ip');
            T = ipGet(ip,'each Transform');
            
            fprintf('\n\n--------------------------------------------\n');
            fprintf('Sensor -> Internal color space\n'),  disp(T{1})
            fprintf('Illuminant correction\n'),  disp(T{2})
            fprintf('Internal color space -> Display primaries\n'), disp(T{3})
            
            % Now the product of the first three
            fprintf('Color matrices combined\n'),
            T{4} = ipGet(ip,'prodT');
            disp(T{4})
            fprintf('--------------------------------------------\n');
            
        end

        % Menu selected function: plotDisplaySPD
        function plotDisplaySPD_Callback(app, event)
            ip = ieGetObject('ip');
            displayPlot(ipGet(ip,'display'),'spd');
        end

        % Menu selected function: plotGamut
        function plotGamut_Callback(app, event)
            ip = ieGetObject('ip');
            displayPlot(ipGet(ip,'display'),'gamut');
        end

        % Menu selected function: plotMCCOverOff
        function plotMCCOverOff_Callback(app, event)
            % Plot | MCC Overlay Off
            %
            ip = ieGetObject('ip');
            ip = macbethDrawRects(ip,'off');
            ieReplaceObject(ip);
            app.refresh;
        end

        % Value changed function: popBalance
        function popBalance_Callback(app, event)
            [ip,val] = ieGetObject('ip');
            ip = ipSet(ip,'illuminant correction method',app.popBalance.Value);
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Value changed function: popColorConversionM
        function popColorConversionM_Callback(app, event)
            [ip,val] = ieGetObject('ip');
            ip      = ipSet(ip,'sensor conversion method',app.popColorConversionM.Value);
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Value changed function: popColorSpace
        function popColorSpace_Callback(app, event)
            % Set internal color space for image processing
            
            [ip,val] = ieGetObject('ip');
            ip = ipSet(ip,'internalcs',app.popColorSpace.Value);
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Value changed function: popDemosaic
        function popDemosaic_Callback(app, event)
            
            [ip,val] = ieGetObject('vcimage');
            method = app.popDemosaic.Value;
            
            % We do some checking here for permissible combinations.  For example,
            % we don't have a Laplacian implemented for CMY.  So, we test for that
            % condition and disallow it here.
            sensor = ieGetObject('sensor');
            cOrder = sensorGet(sensor,'filter Color Letters');
            filters = sort(unique(cOrder(:)))';
            switch(filters)
                case {'bgr'}
                    ip = ipSet(ip,'demosaicmethod',method);
                    ieInWindowMessage('',app)
                    
                case {'cmy'}
                    switch (lower(method))
                        case {'bilinear','nearest neighbor'}
                            ip = ipSet(ip,'demosaic method',method);
                            ieInWindowMessage('',app);
                        otherwise
                            ieInWindowMessage('CMY: only bilinear and nearest neighbor available.',app)
                            ip = ipSet(ip,'demosaic method','bilinear');
                    end
                otherwise
                    ieInWindowMessage('Not or (CMY/RGB) sensor: only bilinear demosaicing available.',app)
                    ip = ipSet(ip,'demosaic method','bilinear');
            end
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Value changed function: popSelect
        function popSelect_Callback(app, event)
            switch app.popSelect.Value
                case 'New'
                    % The first entry is always New.  When New is selected, we
                    % create a new VCIMAGE structure.  That structure is a copy of the current
                    % VCIMAGE structure with a new name.  Then we adjust the parameter
                    % settings and compute a new VCIMAGE image.
                    menuEditCreate_Callback(app);
                otherwise
                    % Select one of the existing VCIMAGE objects.
                    val = find(strcmp(app.popSelect.Items,app.popSelect.Value));
                    vcSetSelectedObject('ip',val-1);
            end
            app.refresh;
        end

        % Value changed function: popTransform
        function popTransform_Callback(app, event)
            % popTransform - Transform processing popup
            % Store the selected transform method
            vci = ieGetObject('vcimage');
            vci = ipSet(vci,'transform method',app.popTransform.Value);
            ieReplaceObject(vci);
            app.refresh;
        end

        % Value changed function: MaxbrightSwitch
        function switchMaxbright(app, event)
            % Determine how to render the display
            [ip,val] = ieGetObject('ip');
            switch lower(app.MaxbrightSwitch.Value)
                case 'on'
                    ip = ipSet(ip,'scale display',true);
                case 'off'
                    ip = ipSet(ip,'scale display',false);
            end
            ieReplaceObject(ip,val);
            app.refresh;
        end

        % Cell selection callback: ipTable
        function ipDescription(app, event)
            % indices = event.Indices;
            ip = ieGetObject('ip');
            iePTable(ip,'format','window');
        end

        % Menu selected function: chartClearcornersMenu
        function chartClearCorners(app, event)
            ip = ieGetObject('ip');
            ip = ipSet(ip,'chart corner points',[]);
            ieReplaceObject(ip);
        end

        % Menu selected function: SingleWindowMenu
        function multipleImageSingle_Callback(app, event)
            % Plot | Multiple image (RGB)
            singleWindow = true;
            imageMultiview('vcimage',[],singleWindow,app);
        end

        % Menu selected function: MultipleWindowsMenu
        function multipleImageMultipleWindows_Callback(app, event)
            singleWindow = false;
            imageMultiview('vcimage',[],singleWindow,app);            
        end

        % Value changed function: popupRender
        function renderMethod(app, event)
            value = app.popupRender.Value;
            ip = ieGetObject('ip');
            
            % This method calls app.refresh when the window is open
            ip = ipSet(ip,'renderflag',value);
            ieReplaceObject(ip);
            % app.refresh;
        end

        % Menu selected function: VerticalMenu
        function menuAnnLineLuminanceVertical(app, event)
            ip = ieGetObject('ip');
            ipPlot(ip,'vertical line luminance');
        end

        % Menu selected function: HorizontalMenu
        function menuAnnLineLuminanceHorizontal(app, event)
            ip = ieGetObject('ip');
            ipPlot(ip,'horizontal line luminance');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'ISET-Image Processor';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileLoad = uimenu(app.menuFile);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadProcData_Callback, true);
            app.menuFileLoad.Text = 'Load processor data (.mat)';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuFileSaveProcData = uimenu(app.menuFile);
            app.menuFileSaveProcData.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveProcData_Callback, true);
            app.menuFileSaveProcData.Text = 'Save processor data (.mat)';
            app.menuFileSaveProcData.Tag = 'menuFileSaveProcData';
            app.menuFileLoadImage = uimenu(app.menuFile);
            app.menuFileLoadImage.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadImage_Callback, true);
            app.menuFileLoadImage.Separator = 'on';
            app.menuFileLoadImage.Text = 'Load image data (png, jpg, ...)';
            app.menuFileLoadImage.Tag = 'menuFileLoadImage';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Separator = 'on';
            app.menuFileSave.Text = 'Save image';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuFileRefresh = uimenu(app.menuFile);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Separator = 'on';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = '(Re-) Name image';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditCreate = uimenu(app.menuEdit);
            app.menuEditCreate.MenuSelectedFcn = createCallbackFcn(app, @menuEditCreate_Callback, true);
            app.menuEditCreate.Separator = 'on';
            app.menuEditCreate.Text = 'Create new image';
            app.menuEditCreate.Tag = 'menuEditCreate';
            app.menuEditCopyImage = uimenu(app.menuEdit);
            app.menuEditCopyImage.MenuSelectedFcn = createCallbackFcn(app, @menuEditCopyImage_Callback, true);
            app.menuEditCopyImage.Text = 'Copy current image';
            app.menuEditCopyImage.Tag = 'menuEditCopyImage';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current image';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.menuEditDeleteSome = uimenu(app.menuEdit);
            app.menuEditDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @menuEditDeleteSome_Callback, true);
            app.menuEditDeleteSome.Text = 'Delete some images';
            app.menuEditDeleteSome.Tag = 'menuEditDeleteSome';
            app.menuImageWhite = uimenu(app.menuEdit);
            app.menuImageWhite.MenuSelectedFcn = createCallbackFcn(app, @menuImageWhite_Callback, true);
            app.menuImageWhite.Separator = 'on';
            app.menuImageWhite.Text = 'Image white point (ROI)';
            app.menuImageWhite.Tag = 'menuImageWhite';
            app.menuEditResetWhite = uimenu(app.menuEdit);
            app.menuEditResetWhite.MenuSelectedFcn = createCallbackFcn(app, @menuEditResetWhite_Callback, true);
            app.menuEditResetWhite.Text = 'Set image white to display white';
            app.menuEditResetWhite.Tag = 'menuEditResetWhite';
            app.menuScaleChooseMax = uimenu(app.menuEdit);
            app.menuScaleChooseMax.MenuSelectedFcn = createCallbackFcn(app, @menuScaleChooseMax_Callback, true);
            app.menuScaleChooseMax.Separator = 'on';
            app.menuScaleChooseMax.Text = 'Scale data (ROI)';
            app.menuScaleChooseMax.Tag = 'menuScaleChooseMax';
            app.menuScaleDisplay = uimenu(app.menuEdit);
            app.menuScaleDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuScaleDisplay_Callback, true);
            app.menuScaleDisplay.Text = 'Scale data (max)';
            app.menuScaleDisplay.Tag = 'menuScaleDisplay';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearMessage = uimenu(app.menuEdit);
            app.menuEditClearMessage.Text = 'Clear window message';
            app.menuEditClearMessage.Tag = 'menuEditClearMessage';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_Callback, true);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuPlImTrueSize = uimenu(app.menuPlot);
            app.menuPlImTrueSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlImTrueSize_Callback, true);
            app.menuPlImTrueSize.Text = 'True size image';
            app.menuPlImTrueSize.Tag = 'menuPlImTrueSize';
            app.multipleImageRGB = uimenu(app.menuPlot);
            app.multipleImageRGB.MenuSelectedFcn = createCallbackFcn(app, @multipleImageRGB_Callback, true);
            app.multipleImageRGB.Text = 'Multiple image (RGB)';
            app.multipleImageRGB.Tag = 'multipleImageRGB';
            app.SingleWindowMenu = uimenu(app.multipleImageRGB);
            app.SingleWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @multipleImageSingle_Callback, true);
            app.SingleWindowMenu.Text = 'Single Window';
            app.MultipleWindowsMenu = uimenu(app.multipleImageRGB);
            app.MultipleWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @multipleImageMultipleWindows_Callback, true);
            app.MultipleWindowsMenu.Text = 'Multiple Windows';
            app.menuPlotDisplay = uimenu(app.menuPlot);
            app.menuPlotDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDisplay_Callback, true);
            app.menuPlotDisplay.Text = 'Display color';
            app.menuPlotDisplay.Tag = 'menuPlotDisplay';
            app.plotDisplaySPD = uimenu(app.menuPlotDisplay);
            app.plotDisplaySPD.MenuSelectedFcn = createCallbackFcn(app, @plotDisplaySPD_Callback, true);
            app.plotDisplaySPD.Text = 'SPD of primaries';
            app.plotDisplaySPD.Tag = 'plotDisplaySPD';
            app.plotGamut = uimenu(app.menuPlotDisplay);
            app.plotGamut.MenuSelectedFcn = createCallbackFcn(app, @plotGamut_Callback, true);
            app.plotGamut.Text = 'Gamut';
            app.plotGamut.Tag = 'plotGamut';
            app.plotColorProcessingMatrices = uimenu(app.menuPlot);
            app.plotColorProcessingMatrices.MenuSelectedFcn = createCallbackFcn(app, @plotColorProcessingMatrices_Callback, true);
            app.plotColorProcessingMatrices.Separator = 'on';
            app.plotColorProcessingMatrices.Text = 'Color processing matrices';
            app.plotColorProcessingMatrices.Tag = 'plotColorProcessingMatrices';
            app.plotMCCOverOff = uimenu(app.menuPlot);
            app.plotMCCOverOff.MenuSelectedFcn = createCallbackFcn(app, @plotMCCOverOff_Callback, true);
            app.plotMCCOverOff.Text = 'MCC overlay off';
            app.plotMCCOverOff.Tag = 'plotMCCOverOff';
            app.menuDisplay = uimenu(app.figure1);
            app.menuDisplay.MenuSelectedFcn = createCallbackFcn(app, @menuDisplay_Callback, true);
            app.menuDisplay.Text = 'Display';
            app.menuDisplay.Tag = 'menuDisplay';
            app.menuReadSPD = uimenu(app.menuDisplay);
            app.menuReadSPD.MenuSelectedFcn = createCallbackFcn(app, @menuReadSPD_Callback, true);
            app.menuReadSPD.Text = 'Load display';
            app.menuReadSPD.Tag = 'menuReadSPD';
            app.menuDisplayWindow = uimenu(app.menuDisplay);
            app.menuDisplayWindow.MenuSelectedFcn = createCallbackFcn(app, @menuDisplayWindow_Callback, true);
            app.menuDisplayWindow.Text = 'Display window';
            app.menuDisplayWindow.Tag = 'menuDisplayWindow';
            app.menuDisplayViewD = uimenu(app.menuDisplay);
            app.menuDisplayViewD.MenuSelectedFcn = createCallbackFcn(app, @menuDisplayViewD_Callback, true);
            app.menuDisplayViewD.Separator = 'on';
            app.menuDisplayViewD.Text = 'Set viewing distance (m)';
            app.menuDisplayViewD.Tag = 'menuDisplayViewD';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyze_Callback, true);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.menuAnColor = uimenu(app.menuAnalyze);
            app.menuAnColor.MenuSelectedFcn = createCallbackFcn(app, @menuAnColor_Callback, true);
            app.menuAnColor.Text = 'Color and luminance';
            app.menuAnColor.Tag = 'menuAnColor';
            app.menuAnROI = uimenu(app.menuAnColor);
            app.menuAnROI.MenuSelectedFcn = createCallbackFcn(app, @menuAnROI_Callback, true);
            app.menuAnROI.Text = 'ROI';
            app.menuAnROI.Tag = 'menuAnROI';
            app.menuAnLum = uimenu(app.menuAnROI);
            app.menuAnLum.MenuSelectedFcn = createCallbackFcn(app, @menuAnLum_Callback, true);
            app.menuAnLum.Text = 'Luminance (CIE)';
            app.menuAnLum.Tag = 'menuAnLum';
            app.menuAnChrom = uimenu(app.menuAnROI);
            app.menuAnChrom.MenuSelectedFcn = createCallbackFcn(app, @menuAnChrom_Callback, true);
            app.menuAnChrom.Text = 'Chromaticity (CIE xy)';
            app.menuAnChrom.Tag = 'menuAnChrom';
            app.menuAnColorLAB = uimenu(app.menuAnROI);
            app.menuAnColorLAB.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorLAB_Callback, true);
            app.menuAnColorLAB.Separator = 'on';
            app.menuAnColorLAB.Text = 'CIELAB';
            app.menuAnColorLAB.Tag = 'menuAnColorLAB';
            app.menuAnLUV = uimenu(app.menuAnROI);
            app.menuAnLUV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLUV_Callback, true);
            app.menuAnLUV.Text = 'CIELUV';
            app.menuAnLUV.Tag = 'menuAnLUV';
            app.menuRGBHist = uimenu(app.menuAnROI);
            app.menuRGBHist.MenuSelectedFcn = createCallbackFcn(app, @menuRGBHist_Callback, true);
            app.menuRGBHist.Text = 'RGB histogram';
            app.menuRGBHist.Tag = 'menuRGBHist';
            app.menuAnROIvSNR = uimenu(app.menuAnColor);
            app.menuAnROIvSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnROIvSNR_Callback, true);
            app.menuAnROIvSNR.Text = 'vSNR';
            app.menuAnROIvSNR.Tag = 'menuAnROIvSNR';
            app.chartClearcornersMenu = uimenu(app.menuAnColor);
            app.chartClearcornersMenu.MenuSelectedFcn = createCallbackFcn(app, @chartClearCorners, true);
            app.chartClearcornersMenu.Separator = 'on';
            app.chartClearcornersMenu.Text = 'Clear chart corners';
            app.MCCMenu = uimenu(app.menuAnColor);
            app.MCCMenu.Text = 'MCC';
            app.LuminancenoiseMenu = uimenu(app.MCCMenu);
            app.LuminancenoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnColLumNoise_Callback, true);
            app.LuminancenoiseMenu.Text = 'Luminance noise';
            app.ColormetricssRGBMenu = uimenu(app.MCCMenu);
            app.ColormetricssRGBMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnColMCCsRGB_Callback, true);
            app.ColormetricssRGBMenu.Text = 'Color metrics (sRGB)';
            app.VisualcompareD65Menu = uimenu(app.MCCMenu);
            app.VisualcompareD65Menu.MenuSelectedFcn = createCallbackFcn(app, @menuAnColVisualComp_Callback, true);
            app.VisualcompareD65Menu.Text = 'Visual compare (D65)';
            app.menuAnLine = uimenu(app.menuAnalyze);
            app.menuAnLine.MenuSelectedFcn = createCallbackFcn(app, @menuAnLine_Callback, true);
            app.menuAnLine.Separator = 'on';
            app.menuAnLine.Text = 'Line intensities (RGB)';
            app.menuAnLine.Tag = 'menuAnLine';
            app.menuAnLineH = uimenu(app.menuAnLine);
            app.menuAnLineH.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineH_Callback, true);
            app.menuAnLineH.Text = 'Horizontal';
            app.menuAnLineH.Tag = 'menuAnLineH';
            app.menuAnLineV = uimenu(app.menuAnLine);
            app.menuAnLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineV_Callback, true);
            app.menuAnLineV.Text = 'Vertical';
            app.menuAnLineV.Tag = 'menuAnLineV';
            app.LineluminanceMenu = uimenu(app.menuAnalyze);
            app.LineluminanceMenu.Text = 'Line luminance';
            app.HorizontalMenu = uimenu(app.LineluminanceMenu);
            app.HorizontalMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnnLineLuminanceHorizontal, true);
            app.HorizontalMenu.Text = 'Horizontal';
            app.VerticalMenu = uimenu(app.LineluminanceMenu);
            app.VerticalMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnnLineLuminanceVertical, true);
            app.VerticalMenu.Text = 'Vertical';
            app.menuAnalyzeCreateSB = uimenu(app.menuAnalyze);
            app.menuAnalyzeCreateSB.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeCreateSB_Callback, true);
            app.menuAnalyzeCreateSB.Separator = 'on';
            app.menuAnalyzeCreateSB.Text = 'Create slanted bar';
            app.menuAnalyzeCreateSB.Tag = 'menuAnalyzeCreateSB';
            app.menuAnalyzeISO12233 = uimenu(app.menuAnalyze);
            app.menuAnalyzeISO12233.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeISO12233_Callback, true);
            app.menuAnalyzeISO12233.Text = 'ISO 12233 (slanted bar)';
            app.menuAnalyzeISO12233.Tag = 'menuAnalyzeISO12233';
            app.menuMetricsWindow = uimenu(app.menuAnalyze);
            app.menuMetricsWindow.MenuSelectedFcn = createCallbackFcn(app, @menuMetricsWindow_Callback, true);
            app.menuMetricsWindow.Separator = 'on';
            app.menuMetricsWindow.Text = 'Metrics window';
            app.menuMetricsWindow.Tag = 'menuMetricsWindow';
            app.menuAnComputeFromSensor = uimenu(app.menuAnalyze);
            app.menuAnComputeFromSensor.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromSensor_Callback, true);
            app.menuAnComputeFromSensor.Separator = 'on';
            app.menuAnComputeFromSensor.Text = 'Compute from sensor';
            app.menuAnComputeFromSensor.Tag = 'menuAnComputeFromSensor';
            app.menuComputeFromOI = uimenu(app.menuAnalyze);
            app.menuComputeFromOI.MenuSelectedFcn = createCallbackFcn(app, @menuComputeFromOI_Callback, true);
            app.menuComputeFromOI.Text = 'Compute from OI';
            app.menuComputeFromOI.Tag = 'menuComputeFromOI';
            app.menuAnComputeFromScene = uimenu(app.menuAnalyze);
            app.menuAnComputeFromScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromScene_Callback, true);
            app.menuAnComputeFromScene.Text = 'Compute from scene';
            app.menuAnComputeFromScene.Tag = 'menuAnComputeFromScene';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpProcessorOnline = uimenu(app.menuHelp);
            app.menuHelpProcessorOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpProcessorOnline_Callback, true);
            app.menuHelpProcessorOnline.Text = 'Processor functions';
            app.menuHelpProcessorOnline.Tag = 'menuHelpProcessorOnline';
            app.menuHelpMetricsPG = uimenu(app.menuHelp);
            app.menuHelpMetricsPG.MenuSelectedFcn = createCallbackFcn(app, @menuHelpMetricsPG_Callback, true);
            app.menuHelpMetricsPG.Text = 'Metrics functions';
            app.menuHelpMetricsPG.Tag = 'menuHelpMetricsPG';
            app.menuHelpISETOnline = uimenu(app.menuHelp);
            app.menuHelpISETOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpISETOnline_Callback, true);
            app.menuHelpISETOnline.Text = 'ISET functions';
            app.menuHelpISETOnline.Tag = 'menuHelpISETOnline';
            app.menuHelpAppNotes = uimenu(app.menuHelp);
            app.menuHelpAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuHelpAppNotes_Callback, true);
            app.menuHelpAppNotes.Text = 'Documentation (web)';
            app.menuHelpAppNotes.Tag = 'menuHelpAppNotes';
            app.ProcessedimagePanel = uipanel(app.figure1);
            app.ProcessedimagePanel.Title = 'Processed image';
            app.ipImage = uiaxes(app.ProcessedimagePanel);
            app.ipImage.XLim = [0 1];
            app.ipImage.YLim = [0 1];
            app.ipImage.ZLim = [0 1];
            app.ipImage.CLim = [0 1];
            app.ipImage.ALim = [0 1];
            app.ipImage.XTickLabel = '';
            app.ipImage.YTick = zeros(1,0);
            app.ipImage.YTickLabel = '';
            app.ipImage.ZTick = [0 0.5 1];
            app.ipImage.ZTickLabel = '';
            app.ipImage.Tag = 'axes1';
            app.popSelect = uidropdown(app.ProcessedimagePanel);
            app.popSelect.Items = {'New'};
            app.popSelect.ValueChangedFcn = createCallbackFcn(app, @popSelect_Callback, true);
            app.popSelect.Tag = 'popSelect';
            app.popSelect.Value = 'New';
            app.btnNext = uibutton(app.ProcessedimagePanel, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Icon = 'rightArrow.png';
            app.btnNext.Tooltip = 'Show next sensor';
            app.btnNext.Text = '';
            app.btnPrev = uibutton(app.ProcessedimagePanel, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Icon = 'leftArrow.png';
            app.btnPrev.Tooltip = 'Show previous sensor';
            app.btnPrev.Text = '';
            app.text10 = uilabel(app.ProcessedimagePanel);
            app.text10.Tag = 'text10';
            app.text10.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.text10.Text = 'Gamma';
            app.editGamma = uieditfield(app.ProcessedimagePanel, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Tooltip = 'Gamma for display';
            app.editGamma.Value = '1';
            app.txtMessage = uilabel(app.ProcessedimagePanel);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.MaxbrightSwitchLabel = uilabel(app.ProcessedimagePanel);
            app.MaxbrightSwitchLabel.Text = 'Max bright';
            app.MaxbrightSwitch = uiswitch(app.ProcessedimagePanel, 'slider');
            app.MaxbrightSwitch.ValueChangedFcn = createCallbackFcn(app, @switchMaxbright, true);
            app.MaxbrightSwitch.Value = 'On';
            app.popupRender = uidropdown(app.ProcessedimagePanel);
            app.popupRender.Items = {'rgb', 'hdr', 'gray'};
            app.popupRender.ValueChangedFcn = createCallbackFcn(app, @renderMethod, true);
            app.popupRender.Value = 'rgb';
            app.ipTable = uitable(app.figure1);
            app.ipTable.ColumnName = {'Parameter'; 'Value'};
            app.ipTable.RowName = {};
            app.ipTable.CellSelectionCallback = createCallbackFcn(app, @ipDescription, true);
            app.ColortransformsPanel = uipanel(app.figure1);
            app.ColortransformsPanel.Title = 'Color transforms';
            app.popTransform = uidropdown(app.ColortransformsPanel);
            app.popTransform.Items = {'current', 'new', 'adaptive', 'rgbwrestormer'};
            app.popTransform.ValueChangedFcn = createCallbackFcn(app, @popTransform_Callback, true);
            app.popTransform.Tag = 'popTransform';
            app.popTransform.Tooltip = 'Select transform - current, enter new one, or use adaptive processing';
            app.popTransform.Value = 'adaptive';
            app.popColorConversionM = uidropdown(app.ColortransformsPanel);
            app.popColorConversionM.Items = {'mcc optimized', 'manual matrix entry', 'current matrix', 'none'};
            app.popColorConversionM.ValueChangedFcn = createCallbackFcn(app, @popColorConversionM_Callback, true);
            app.popColorConversionM.Tag = 'popColorConversionM';
            app.popColorConversionM.Value = 'mcc optimized';
            app.txtMethod = uilabel(app.ColortransformsPanel);
            app.txtMethod.Tag = 'txtMethod';
            app.txtMethod.Tooltip = {'Use the current matrix transform, set a new transform, or recalculate'};
            app.txtMethod.Text = 'Transform';
            app.popColorSpace = uidropdown(app.ColortransformsPanel);
            app.popColorSpace.Items = {'sensor', 'xyz', 'stockman'};
            app.popColorSpace.ValueChangedFcn = createCallbackFcn(app, @popColorSpace_Callback, true);
            app.popColorSpace.Tag = 'popColorSpace';
            app.popColorSpace.Value = 'xyz';
            app.txtICS = uilabel(app.ColortransformsPanel);
            app.txtICS.Tag = 'txtICS';
            app.txtICS.Tooltip = {'Calibrated color space '};
            app.txtICS.Text = 'Color space';
            app.popBalance = uidropdown(app.ColortransformsPanel);
            app.popBalance.Items = {'none', 'gray world', 'white world'};
            app.popBalance.ValueChangedFcn = createCallbackFcn(app, @popBalance_Callback, true);
            app.popBalance.Tag = 'popBalance';
            app.popBalance.Tooltip = 'Color balancing method options';
            app.popBalance.Value = 'gray world';
            app.txtIlluminant = uilabel(app.ColortransformsPanel);
            app.txtIlluminant.Tag = 'txtIlluminant';
            app.txtIlluminant.Tooltip = 'Illuminant correction method';
            app.txtIlluminant.Text = 'Illuminant correction';
            app.txtSensor = uilabel(app.ColortransformsPanel);
            app.txtSensor.Tag = 'txtICS';
            app.txtSensor.Tooltip = {'Method for converting from sensor to calibrated color space'};
            app.txtSensor.Text = 'Sensor conversion';
            app.DemosaicPanel = uipanel(app.figure1);
            app.DemosaicPanel.Title = 'Demosaic';
            app.popDemosaic = uidropdown(app.DemosaicPanel);
            app.popDemosaic.Items = {'bilinear', 'laplacian', 'adaptive laplacian', 'skip', '', 'nearest neighbor', 'multichannel', 'analog rccc', 'pocs'};
            app.popDemosaic.ValueChangedFcn = createCallbackFcn(app, @popDemosaic_Callback, true);
            app.popDemosaic.Tag = 'popDemosaic';
            app.popDemosaic.Value = 'bilinear';
            app.btnCompute = uibutton(app.figure1, 'push');
            app.btnCompute.ButtonPushedFcn = createCallbackFcn(app, @btnCompute_Callback, true);
            app.btnCompute.Tag = 'btnCompute';
            app.btnCompute.Text = 'Compute Display  Image';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ipWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)ipWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end