classdef ieScoreWindow < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        GridLayout    matlab.ui.container.GridLayout
        LeftPanel     matlab.ui.container.Panel
        tblScore      matlab.ui.control.Table
        RightPanel    matlab.ui.container.Panel
        TabGroup      matlab.ui.container.TabGroup
        OriginalTab   matlab.ui.container.Tab
        imgOriginal   matlab.ui.control.Image
        ProcessedTab  matlab.ui.container.Tab
        imgProcessed  matlab.ui.control.Image
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        mainapp % Calling app window
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Name = sprintf("Scores for your %s test run", mainapp.ourClassifier);
            app.tblScore.ColumnName = {'Original:', 'Processed:', 'Path to Original image:', 'Path to Processed image:'};
            app.tblScore.ColumnWidth = {floor(app.tblScore.Position(3)/2), floor(app.tblScore.Position(3)/2)};
            app.tblScore.RowName = "";
            %tblScore.CellSelectionCallback = createCallbackFcn(app, @ImageFileClickedCallback, true);
            app.tblScore.Data = mainapp.scoreTable;
            app.mainapp = mainapp; % stash the calling window so we can query it!

        end

        % Cell selection callback: tblScore
        function tblScoreCellSelection(app, event)
            indices = event.Indices;
            try % hope we get a click on the right thing:)
                if indices(2) == 2 % right column
                    origFName = app.mainapp.scoreTable(indices(1), indices(2)+1);
                    processedFName = app.mainapp.scoreTable(indices(1), indices(2)+2);
                    if isfile(origFName)
                        % set image file!
                        app.imgOriginal.ImageSource = origFName;
                    end
                    if isfile(processedFName)
                        app.imgProcessed.ImageSource = processedFName;
                    end
                end
            catch
                uialert(app.UIFigure, "Click on one of the filenames to see the image.");
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {456, 456};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {239, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.tblScore = uitable(app.LeftPanel);
            app.tblScore.ColumnName = {'Original:'; 'Processed:'};
            app.tblScore.RowName = {};
            app.tblScore.CellSelectionCallback = createCallbackFcn(app, @tblScoreCellSelection, true);
            app.tblScore.Tooltip = {'Score information for each tested image. Click on an image''s filename to see a preview on the right.'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.OriginalTab = uitab(app.TabGroup);
            app.OriginalTab.Title = 'Original';
            app.imgOriginal = uiimage(app.OriginalTab);
            app.ProcessedTab = uitab(app.TabGroup);
            app.ProcessedTab.Title = 'Processed';
            app.imgProcessed = uiimage(app.ProcessedTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieScoreWindow(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end