classdef oiWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        FileMenu                   matlab.ui.container.Menu
        menuFileLoadOI             matlab.ui.container.Menu
        menuFileSaveOI             matlab.ui.container.Menu
        menuSaveImage              matlab.ui.container.Menu
        menuFileRefresh            matlab.ui.container.Menu
        menuFileClose              matlab.ui.container.Menu
        EditMenu                   matlab.ui.container.Menu
        menuEditName               matlab.ui.container.Menu
        menuEditCreate             matlab.ui.container.Menu
        menuCopyOI                 matlab.ui.container.Menu
        menuEditDelete             matlab.ui.container.Menu
        menuEditDeleteSome         matlab.ui.container.Menu
        menuEditScale              matlab.ui.container.Menu
        RemoveborderMenu           matlab.ui.container.Menu
        DenoiseMenu                matlab.ui.container.Menu
        menuEditFontSize           matlab.ui.container.Menu
        menuEditClearMessage       matlab.ui.container.Menu
        menuEditViewer             matlab.ui.container.Menu
        PlotMenu                   matlab.ui.container.Menu
        plotIrradiance             matlab.ui.container.Menu
        menuPlotIrradEnergy        matlab.ui.container.Menu
        menuPlotRGB                matlab.ui.container.Menu
        menuPlotImageGrid          matlab.ui.container.Menu
        menuPlotMultiRGB           matlab.ui.container.Menu
        SingleWindowMenu           matlab.ui.container.Menu
        MultipleWindowsMenu        matlab.ui.container.Menu
        menuPlotImageSpectral      matlab.ui.container.Menu
        menuPlotDepthmap           matlab.ui.container.Menu
        menuPlotDepthContour       matlab.ui.container.Menu
        menuOptics                 matlab.ui.container.Menu
        menuStandForm              matlab.ui.container.Menu
        menuOpticsQuarterInch      matlab.ui.container.Menu
        menuOpticsThird            matlab.ui.container.Menu
        menuOpticsHalfInch         matlab.ui.container.Menu
        menuOpticsTwoThirds        matlab.ui.container.Menu
        menuOpticsInch             matlab.ui.container.Menu
        menuHuman                  matlab.ui.container.Menu
        menuOpticsHuman            matlab.ui.container.Menu
        menuMacular                matlab.ui.container.Menu
        menuMacular028             matlab.ui.container.Menu
        menuOpticsRayTraceParam    matlab.ui.container.Menu
        menuOptTrans               matlab.ui.container.Menu
        menuOpticsRename           matlab.ui.container.Menu
        menuOpticsImport           matlab.ui.container.Menu
        menuOpticsExport           matlab.ui.container.Menu
        menuOpticsLoadSI           matlab.ui.container.Menu
        menuOpticsConvertCV        matlab.ui.container.Menu
        menuOpticsConvertZM        matlab.ui.container.Menu
        menuAn                     matlab.ui.container.Menu
        menuPlOp                   matlab.ui.container.Menu
        menuAnOpticsPSF            matlab.ui.container.Menu
        menuPlotPS550              matlab.ui.container.Menu
        menuAnPSFMovie             matlab.ui.container.Menu
        menuPlotLSWave             matlab.ui.container.Menu
        menuOTFAnyWave             matlab.ui.container.Menu
        plotOTF                    matlab.ui.container.Menu
        menuPlOTFWave              matlab.ui.container.Menu
        menuAnOpImFieldAndWave     matlab.ui.container.Menu
        menuAnalyzeOptFieldD       matlab.ui.container.Menu
        menuAnOptSampling          matlab.ui.container.Menu
        menuPlotOffAxis            matlab.ui.container.Menu
        menuTransmittance          matlab.ui.container.Menu
        menuAnLineIllum            matlab.ui.container.Menu
        menuAnLineIllumHorizontal  matlab.ui.container.Menu
        menuAnLineIllumHorFFT      matlab.ui.container.Menu
        menuAnLineIllumVertical    matlab.ui.container.Menu
        menuAnLineIllumVertFFT     matlab.ui.container.Menu
        menuAnalyzeLinePlots       matlab.ui.container.Menu
        menuHline                  matlab.ui.container.Menu
        menuVLine                  matlab.ui.container.Menu
        menuPlotHLContrast         matlab.ui.container.Menu
        menuPlotVLContrast         matlab.ui.container.Menu
        menuROISummaries           matlab.ui.container.Menu
        menuPlotLuxHist            matlab.ui.container.Menu
        menuPlotCIE                matlab.ui.container.Menu
        menuPlotIllumLog           matlab.ui.container.Menu
        menuPlotIllumLin           matlab.ui.container.Menu
        menuFFTamp                 matlab.ui.container.Menu
        menuHelp                   matlab.ui.container.Menu
        menuHelpOIOnline           matlab.ui.container.Menu
        menuHelpOpticsOnline       matlab.ui.container.Menu
        menuHelpISETOnline         matlab.ui.container.Menu
        menuHelpAppNotes           matlab.ui.container.Menu
        OpticssettingsPanel        matlab.ui.container.Panel
        popDiffuser                matlab.ui.control.DropDown
        txtDiffuser                matlab.ui.control.Label
        txtBlurSD                  matlab.ui.control.Label
        editDiffuserBlur           matlab.ui.control.EditField
        Cos4thSwitch               matlab.ui.control.Switch
        Cos4thSwitchLabel          matlab.ui.control.Label
        btnSimulate                matlab.ui.control.Button
        txtFnumber                 matlab.ui.control.Label
        popOpticsModel             matlab.ui.control.DropDown
        editFnumber                matlab.ui.control.EditField
        txtFocalLength             matlab.ui.control.Label
        editFocalLength            matlab.ui.control.EditField
        UITable                    matlab.ui.control.Table
        btnNext                    matlab.ui.control.Button
        btnPrev                    matlab.ui.control.Button
        popupRender                matlab.ui.control.DropDown
        pulldownSelectOI           matlab.ui.control.DropDown
        text32                     matlab.ui.control.Label
        txtMessage                 matlab.ui.control.Label
        txtDisplayGamma            matlab.ui.control.Label
        editGamma                  matlab.ui.control.EditField
        oiImage                    matlab.ui.control.UIAxes
    end


    methods (Access = private)
        % These seem to be cases appdesigner couldn't convert
        % knowledgeably.  Not sure what to do with them yet.
        
        function btnDiffuser_Callback(app)
            % Turn on or off diffuser simulation
            % I think this is obsolete now, replaced by the popup for the diffuser
            % popDiffuser
            %
            [val,oi] = vcGetSelectedObject('OPTICALIMAGE');
            if get(hObject,'Value')
                oi = oiSet(oi,'diffuser Method','blur');
                ieInWindowMessage([],app,[]);
            else
                oi = oiSet(oi,'diffuser Method','skip');
                ieInWindowMessage([],app,[]);
            end
            
            ieReplaceObject(oi,val);
            app.refresh;
        end
        
        %{
        function btnOTF_Callback(app, hObject)
            % Button for diffraction limited OTF
            
            [val,oi] = vcGetSelectedObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            
            if get(hObject,'Value')
                optics = opticsSet(optics,'otfmethod','dlMTF');
                ieInWindowMessage([],app,[]);
            else
                optics = opticsSet(optics,'otfmethod','skip');
                ieInWindowMessage([],app,[]);
            end
            oi = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            app.refresh;
        end
        %}
        %{
        function editDefocus_CreateFcn(app)
            
            if ispc, set(hObject,'BackgroundColor','white');
            else, set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function menuOpticsExports_Callback(app)
            [~,optics] = vcGetSelectedObject('OPTICS');
            vcExportObject(optics);
        end
        
        function menuPlCIE_Callback(app)
        end
        %}
        
        function oiDelete(app)
            % Edit | Delete Current OI
            vcDeleteSelectedObject('OPTICALIMAGE');
            app.refresh;
        end
        
        function oiAdd(app)
            newOI = ieGetObject('opticalimage');
            newOI.name = vcNewObjectName('opticalimage');
            newOI.type = 'opticalimage';
            newOI      = oiClearData(newOI);
            
            ieAddObject(newOI)
            app.refresh;
        end
        
        %%%%%%%%%%%%%%%
        function oiOpen(app)
            % Initialize oiWindow
            %
            %    oiOpen(app)
            
            dbApp = ieSessionGet('oi window');
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('oi window',app);
            end
            
            app.figure1.Name = sprintf('ISET OI %s',datetime('now','Format','HH:mm:ss'));
            
            app.refresh;

            % Check the preferences for ISET and adjust the font size.
            ieFontInit(app.figure1);
        end
        
        %%%%%%%%%%%%%%%%
        function oiClose(app)
            % If we are closing a selected window in the database, remove it from the
            % database.
            W = ieSessionGet('oi window');
            if isequal(W,app)
                ieSessionSet('oi window',[]);
            end
            
            % Close the window.
            closereq;
            delete(app);
        end
    end
    
    methods (Access = public)
        
        function refresh(app)
            % Refresh the buttons and edit fields in the optical image window
            %
            % Synopsis
            %   oiSetEditsAndButtons(app)
            %
            % Description
            %  Refresh the current optical image window. If there is no optical image,
            %  then a default oi is created.
            %
            %  Perhaps we should have a flag to leave the inline message in place?
            %
            % Copyright ImagEval Consultants, LLC, 2003.
            %
            % See also
            %
            
            [oi, val] = ieGetObject('OPTICALIMAGE');
            if isempty(oi)
                oi = oiCreate;
                ieAddObject(oi);
                val = 1;
            end
            
            %% Clear the inline message
            optics = oiGet(oi,'optics');
            ieInWindowMessage('',app,[]);
            
            % Which optics model
            opticsModel = opticsGet(optics,'model');
            
            % Get a numbered list of names
            oiNames = vcGetObjectNames('OPTICALIMAGE',true);
            
            % Prepend the oi names to 'New'
            Items = cellMerge({'New OI'}, oiNames);
            
            app.pulldownSelectOI.Items = Items;
            app.pulldownSelectOI.Value = Items{1 + val};
            
            %% Buttons
            % Check the custom compute
            switch lower(opticsModel)
                
                case {'diffractionlimited','dlmtf'}
                    app.popOpticsModel.Value = app.popOpticsModel.Items{1};
                    
                    % Set the diffraction limited optics parameters
                    optics = oiGet(oi,'optics');
                    str = sprintf('%2.2f',opticsGet(optics,'focalLength','mm'));
                    app.editFocalLength.Value = str;
                    
                    str = sprintf('%1.2f',opticsGet(optics,'fnumber'));
                    app.editFnumber.Value = str;
                    switchControlVisibility(app,'on');
                    
                case 'shiftinvariant'
                    % The SI model may have a wvf attached, or not.
                    app.popOpticsModel.Value = app.popOpticsModel.Items{2};
                    switchControlVisibility(app,'off');
                    
                case 'raytrace'
                    app.popOpticsModel.Value = app.popOpticsModel.Items{3};
                    switchControlVisibility(app,'off');
                    
                case 'iset3d'
                    app.popOpticsModel.Value = app.popOpticsModel.Items{4};
                    switchControlVisibility(app,'off');
                    
                case 'skip'
                    app.popOpticsModel.Value = app.popOpticsModel.Items{4};
                    switchControlVisibility(app,'off');
                    
                otherwise
                    error('Unknown optics model')
            end
            
            %% Based on the anti-alias filter pulldown selection
            dMethod = oiGet(oi,'diffuser method');
            
            switch lower(dMethod)
                case 'skip'
                    app.popDiffuser.Value =  app.popDiffuser.Items{1};
                    app.editDiffuserBlur.Visible = 'off';
                    app.txtBlurSD.Visible = 'off';
                case 'blur'
                    app.popDiffuser.Value =  app.popDiffuser.Items{2};
                    app.editDiffuserBlur.Visible = 'on';
                    app.txtBlurSD.Text = 'FWHM (um)';
                    app.txtBlurSD.Visible = 'on';
                    app.txtBlurSD.Tooltip = 'Full-width half-max Gaussian spread';
                    
                    val = oiGet(oi,'diffuserBlur','um');
                    if isempty(val)
                        val = ieReadNumber('Enter blur sd (FWHM, um)',2,' %.2f');
                        oi = oiSet(oi,'diffuserBlur',val*10^-6);
                        vcReplaceObject(oi);
                    end
                    app.editDiffuserBlur.Value = num2str(val);
                    
                case 'birefringent'
                    app.popDiffuser.Value =  app.popDiffuser.Items{3};
                    
                    % For now off, but if we decide to allow the value to change, then
                    % we can use the buttons like this.
                    app.editDiffuserBlur.Visible = 'on';
                    app.txtBlurSD.Visible,'on';
                    app.txtBlurSD.Text = 'Displacement (um)';
                    app.txtBlurSD.Tooltip ='Birefringent displacement (um)';
                otherwise
                    error('Unknown diffuser method %s\n',dMethod);
            end
            
            % The relative illumination is controlled by the cos4th switch in the
            % window.  We make sure they are consistent here.
            switch lower(app.Cos4thSwitch.Value)
                case 'on'
                    oi = oiSet(oi,'optics relative illumination','cos4th');
                case 'off'
                    oi = oiSet(oi,'optics relative illumination','skip');
            end
            
            %% Information table
            app.oiDisplayData(oi);
            
            %% Display in axis
            
            gam = str2double(app.editGamma.Value);
            
            % Select the OI figure
            figure(app.figure1);
            
            % For NIR, SWIR and so forth we might use a different displayFlag value.
            % See oiShowImage.  In the future, we will read the displayFlag from
            % either a global or a setting in the oi GUI.
            renderFlag = oiGet(oi,'render flag index');
            
            oiShowImage(oi,renderFlag,gam,app);
            
        end
        
        %------------------------------------------------------
        function switchControlVisibility(app,state)
            %Turn on/off the diffraction limited buttons and edit fields
            %On turns on the diffraction.
            %Off turns off the diffraction and puts up the custom popup menu
            %
            
            switch state
                case 'on'
                    app.txtFocalLength.Visible  ='on';
                    app.editFocalLength.Visible ='on';
                    
                    app.txtFnumber.Visible  = 'on';
                    app.editFnumber.Visible = 'on';
                    
                    app.txtMessage.Visible='on';
                    % set(handles.btnOffAxis.Visible,'on');
                    % set(handles.txtDiffractionLimitedOptics.Visible,'on');
                    app.editDiffuserBlur.Visible='on';
                    app.txtBlurSD.Visible='on';
                    
                case 'off'
                    app.txtFocalLength.Visible  = 'off';
                    app.editFocalLength.Visible = 'off';
                    
                    app.txtFnumber.Visible  = 'off';
                    app.editFnumber.Visible = 'off';
                    
                    app.txtMessage.Visible = 'off';
                    % set(handles.btnOffAxis.Visible,'off');
                    % set(handles.txtDiffractionLimitedOptics.Visible,'off');
                    
                    app.editDiffuserBlur.Visible = 'off';
                    app.txtBlurSD.Visible = 'off';
                    
                otherwise
                    error('Unknown state %s.\n',state);
            end
            
        end
        
        function oiDisplayData(app, oi)
            %
            %  iePTable(sceneCreate);
            %
            iePTable(oi,'uitable',app.UITable,'format','embed');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function oiWindow_OpeningFcn(app, varargin)
            % Permits calling as oiWindow(scene);
            % for now don't confuse users with new icons on existing
            % windows
            %if ~verLessThan('matlab','9.9') && getpref('ISET','customicons', false)
            %    app.figure1.Icon = 'FHV_Cover_Logo.png';
            %end
            % We might test to see if there is an existing window open
            % already before we do this.  But as currently organized,
            % anytime a new oiWindow is open, we set that window's app into
            % the session.
            ieSessionSet('oi window',app);
            
            if ~isempty(varargin) ...
                    && isstruct(varargin{1}) ...
                    && strcmp(varargin{1}.type,'opticalimage')
                oi = varargin{1};
                ieAddObject(oi);
            end
            
            app.figure1.Visible = false; % see if we can set the entire thing to false!
            
            % see if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.oiImage.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.oiImage.Toolbar = [];
            end
            
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                s = get(0);
                wPos = ISETprefs.wPos; % All the window positions
                thisW = 3;  % OI window stored in this slot
                if numel(wPos{thisW}) >= 2
                    if wPos{thisW}(1) < 1 % we have old-style
                        app.figure1.Position(1:2) = round(wPos{thisW}(1:2) .* s.ScreenSize(3:4));
                    else
                        app.figure1.Position(1:2) = wPos{thisW}(1:2);
                    end
                end
            end
            oiOpen(app);
            app.refresh;
            
            % Force a font size refresh
            ieFontSizeSet(app.figure1,0);

            app.figure1.Visible = true;
            
        end

        % Menu selected function: EditMenu
        function EditMenu_Callback(app, event)
            
        end

        % Menu selected function: FileMenu
        function FileMenu_Callback(app, event)
            
        end

        % Menu selected function: PlotMenu
        function PlotMenu_Callback(app, event)
            % Plot Menu
        end

        % Value changed function: pulldownSelectOI
        function pulldownSelectOI_Callback(app, event)
            % Pulldown for selecting the new optical image
            thisName = app.pulldownSelectOI.Value;
            
            switch ieParamFormat(thisName)
                case 'newoi'
                    oiAdd(app);
                otherwise
                    val = find(contains(app.pulldownSelectOI.Items,app.pulldownSelectOI.Value)) - 1;
                    val = max(1,val);
                    vcSetSelectedObject('OPTICALIMAGE',val);
            end
            app.refresh;
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            
            % Button to move to previous image
            s  = ieSessionGet('selected','oi');
            nS = ieSessionGet('nobjects','oi');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('oi',s);
            app.refresh;
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            
            % Button to move to previous image
            s  = ieSessionGet('selected','oi');
            nS = ieSessionGet('nobjects','oi');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('oi',s);
            app.refresh;
        end

        % Button pushed function: btnSimulate
        function btnSimulate_Callback(app, event)
            % This call back reads the current scene and optics and then calculates a
            % the optical image irradiance with the current parameters.   We do not
            % calculate a new optical image. Probably, we should put this in a separate
            % function rather than keeping it in here.
            
            scene = ieGetObject('scene');
            if isempty(scene), ieInWindowMessage('No scene data.',app); beep; return;
            else, ieInWindowMessage('',app); end
            
            oi = ieGetObject('OPTICALIMAGE');
            
            app.figure1.Pointer = 'watch';
            % We now check within oiCompute whether the custom button is selected or
            % not.
            oi = oiCompute(oi,scene);
            
            % oi = oiSet(oi,'consistency',1);
            
            % Save the OI in the vcSESSION as the selected optical image.
            vcReplaceAndSelectObject(oi);
            
            % hObject = oiwindow;
            app.refresh;
            app.figure1.Pointer = 'arrow';
            
        end

        % Value changed function: editDiffuserBlur
        function editDiffuserBlur_Callback(app, event)
            % Set FWHM (um) of the diffuser
            %
            [val,oi] = vcGetSelectedObject('OPTICALIMAGE');
            
            % returns contents of editDiffuserBlur as a double
            blur = str2double(get(hObject,'String'));
            
            oi = oiSet(oi,'diffuserBlur',blur*10^-6);   % Stored in meters
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Value changed function: editFnumber
        function editFnumber_Callback(app, event)
            % Edit box, fnumber
            
            [oi,val] = ieGetObject('oi');
            
            fNumber = str2double(app.editFnumber.Value);
            oi = oiSet(oi,'optics fnumber',fNumber);
            
            ieReplaceObject(oi,val);
            app.refresh;
            
        end

        % Value changed function: editFocalLength
        function editFocalLength_Callback(app, event)
            
            [oi,val] = ieGetObject('OPTICALIMAGE');
            
            % Get the optics from the window and set it in the oi
            % Focal length is displayed in millimeters but stored in meters
            focalLength = str2double(app.editFocalLength.Value)/1e3;
            
            oi = oiSet(oi,'optics focal length',focalLength);
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % When we refresh the GUI the value is read and the image is displayed
            % with the new gamma value.
            app.refresh;
            
        end

        % Menu selected function: menuAnLineIllumHorFFT
        function menuAnLineIllumHorFFT_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            oiPlot(ieGetObject('OI'),'illuminance fft hline',[],app);
            
        end

        % Menu selected function: menuAnLineIllumHorizontal
        function menuAnLineIllumHorizontal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            oiPlot(ieGetObject('OI'),'illuminance hline',[],app);
            
        end

        % Menu selected function: menuAnLineIllumVertFFT
        function menuAnLineIllumVertFFT_Callback(app, event)
            
            oiPlot(ieGetObject('OI'),'illuminance fft vline',[],app);
            
        end

        % Menu selected function: menuAnLineIllumVertical
        function menuAnLineIllumVertical_Callback(app, event)
            
            oiPlot(ieGetObject('OI'),'illuminance vline',[],app);
            
        end

        % Menu selected function: menuAnLineIllum
        function menuAnLineIllum_Callback(app, event)
            
        end

        % Menu selected function: menuAnOpImFieldAndWave
        function menuAnOpImFieldAndWave_Callback(app, event)
            % Analyze | Optics | PSF images (rt)
            
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'psfimages');
                otherwise
                    ieInWindowMessage('No psf images for SI and diffraction.',app);
                    disp('Not yet implemented')
            end
            
            
        end

        % Menu selected function: menuAnOptSampling
        function menuAnOptSampling_Callback(app, event)
            
            % The image sampling rate supports a certain spatial frequency. The
            % diffraction limited optics supports a certain spatial frequency. We only
            % obtain a very accurate spatial representation when the image sampling
            % supports a representation as high as the diffraction limited optics.
            % Otherwise, the higher spatial frequencies are not represented in the
            % result.
            %
            % In many cases, people will leave the lower sampling rate, which provides
            % speed but blurs the image, because they are interested in other features
            % of the simulation.
            oi       = ieGetObject('oi');
            inCutoff = opticsGet(oiGet(oi,'optics'),'maxincutoff','mm');
            maxFres  = oiGet(oi,'maxFreqRes','mm');
            str = sprintf('DL cutoff: %.2f - Samp cutoff %.2f (cyc/mm)\n',inCutoff,maxFres);
            ieInWindowMessage(str,app);
            
        end

        % Menu selected function: menuAnOpticsPSF
        function menuAnOpticsPSF_Callback(app, event)
            
            % Analyze | Optics | PSF
            
            oi          = ieGetObject('OPTICALIMAGE');
            optics      = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'psf');
                otherwise
                    oiPlot(oi,'psf',[],app);
            end
            
        end

        % Menu selected function: menuAnPSFMovie
        function menuAnPSFMovie_Callback(app, event)
            psfMovie;
        end

        % Menu selected function: menuAn
        function menuAn_Callback(app, event)
            
        end

        % Menu selected function: menuAnalyzeLinePlots
        function menuAnalyzeLinePlots_Callback(app, event)
            
        end

        % Menu selected function: menuAnalyzeOptFieldD
        function menuAnalyzeOptFieldD_Callback(app, event)
            
            if isempty(which('rtRootPath'))
                warndlg('Optics toolbox not installed. Contact ImagEval');
                return;
            end
            
            oi     = ieGetObject('OI');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'distortion');
                otherwise
                    ieInWindowMessage('No geometric distortion with diffraction limited optics',app);
                    disp('Not yet implemented')
            end
            
        end

        % Menu selected function: menuCopyOI
        function menuCopyOI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [~,oi] = vcGetSelectedObject('OI');
            
            newName = ieReadString('New optical image name','new-oi');
            if isempty(newName),  return;
            else,    oi = oiSet(oi,'name',newName);
            end
            
            ieAddObject(oi);
            app.refresh;
            
        end

        % Menu selected function: menuEditClearMessage
        function menuEditClearMessage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ieInWindowMessage('',app,[]);
            
        end

        % Menu selected function: menuEditCreate
        function menuEditCreate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            oiAdd(app);
            
        end

        % Menu selected function: menuEditDeleteSome
        function menuEditDeleteSome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete Some OIs
            vcDeleteSomeObjects('oi');
            app.refresh;
            
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            oiDelete(app);
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Edit | Change font size
            ieFontSizeSet(app.figure1);
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            
            [oi,val] =ieGetObject('OPTICALIMAGE');
            newName = ieReadString('New optical image name','new-oi');
            
            if isempty(newName),  return;
            else,    oi = oiSet(oi,'name',newName);
            end
            
            vcReplaceAndSelectObject(oi,val)
            app.refresh;
            
        end

        % Menu selected function: menuEditScale
        function menuEditScale_Callback(app, event)
            % Scale irradiance levels by s
            
            s = ieReadNumber('Enter scale factor',1,' %.2f');
            if isempty(s), return; end
            
            [val,oi] = vcGetSelectedObject('OPTICALIMAGE');
            irrad = oiGet(oi,'photons');
            if isempty(irrad)
                ieInWindowMessage('Can not scale:  No irradiance data.',app,[]);
            else
                ieInWindowMessage('',app,[]);
            end
            
            ill = oiGet(oi,'illuminance');
            meanIll = oiGet(oi,'meanIlluminance');
            
            oi = oiSet(oi,'compressedPhotons',irrad*s);
            if ~isempty(ill), oi = oiSet(oi,'illuminance',s*ill); end
            if ~isempty(meanIll), oi = oiSet(oi,'meanIlluminance',s*meanIll); end
            
            vcReplaceAndSelectObject(oi,val)
            app.refresh;
            
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Edit | Viewer
            
            oi  = ieGetObject('oi');
            photons = oiGet(oi,'photons');
            gam = str2double(app.editGamma.Value);
            displayFlag = -1*find(contains(app.popupRender.Items,app.popupRender.Value));
            % row = size(photons,1); col = size(photons,2);
            rgb = imageSPD(photons,oiGet(oi,'wavelength'),gam,[],[],displayFlag,[],[],app);
            ieViewer(rgb);
            
        end

        % Menu selected function: menuFFTamp
        function menuFFTamp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | FFT2dAmp
            % Default is whole image and a middle wavelength
            
            oi = ieGetObject('OI');
            oiPlot(oi,'irradiance fft',[],app);
            
            return
        end

        % Callback function: figure1, menuFileClose
        function menuFileClose_Callback(app, event)
            oiClose(app);
        end

        % Menu selected function: menuFileLoadOI
        function menuFileLoadOI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            newVal = vcImportObject('OPTICALIMAGE');
            vcSetSelectedObject('OPTICALIMAGE',newVal);
            app.refresh;
            
            return
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            app.refresh;
        end

        % Menu selected function: menuFileSaveOI
        function menuFileSaveOI_Callback(app, event)
            [~,oi] = vcGetSelectedObject('OPTICALIMAGE');
            vcSaveObject(oi);
        end

        % Menu selected function: menuHelpAppNotes
        function menuHelpAppNotes_Callback(app, event)
            % Help | Documentation (web)
            ieManualViewer('imageval code');
        end

        % Menu selected function: menuHelpISETOnline
        function menuHelpISETOnline_Callback(app, event)
            % Help | ISET functions
            ieManualViewer('iset functions');
            
        end

        % Menu selected function: menuHelpOIOnline
        function menuHelpOIOnline_Callback(app, event)
            % Help | Optics functions
            ieManualViewer('oi functions');
        end

        % Menu selected function: menuHelpOpticsOnline
        function menuHelpOpticsOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | optics functions
            ieManualViewer('optics functions');
            
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            
        end

        % Menu selected function: menuHline
        function menuHline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Line | Horizontal
            oi = ieGetObject('OI');
            oiPlot(oi,'hline',[],app);
            
        end

        % Menu selected function: menuHuman
        function menuHuman_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
        end

        % Menu selected function: menuMacular028
        function menuMacular028_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % These are not updated, as per ISETBIO.
            % We should probably remove from ISET, or update.
            %
            [val,oi] = vcGetSelectedObject('OPTICALIMAGE');
            oi = humanMacularTransmittance(oi,0.28);
            ieReplaceObject(oi,val);
            
            app.refresh;
            return
        end

        % Menu selected function: menuMacular
        function menuMacular_Callback(app, event)
            disp('Use ISETBio')
        end

        % Menu selected function: menuOTFAnyWave
        function menuOTFAnyWave_Callback(app, event)
            % Analyze | Optics | OTF
            % User selects wavelength and plots OTF
            
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'otf');
                otherwise
                    oiPlot(oi,'otf',[],app);
            end
            
        end

        % Menu selected function: menuOptTrans
        function menuOptTrans_Callback(app, event)
            % Read the optical transmittance in wavelength
            %
            %  We could use a function that multiplies the transmittance by another
            %  function, such as a lens or macular pigment transmittance.  As things
            %  stand we load a transmittance, but we should probably have a function
            %  that gets the existing one and multipllies it by another.
            
            [oi,val] = ieGetObject('OI');
            optics = oiGet(oi,'optics');
            wave = opticsGet(oi,'wave');
            
            fullName = vcSelectDataFile('optics');
            if isempty(fullName), return;
            else,                 optics = opticsSet(optics,'transmittance',ieReadSpectra(fullName,wave));
            end
            
            oi = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
        end

        % Menu selected function: menuOpticsConvertCV
        function menuOpticsConvertCV_Callback(app, event)
            % Optics | Convert Code V
            
            if isempty(which('rtRootPath')), warndlg('Optics toolbox not installed. Contact ImagEval'); return; end
            
            [val, oi] = vcGetSelectedObject('OPTICALIMAGE');
            optics = oiGet(oi,'OPTICS');
            optics = rtImportData(optics,'Code V');
            
            % Replace the optics.
            oi = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            app.refresh;
            
        end

        % Menu selected function: menuOpticsConvertZM
        function menuOpticsConvertZM_Callback(app, event)
            % Optics | Convert Zemax
            
            if isempty(which('rtRootPath'))
                warndlg('Optics toolbox not installed. Contact ImagEval');
                return;
            end
            
            [oi,val] = ieGetObject('OI');
            optics = oiGet(oi,'OPTICS');
            optics = rtImportData(optics,'Zemax');
            
            % Replace the optics.
            oi = oiSet(oi,'optics',optics);
            
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Menu selected function: menuOpticsHalfInch
        function menuOpticsHalfInch_Callback(app, event)
            [oi,val] = ieGetObject('OI');
            oi = oiClearData(oi);
            optics = opticsCreate('standard (1/2-inch)');
            oi  = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            
            app.refresh;
            
        end

        % Menu selected function: menuOpticsHuman
        function menuOpticsHuman_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % These are not updated, as per ISETBIO.
            % We should probably remove from ISET, or update.
            
            [oi,val] = ieGetObject('OI');
            
            oi = oiClearData(oi);
            optics = opticsCreate('human');
            optics = opticsSet(optics,'otfMethod','humanOTF');
            
            oi = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Menu selected function: menuOpticsImport
        function menuOpticsImport_Callback(app, event)
            % Optics | Import Optics
            vcImportObject('OPTICS');
            oiRefresh(app, hObject,eventdata,handles);
        end

        % Menu selected function: menuOpticsInch
        function menuOpticsInch_Callback(app, event)
            [oi,val] = ieGetObject('OI');
            
            optics = opticsCreate('standard (1-inch)');
            oi.optics = optics;
            oi = sceneClearData(oi);
            ieReplaceObject(oi,val);
            
            app.refresh;
        end

        % Menu selected function: menuOpticsLoadSI
        function menuOpticsLoadSI_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Optics | Load SI data
            
            % The user selects a file containing the shift-invariant data.
            [val, oi] = vcGetSelectedObject('OPTICALIMAGE');
            optics = siSynthetic('custom',oi);
            oi     = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            
            return
        end

        % Menu selected function: menuOpticsQuarterInch
        function menuOpticsQuarterInch_Callback(app, event)
            [oi,val] = ieGetObject('OI');
            oi = oiClearData(oi);
            optics = opticsCreate('standard (1/4-inch)');
            oi  = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Menu selected function: menuOpticsRayTraceParam
        function menuOpticsRayTraceParam_Callback(app, event)
            % Optics | Ray Trace Params
            disp('Not yet implemented')
            
            %  This is the old code.  In the future, we may use this for setting the
            %  eccentricity and angle sample spacing for the PSF interpolation
            %
            % [val, oi] = vcGetSelectedObject('OPTICALIMAGE');
            % optics = oiGet(oi,'optics');
            %
            % spacingMM = opticsGet(optics,'rtComputeSpacing','mm');
            % if isempty(spacingMM), spacingMM = 0.2; end
            %
            % spacingMM = ieReadNumber('Wedge size for PSF computation (mm)',spacingMM,' %.3f');
            % if isempty(spacingMM) return; end
            %
            % % Data are stored in meters
            % optics = opticsSet(optics,'rtComputeSpacing',spacingMM/1000);
            % oi = oiSet(oi,'optics',optics);
            % ieReplaceObject(oi,val);
            %
            % oiRefresh(hObject,eventdata,handles);
            %
            % return;
            return
        end

        % Menu selected function: menuOpticsRename
        function menuOpticsRename_Callback(app, event)
            % Optics | Re-name
            
            oi = ieGetObject('OI');
            optics = oiGet(oi,'optics');
            
            if oiGet(oi,'customCompute')
                name = ieReadString('Enter new ray trace optics name');
                if isempty(name), return; end
                optics = opticsSet(optics,'rtname',name);
            else
                name = ieReadString('Enter new diffraction limited optics name');
                if isempty(name), return; end
                optics = opticsSet(optics,'name',name);
            end
            oi = oiSet(oi,'optics',optics');
            
            ieReplaceObject(oi);
            app.refresh;
        end

        % Menu selected function: menuOpticsThird
        function menuOpticsThird_Callback(app, event)
            
            oi = ieGetObject('OI');
            
            optics = opticsCreate('standard (1/3-inch)');
            oi.optics = optics;
            oi.data = [];
            oi = sceneClearData(oi);
            ieReplaceObject(oi,val);
            app.refresh;
            
        end

        % Menu selected function: menuOpticsTwoThirds
        function menuOpticsTwoThirds_Callback(app, event)
            
            oi = ieGetObject('OI');
            optics = opticsCreate('standard (2/3-inch)');
            oi = oiSet(oi,'optics',optics);
            oi = sceneClearData(oi);
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Menu selected function: menuOptics
        function menuOptics_Callback(app, event)
            
        end

        % Menu selected function: menuPlOTFWave
        function menuPlOTFWave_Callback(app, event)
            % Analyze | Optics | OTF 1d by wave
            
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'otf');
                otherwise
                    oiPlot(oi,'otf Wavelength',[],app);
            end
            
        end

        % Menu selected function: menuPlOp
        function menuPlOp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot -> Optics
            
        end

        % Menu selected function: menuPlotCIE
        function menuPlotCIE_Callback(app, event)
            oi = ieGetObject('oi');
            oiPlot(oi,'chromaticity roi',[],app);
        end

        % Menu selected function: menuPlotDepthContour
        function menuPlotDepthContour_Callback(app, event)
            % Plot | Depth Contour
            
            oi = ieGetObject('oi');
            if isempty(oiGet(oi,'depth map'))
                ieInWindowMessage('No depth data.',app,3);
            else
                oiPlot(oi,'depth map contour',[],app);
            end
            
        end

        % Menu selected function: menuPlotDepthmap
        function menuPlotDepthmap_Callback(app, event)
            
            % Plot | Depth Map
            
            oi = ieGetObject('oi');
            if isempty(oiGet(oi,'depth map'))
                ieInWindowMessage('No depth data.',app,3);
            else
                oiPlot(oi,'depth map',[],app);
            end
            
            
        end

        % Menu selected function: menuPlotHLContrast
        function menuPlotHLContrast_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Might never be called.  If it is, it is from Analyze pull down.
            oi = ieGetObject('OPTICALIMAGE');
            oiPlot(oi,'hline contrast',[],app);
            
        end

        % Menu selected function: menuPlotIllumLin
        function menuPlotIllumLin_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,oi] =  vcGetSelectedObject('OPTICALIMAGE');
            
            if ~checkfields(oi,'data','illuminance')
                [oi.data.illuminance, oi.data.meanIll] = oiCalculateIlluminance(oi);
                ieReplaceObject(oi,val);
            end
            % Plots log10 or linear luminance,
            oiPlot(oi,'illuminance mesh linear',[],app);
            
        end

        % Menu selected function: menuPlotIllumLog
        function menuPlotIllumLog_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,oi] = vcGetSelectedObject('OI');
            
            if ~checkfields(oi,'data','illuminance')
                illuminance = oiCalculateIlluminance(oi);
                oi = oiSet(oi,'illuminance',illuminance);
                ieReplaceObject(oi,val);
            end
            
            % Plots log10 or linear luminance,
            % oiPlotIlluminance(oi,'log');
            oiPlot(oi,'illuminance mesh log',[],app);
            
        end

        % Menu selected function: menuPlotImageGrid
        function menuPlotImageGrid_Callback(app, event)
            % Plot | Image (RGB, grid)
            oiPlot(ieGetObject('oi'),'irradiance image with grid',[],app);
        end

        % Menu selected function: menuPlotIrradEnergy
        function menuPlotIrradEnergy_Callback(app, event)
            % Plot | Irradiance (energy)
            oi = ieGetObject('oi');
            oiPlot(oi,'irradiance energy roi',[],app);
            
        end

        % Menu selected function: menuPlotLSWave
        function menuPlotLSWave_Callback(app, event)
            % Analyze | Optics | LS by Wavelength
            
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    ieInWindowMessage('Ray trace: ls wavelength not yet implemented.',app);
                    disp('Not yet implemented')
                otherwise
                    oiPlot(oi,'ls Wavelength',[],app);
            end
        end

        % Menu selected function: menuPlotLuxHist
        function menuPlotLuxHist_Callback(app, event)
            % Analyze | ROI Summary | Illuminance
            oi = ieGetObject('OI');
            oiPlot(oi,'illuminance roi',[],app);
        end

        % Menu selected function: menuPlotMultiRGB
        function menuPlotMultiRGB_Callback(app, event)

        end

        % Menu selected function: menuPlotOffAxis
        function menuPlotOffAxis_Callback(app, event)
            % Analyze | Optics | Off-Axis fall-off
            
            oi = ieGetObject('oi');
            opticsModel = oiGet(oi,'optics model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'relativeIllumination');
                otherwise
                    % If not ray trace
                    oiPlot(oi,'relative illumination');
            end
        end

        % Menu selected function: menuPlotPS550
        function menuPlotPS550_Callback(app, event)
            % Analyze | Optics | PSF Mesh (550)
            
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'psf',550);
                otherwise
                    oiPlot(oi,'psf 550',[],app);
            end
        end

        % Menu selected function: menuPlotRGB
        function menuPlotRGB_Callback(app, event)
            % Plot | Image (RGB)
            
            % Shows the image in the window in a separate window, with just the image
            % at true size. The spatial scale is 1 to 1 with the data (true size).
            % Get the necessary display data
            oi = ieGetObject('oi');
            displayFlag = find(contains(app.popupRender.Items,app.popupRender.Value));
            gam = str2double(app.editGamma.Value);
            
            % Call same routine as shows the image in the GUI, but put the image in a
            % new graph window.  The image displays at true size.  To change the size,
            % you can call truesize(gcf,[row,col])
            rgb = oiShowImage(oi,-1*displayFlag,gam);
            ieNewGraphWin; imagesc(rgb); axis image;
            truesize;
        end

        % Menu selected function: menuPlotVLContrast
        function menuPlotVLContrast_Callback(app, event)
            % Might never be called.  If it is, it is from Analyze pull down.
            oi = ieGetObject('OPTICALIMAGE');
            oiPlot(oi,'vlinecontrast',[],app);
        end

        % Menu selected function: menuROISummaries
        function menuROISummaries_Callback(app, event)
            
        end

        % Menu selected function: menuSaveImage
        function menuSaveImage_Callback(app, event)
            oi = ieGetObject('OPTICALIMAGE');
            oiSaveImage(oi,[]);
        end

        % Menu selected function: menuStandForm
        function menuStandForm_Callback(app, event)
            % Optics->StanfordFormat
        end

        % Menu selected function: menuTransmittance
        function menuTransmittance_Callback(app, event)
            % Analyze | Optics | Transmittance
            oi = ieGetObject('OPTICALIMAGE');
            oiPlot(oi,'lens transmittance');
        end

        % Menu selected function: menuVLine
        function menuVLine_Callback(app, event)
            % Analyze | Line | Vertical
            oi = ieGetObject('OI');
            oiPlot(oi,'vline',[],app);
        end

        % Menu selected function: plotIrradiance
        function plotIrradiance_Callback(app, event)
            % Plot | Irradiance (photons)
            oi = ieGetObject('oi');
            oiPlot(oi,'irradiance photons roi',[],app);
        end

        % Menu selected function: plotOTF
        function plotOTF_Callback(app, event)
            % Analyze | Optics | OTF (550)
            %
            oi = ieGetObject('OPTICALIMAGE');
            optics = oiGet(oi,'optics');
            opticsModel = opticsGet(optics,'model');
            switch lower(opticsModel)
                case 'raytrace'
                    rtPlot(oi,'otf 550');
                otherwise
                    oiPlot(oi,'otf 550',[],app);
            end
        end

        % Value changed function: popDiffuser
        function popDiffuser_Callback(app, event)
            %  Popup selects diffuser method
            %  Current methods: skip, blur, birefringent
            
            [oi,val] = ieGetObject('oi');
            dMethod  = app.popDiffuser.Value;
            
            oi = oiSet(oi,'diffuserMethod',dMethod);
            ieReplaceObject(oi,val);
            app.refresh;
            
        end

        % Value changed function: popOpticsModel
        function popOpticsModel_Callback(app, event)
            % Manage optics model
            %
            % The method names are in the GUI of the window.  The options are
            %   Diffraction-limited
            %   Shift-invariant
            %   Ray trace
            
            method = app.popOpticsModel.Value;
            
            [oi,val]     = ieGetObject('oi');
            optics = oiGet(oi,'optics');
            switch lower(method)
                case 'diffraction-limited'
                    optics = opticsSet(optics,'model','diffraction limited');
                case 'shift-invariant'
                    optics = opticsSet(optics,'model','shift invariant');
                    if isempty(opticsGet(optics,'otfdata'))
                        % Warn the user
                        ieInWindowMessage('Shift-invariant OTF data not loaded.',app,2);
                        disp('Shift-invariant data not loaded')
                    end
                case 'ray trace'
                    if isempty(opticsGet(optics,'ray trace'))
                        % Warn the user
                        ieInWindowMessage('Ray trace data not loaded.',app,2);
                        disp('Ray trace data not loaded')
                    end
                    optics = opticsSet(optics,'model','ray trace');
                case 'iset3d'
                    optics = opticsSet(optics,'model','iset3d');
                    
                otherwise
                    error('Unknown optics method');
            end
            
            oi = oiSet(oi,'optics',optics);
            ieReplaceObject(oi,val);
            app.refresh;
        end

        % Value changed function: popupRender
        function popupRender_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % When we refresh, the rendering method is read and the oiShowImage
            % calls the relevant rendering routine.
            %
            % Hints: contents = get(hObject,'String') returns popupDisplay contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupDisplay
            app.refresh;
        end

        % Value changed function: Cos4thSwitch
        function switchRelativeIllumination(app, event)
            % Toggle switch to turn on or off cos4th relative illumination
            value = app.Cos4thSwitch.Value;
            oi = ieGetObject('oi');
            switch lower(value)
                case 'on'
                    oi = oiSet(oi,'optics off axis method','cos4th');
                case 'off'
                    oi = oiSet(oi,'optics off axis method','skip');
                otherwise
                    error('Bad switch value %s',value)
            end
            ieReplaceObject(oi);
        end

        % Cell selection callback: UITable
        function oiDescription(app, event)
            % indices = event.Indices;
            oi = ieGetObject('oi');
            iePTable(oi,'format','window');
        end

        % Menu selected function: RemoveborderMenu
        function menuEditRemoveBorder(app, event)
            % Edit | Remove Border
            oi = ieGetObject('oi');
            oi = oiCrop(oi,'border');
            ieReplaceObject(oi);
            app.refresh;
        end

        % Menu selected function: menuPlotImageSpectral
        function menuPlotImageSpectral_Callback(app, event)
            oi = ieGetObject('oi');
            plotSpectralImage(oi);
        end

        % Menu selected function: SingleWindowMenu
        function RGBSingleWindowMenu(app, event)
            % Plot | Multiple images (RGB)
            % Plots the selected RGB images from all the OIs in the
            % session in the subplots of a single window.
            singleWindow = true;
            imageMultiview('oi',[],singleWindow,app);
        end

        % Menu selected function: MultipleWindowsMenu
        function RGBMultipleWindows(app, event)
            % Plots the selected RGB images from all the OIs in the
            % session in multiple windows.
            singleWindow = false;
            imageMultiview('oi',[],singleWindow,app);
        end

        % Menu selected function: DenoiseMenu
        function menuEditDenoise_Callback(app, event)
            oi = ieGetObject('oi');
            oi = piAIdenoise(oi);
            ieReplaceObject(oi);
            app.refresh;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'ISET-Optics';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_Callback, true);
            app.FileMenu.Separator = 'on';
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.menuFileLoadOI = uimenu(app.FileMenu);
            app.menuFileLoadOI.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadOI_Callback, true);
            app.menuFileLoadOI.Text = 'Load OI data (.mat)';
            app.menuFileLoadOI.Tag = 'menuFileLoadOI';
            app.menuFileSaveOI = uimenu(app.FileMenu);
            app.menuFileSaveOI.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveOI_Callback, true);
            app.menuFileSaveOI.Text = 'Save OI data (.mat)';
            app.menuFileSaveOI.Tag = 'menuFileSaveOI';
            app.menuSaveImage = uimenu(app.FileMenu);
            app.menuSaveImage.MenuSelectedFcn = createCallbackFcn(app, @menuSaveImage_Callback, true);
            app.menuSaveImage.Separator = 'on';
            app.menuSaveImage.Text = 'Save OI image (.png)';
            app.menuSaveImage.Tag = 'menuSaveImage';
            app.menuFileRefresh = uimenu(app.FileMenu);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Separator = 'on';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.FileMenu);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.EditMenu = uimenu(app.figure1);
            app.EditMenu.MenuSelectedFcn = createCallbackFcn(app, @EditMenu_Callback, true);
            app.EditMenu.Text = 'Edit';
            app.EditMenu.Tag = 'EditMenu';
            app.menuEditName = uimenu(app.EditMenu);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = '(Re-)Name OI';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditCreate = uimenu(app.EditMenu);
            app.menuEditCreate.MenuSelectedFcn = createCallbackFcn(app, @menuEditCreate_Callback, true);
            app.menuEditCreate.Separator = 'on';
            app.menuEditCreate.Text = 'Create new OI';
            app.menuEditCreate.Tag = 'menuEditCreate';
            app.menuCopyOI = uimenu(app.EditMenu);
            app.menuCopyOI.MenuSelectedFcn = createCallbackFcn(app, @menuCopyOI_Callback, true);
            app.menuCopyOI.Text = 'Copy OI';
            app.menuCopyOI.Tag = 'menuCopyOI';
            app.menuEditDelete = uimenu(app.EditMenu);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current OI';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.menuEditDeleteSome = uimenu(app.EditMenu);
            app.menuEditDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @menuEditDeleteSome_Callback, true);
            app.menuEditDeleteSome.Text = 'Delete some OIs';
            app.menuEditDeleteSome.Tag = 'menuEditDeleteSome';
            app.menuEditScale = uimenu(app.EditMenu);
            app.menuEditScale.MenuSelectedFcn = createCallbackFcn(app, @menuEditScale_Callback, true);
            app.menuEditScale.Separator = 'on';
            app.menuEditScale.Text = 'Scale irradiance';
            app.menuEditScale.Tag = 'menuEditScale';
            app.RemoveborderMenu = uimenu(app.EditMenu);
            app.RemoveborderMenu.MenuSelectedFcn = createCallbackFcn(app, @menuEditRemoveBorder, true);
            app.RemoveborderMenu.Separator = 'on';
            app.RemoveborderMenu.Text = 'Remove border';
            app.DenoiseMenu = uimenu(app.EditMenu);
            app.DenoiseMenu.MenuSelectedFcn = createCallbackFcn(app, @menuEditDenoise_Callback, true);
            app.DenoiseMenu.Text = 'Denoise';
            app.menuEditFontSize = uimenu(app.EditMenu);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearMessage = uimenu(app.EditMenu);
            app.menuEditClearMessage.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearMessage_Callback, true);
            app.menuEditClearMessage.Text = 'Clear window message';
            app.menuEditClearMessage.Tag = 'menuEditClearMessage';
            app.menuEditViewer = uimenu(app.EditMenu);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.PlotMenu = uimenu(app.figure1);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMenu_Callback, true);
            app.PlotMenu.Text = 'Plot';
            app.PlotMenu.Tag = 'PlotMenu';
            app.plotIrradiance = uimenu(app.PlotMenu);
            app.plotIrradiance.MenuSelectedFcn = createCallbackFcn(app, @plotIrradiance_Callback, true);
            app.plotIrradiance.Text = 'Irradiance (photons)';
            app.plotIrradiance.Tag = 'plotIrradiance';
            app.menuPlotIrradEnergy = uimenu(app.PlotMenu);
            app.menuPlotIrradEnergy.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIrradEnergy_Callback, true);
            app.menuPlotIrradEnergy.Text = 'Irradiance (energy)';
            app.menuPlotIrradEnergy.Tag = 'menuPlotIrradEnergy';
            app.menuPlotRGB = uimenu(app.PlotMenu);
            app.menuPlotRGB.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRGB_Callback, true);
            app.menuPlotRGB.Separator = 'on';
            app.menuPlotRGB.Text = 'Image (RGB)';
            app.menuPlotRGB.Tag = 'menuPlotRGB';
            app.menuPlotImageGrid = uimenu(app.PlotMenu);
            app.menuPlotImageGrid.MenuSelectedFcn = createCallbackFcn(app, @menuPlotImageGrid_Callback, true);
            app.menuPlotImageGrid.Text = 'Image (RGB, grid)';
            app.menuPlotImageGrid.Tag = 'menuPlotImageGrid';
            app.menuPlotMultiRGB = uimenu(app.PlotMenu);
            app.menuPlotMultiRGB.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMultiRGB_Callback, true);
            app.menuPlotMultiRGB.Text = 'Multiple Images (RGB)';
            app.menuPlotMultiRGB.Tag = 'menuPlotMultiRGB';
            app.SingleWindowMenu = uimenu(app.menuPlotMultiRGB);
            app.SingleWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBSingleWindowMenu, true);
            app.SingleWindowMenu.Text = 'Single Window';
            app.MultipleWindowsMenu = uimenu(app.menuPlotMultiRGB);
            app.MultipleWindowsMenu.MenuSelectedFcn = createCallbackFcn(app, @RGBMultipleWindows, true);
            app.MultipleWindowsMenu.Text = 'Multiple Windows';
            app.menuPlotImageSpectral = uimenu(app.PlotMenu);
            app.menuPlotImageSpectral.MenuSelectedFcn = createCallbackFcn(app, @menuPlotImageSpectral_Callback, true);
            app.menuPlotImageSpectral.Text = 'Image spectral';
            app.menuPlotDepthmap = uimenu(app.PlotMenu);
            app.menuPlotDepthmap.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepthmap_Callback, true);
            app.menuPlotDepthmap.Separator = 'on';
            app.menuPlotDepthmap.Text = 'Depth map';
            app.menuPlotDepthmap.Tag = 'menuPlotDepthmap';
            app.menuPlotDepthContour = uimenu(app.PlotMenu);
            app.menuPlotDepthContour.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepthContour_Callback, true);
            app.menuPlotDepthContour.Text = 'Depth contour';
            app.menuPlotDepthContour.Tag = 'menuPlotDepthContour';
            app.menuOptics = uimenu(app.figure1);
            app.menuOptics.MenuSelectedFcn = createCallbackFcn(app, @menuOptics_Callback, true);
            app.menuOptics.Text = 'Optics';
            app.menuOptics.Tag = 'menuOptics';
            app.menuStandForm = uimenu(app.menuOptics);
            app.menuStandForm.MenuSelectedFcn = createCallbackFcn(app, @menuStandForm_Callback, true);
            app.menuStandForm.Text = 'Standard formats';
            app.menuStandForm.Tag = 'menuStandForm';
            app.menuOpticsQuarterInch = uimenu(app.menuStandForm);
            app.menuOpticsQuarterInch.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsQuarterInch_Callback, true);
            app.menuOpticsQuarterInch.Separator = 'on';
            app.menuOpticsQuarterInch.Text = '1/4 Inch';
            app.menuOpticsQuarterInch.Tag = 'menuOpticsQuarterInch';
            app.menuOpticsThird = uimenu(app.menuStandForm);
            app.menuOpticsThird.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsThird_Callback, true);
            app.menuOpticsThird.Text = '1/3 Inch';
            app.menuOpticsThird.Tag = 'menuOpticsThird';
            app.menuOpticsHalfInch = uimenu(app.menuStandForm);
            app.menuOpticsHalfInch.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsHalfInch_Callback, true);
            app.menuOpticsHalfInch.Text = '1/2 Inch';
            app.menuOpticsHalfInch.Tag = 'menuOpticsHalfInch';
            app.menuOpticsTwoThirds = uimenu(app.menuStandForm);
            app.menuOpticsTwoThirds.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsTwoThirds_Callback, true);
            app.menuOpticsTwoThirds.Text = '2/3 Inch';
            app.menuOpticsTwoThirds.Tag = 'menuOpticsTwoThirds';
            app.menuOpticsInch = uimenu(app.menuStandForm);
            app.menuOpticsInch.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsInch_Callback, true);
            app.menuOpticsInch.Text = '1 Inch';
            app.menuOpticsInch.Tag = 'menuOpticsInch';
            app.menuHuman = uimenu(app.menuOptics);
            app.menuHuman.MenuSelectedFcn = createCallbackFcn(app, @menuHuman_Callback, true);
            app.menuHuman.Separator = 'on';
            app.menuHuman.Text = 'Human formats';
            app.menuHuman.Tag = 'menuHuman';
            app.menuOpticsHuman = uimenu(app.menuHuman);
            app.menuOpticsHuman.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsHuman_Callback, true);
            app.menuOpticsHuman.Text = 'Human optics';
            app.menuOpticsHuman.Tag = 'menuOpticsHuman';
            app.menuMacular = uimenu(app.menuHuman);
            app.menuMacular.MenuSelectedFcn = createCallbackFcn(app, @menuMacular_Callback, true);
            app.menuMacular.Text = 'Macular pigment';
            app.menuMacular.Tag = 'menuMacular';
            app.menuMacular028 = uimenu(app.menuHuman);
            app.menuMacular028.MenuSelectedFcn = createCallbackFcn(app, @menuMacular028_Callback, true);
            app.menuMacular028.Text = 'Macular pigment (D = 0.28)';
            app.menuMacular028.Tag = 'menuMacular028';
            app.menuOpticsRayTraceParam = uimenu(app.menuOptics);
            app.menuOpticsRayTraceParam.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsRayTraceParam_Callback, true);
            app.menuOpticsRayTraceParam.Separator = 'on';
            app.menuOpticsRayTraceParam.Text = 'Ray trace parameters';
            app.menuOpticsRayTraceParam.Tag = 'menuOpticsRayTraceParam';
            app.menuOptTrans = uimenu(app.menuOptics);
            app.menuOptTrans.MenuSelectedFcn = createCallbackFcn(app, @menuOptTrans_Callback, true);
            app.menuOptTrans.Separator = 'on';
            app.menuOptTrans.Text = 'Load transmittance';
            app.menuOptTrans.Tag = 'menuOptTrans';
            app.menuOpticsRename = uimenu(app.menuOptics);
            app.menuOpticsRename.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsRename_Callback, true);
            app.menuOpticsRename.Separator = 'on';
            app.menuOpticsRename.Text = '(Re-)Name optics';
            app.menuOpticsRename.Tag = 'menuOpticsRename';
            app.menuOpticsImport = uimenu(app.menuOptics);
            app.menuOpticsImport.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsImport_Callback, true);
            app.menuOpticsImport.Separator = 'on';
            app.menuOpticsImport.Text = 'Import optics';
            app.menuOpticsImport.Tag = 'menuOpticsImport';
            app.menuOpticsExport = uimenu(app.menuOptics);
            app.menuOpticsExport.Text = 'Export optics';
            app.menuOpticsExport.Tag = 'menuOpticsExport';
            app.menuOpticsLoadSI = uimenu(app.menuOptics);
            app.menuOpticsLoadSI.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsLoadSI_Callback, true);
            app.menuOpticsLoadSI.Separator = 'on';
            app.menuOpticsLoadSI.Text = 'Load SI data';
            app.menuOpticsLoadSI.Tag = 'menuOpticsLoadSI';
            app.menuOpticsConvertCV = uimenu(app.menuOptics);
            app.menuOpticsConvertCV.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsConvertCV_Callback, true);
            app.menuOpticsConvertCV.Separator = 'on';
            app.menuOpticsConvertCV.Text = 'Convert Code V';
            app.menuOpticsConvertCV.Tag = 'menuOpticsConvertCV';
            app.menuOpticsConvertZM = uimenu(app.menuOptics);
            app.menuOpticsConvertZM.MenuSelectedFcn = createCallbackFcn(app, @menuOpticsConvertZM_Callback, true);
            app.menuOpticsConvertZM.Text = 'Convert Zemax';
            app.menuOpticsConvertZM.Tag = 'menuOpticsConvertZM';
            app.menuAn = uimenu(app.figure1);
            app.menuAn.MenuSelectedFcn = createCallbackFcn(app, @menuAn_Callback, true);
            app.menuAn.Text = 'Analyze';
            app.menuAn.Tag = 'menuAn';
            app.menuPlOp = uimenu(app.menuAn);
            app.menuPlOp.MenuSelectedFcn = createCallbackFcn(app, @menuPlOp_Callback, true);
            app.menuPlOp.Checked = 'on';
            app.menuPlOp.Text = 'Optics';
            app.menuPlOp.Tag = 'menuPlOp';
            app.menuAnOpticsPSF = uimenu(app.menuPlOp);
            app.menuAnOpticsPSF.MenuSelectedFcn = createCallbackFcn(app, @menuAnOpticsPSF_Callback, true);
            app.menuAnOpticsPSF.Text = 'PSF mesh';
            app.menuAnOpticsPSF.Tag = 'menuAnOpticsPSF';
            app.menuPlotPS550 = uimenu(app.menuPlOp);
            app.menuPlotPS550.MenuSelectedFcn = createCallbackFcn(app, @menuPlotPS550_Callback, true);
            app.menuPlotPS550.Text = 'PSF mesh (550nm)';
            app.menuPlotPS550.Tag = 'menuPlotPS550';
            app.menuAnPSFMovie = uimenu(app.menuPlOp);
            app.menuAnPSFMovie.MenuSelectedFcn = createCallbackFcn(app, @menuAnPSFMovie_Callback, true);
            app.menuAnPSFMovie.Text = 'PSF movie';
            app.menuAnPSFMovie.Tag = 'menuAnPSFMovie';
            app.menuPlotLSWave = uimenu(app.menuPlOp);
            app.menuPlotLSWave.MenuSelectedFcn = createCallbackFcn(app, @menuPlotLSWave_Callback, true);
            app.menuPlotLSWave.Text = 'LS by wavelength';
            app.menuPlotLSWave.Tag = 'menuPlotLSWave';
            app.menuOTFAnyWave = uimenu(app.menuPlOp);
            app.menuOTFAnyWave.MenuSelectedFcn = createCallbackFcn(app, @menuOTFAnyWave_Callback, true);
            app.menuOTFAnyWave.Separator = 'on';
            app.menuOTFAnyWave.Text = 'OTF';
            app.menuOTFAnyWave.Tag = 'menuOTFAnyWave';
            app.plotOTF = uimenu(app.menuPlOp);
            app.plotOTF.MenuSelectedFcn = createCallbackFcn(app, @plotOTF_Callback, true);
            app.plotOTF.Text = 'OTF (550 nm)';
            app.plotOTF.Tag = 'plotOTF';
            app.menuPlOTFWave = uimenu(app.menuPlOp);
            app.menuPlOTFWave.MenuSelectedFcn = createCallbackFcn(app, @menuPlOTFWave_Callback, true);
            app.menuPlOTFWave.Text = 'OTF (1D) by wavelength';
            app.menuPlOTFWave.Tag = 'menuPlOTFWave';
            app.menuAnOpImFieldAndWave = uimenu(app.menuPlOp);
            app.menuAnOpImFieldAndWave.MenuSelectedFcn = createCallbackFcn(app, @menuAnOpImFieldAndWave_Callback, true);
            app.menuAnOpImFieldAndWave.Separator = 'on';
            app.menuAnOpImFieldAndWave.Text = 'PSF images (rt)';
            app.menuAnOpImFieldAndWave.Tag = 'menuAnOpImFieldAndWave';
            app.menuAnalyzeOptFieldD = uimenu(app.menuPlOp);
            app.menuAnalyzeOptFieldD.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeOptFieldD_Callback, true);
            app.menuAnalyzeOptFieldD.Text = 'Field distortion (rt)';
            app.menuAnalyzeOptFieldD.Tag = 'menuAnalyzeOptFieldD';
            app.menuAnOptSampling = uimenu(app.menuPlOp);
            app.menuAnOptSampling.MenuSelectedFcn = createCallbackFcn(app, @menuAnOptSampling_Callback, true);
            app.menuAnOptSampling.Separator = 'on';
            app.menuAnOptSampling.Text = 'Sampling analysis';
            app.menuAnOptSampling.Tag = 'menuAnOptSampling';
            app.menuPlotOffAxis = uimenu(app.menuPlOp);
            app.menuPlotOffAxis.MenuSelectedFcn = createCallbackFcn(app, @menuPlotOffAxis_Callback, true);
            app.menuPlotOffAxis.Separator = 'on';
            app.menuPlotOffAxis.Text = 'Relative illumination';
            app.menuPlotOffAxis.Tag = 'menuPlotOffAxis';
            app.menuTransmittance = uimenu(app.menuPlOp);
            app.menuTransmittance.MenuSelectedFcn = createCallbackFcn(app, @menuTransmittance_Callback, true);
            app.menuTransmittance.Separator = 'on';
            app.menuTransmittance.Text = 'Transmittance';
            app.menuTransmittance.Tag = 'menuTransmittance';
            app.menuAnLineIllum = uimenu(app.menuAn);
            app.menuAnLineIllum.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineIllum_Callback, true);
            app.menuAnLineIllum.Text = 'Line (illuminance)';
            app.menuAnLineIllum.Tag = 'menuAnLineIllum';
            app.menuAnLineIllumHorizontal = uimenu(app.menuAnLineIllum);
            app.menuAnLineIllumHorizontal.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineIllumHorizontal_Callback, true);
            app.menuAnLineIllumHorizontal.Text = 'Horizontal';
            app.menuAnLineIllumHorizontal.Tag = 'menuAnLineIllumHorizontal';
            app.menuAnLineIllumHorFFT = uimenu(app.menuAnLineIllum);
            app.menuAnLineIllumHorFFT.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineIllumHorFFT_Callback, true);
            app.menuAnLineIllumHorFFT.Text = 'Horizontal (FFT)';
            app.menuAnLineIllumHorFFT.Tag = 'menuAnLineIllumHorFFT';
            app.menuAnLineIllumVertical = uimenu(app.menuAnLineIllum);
            app.menuAnLineIllumVertical.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineIllumVertical_Callback, true);
            app.menuAnLineIllumVertical.Text = 'Vertical';
            app.menuAnLineIllumVertical.Tag = 'menuAnLineIllumVertical';
            app.menuAnLineIllumVertFFT = uimenu(app.menuAnLineIllum);
            app.menuAnLineIllumVertFFT.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineIllumVertFFT_Callback, true);
            app.menuAnLineIllumVertFFT.Text = 'Vertical (FFT)';
            app.menuAnLineIllumVertFFT.Tag = 'menuAnLineIllumVertFFT';
            app.menuAnalyzeLinePlots = uimenu(app.menuAn);
            app.menuAnalyzeLinePlots.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLinePlots_Callback, true);
            app.menuAnalyzeLinePlots.Text = 'Line x wave plots';
            app.menuAnalyzeLinePlots.Tag = 'menuAnalyzeLinePlots';
            app.menuHline = uimenu(app.menuAnalyzeLinePlots);
            app.menuHline.MenuSelectedFcn = createCallbackFcn(app, @menuHline_Callback, true);
            app.menuHline.Text = 'Horizontal   (irrad)';
            app.menuHline.Tag = 'menuHline';
            app.menuVLine = uimenu(app.menuAnalyzeLinePlots);
            app.menuVLine.MenuSelectedFcn = createCallbackFcn(app, @menuVLine_Callback, true);
            app.menuVLine.Text = 'Vertical  (irrad)';
            app.menuVLine.Tag = 'menuVLine';
            app.menuPlotHLContrast = uimenu(app.menuAnalyzeLinePlots);
            app.menuPlotHLContrast.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHLContrast_Callback, true);
            app.menuPlotHLContrast.Text = 'Horizontal  (contrast)';
            app.menuPlotHLContrast.Tag = 'menuPlotHLContrast';
            app.menuPlotVLContrast = uimenu(app.menuAnalyzeLinePlots);
            app.menuPlotVLContrast.MenuSelectedFcn = createCallbackFcn(app, @menuPlotVLContrast_Callback, true);
            app.menuPlotVLContrast.Text = 'Vertical line (contrast)';
            app.menuPlotVLContrast.Tag = 'menuPlotVLContrast';
            app.menuROISummaries = uimenu(app.menuAn);
            app.menuROISummaries.MenuSelectedFcn = createCallbackFcn(app, @menuROISummaries_Callback, true);
            app.menuROISummaries.Text = 'ROI summaries';
            app.menuROISummaries.Tag = 'menuROISummaries';
            app.menuPlotLuxHist = uimenu(app.menuROISummaries);
            app.menuPlotLuxHist.MenuSelectedFcn = createCallbackFcn(app, @menuPlotLuxHist_Callback, true);
            app.menuPlotLuxHist.Text = 'Illuminance (Y, lux)';
            app.menuPlotLuxHist.Tag = 'menuPlotLuxHist';
            app.menuPlotCIE = uimenu(app.menuROISummaries);
            app.menuPlotCIE.MenuSelectedFcn = createCallbackFcn(app, @menuPlotCIE_Callback, true);
            app.menuPlotCIE.Text = 'Chromaticity (xy)';
            app.menuPlotCIE.Tag = 'menuPlotCIE';
            app.menuPlotIllumLog = uimenu(app.menuAn);
            app.menuPlotIllumLog.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIllumLog_Callback, true);
            app.menuPlotIllumLog.Separator = 'on';
            app.menuPlotIllumLog.Text = 'Illuminance mesh (log10)';
            app.menuPlotIllumLog.Tag = 'menuPlotIllumLog';
            app.menuPlotIllumLin = uimenu(app.menuAn);
            app.menuPlotIllumLin.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIllumLin_Callback, true);
            app.menuPlotIllumLin.Text = 'Illuminance mesh (linear)';
            app.menuPlotIllumLin.Tag = 'menuPlotIllumLin';
            app.menuFFTamp = uimenu(app.menuAn);
            app.menuFFTamp.MenuSelectedFcn = createCallbackFcn(app, @menuFFTamp_Callback, true);
            app.menuFFTamp.Separator = 'on';
            app.menuFFTamp.Text = 'FFT2D (amp)';
            app.menuFFTamp.Tag = 'menuFFTamp';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpOIOnline = uimenu(app.menuHelp);
            app.menuHelpOIOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpOIOnline_Callback, true);
            app.menuHelpOIOnline.Text = 'Optical image functions';
            app.menuHelpOIOnline.Tag = 'menuHelpOIOnline';
            app.menuHelpOpticsOnline = uimenu(app.menuHelp);
            app.menuHelpOpticsOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpOpticsOnline_Callback, true);
            app.menuHelpOpticsOnline.Text = 'Optics functions';
            app.menuHelpOpticsOnline.Tag = 'menuHelpOpticsOnline';
            app.menuHelpISETOnline = uimenu(app.menuHelp);
            app.menuHelpISETOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpISETOnline_Callback, true);
            app.menuHelpISETOnline.Text = 'ISET functions';
            app.menuHelpISETOnline.Tag = 'menuHelpISETOnline';
            app.menuHelpAppNotes = uimenu(app.menuHelp);
            app.menuHelpAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuHelpAppNotes_Callback, true);
            app.menuHelpAppNotes.Text = 'Documentation (web)';
            app.menuHelpAppNotes.Tag = 'menuHelpAppNotes';
            app.oiImage = uiaxes(app.figure1);
            app.oiImage.XLim = [0 1];
            app.oiImage.YLim = [0 1];
            app.oiImage.ZLim = [0 1];
            app.oiImage.CLim = [0 1];
            app.oiImage.ALim = [0 1];
            app.oiImage.XTickLabel = '';
            app.oiImage.YTick = zeros(1,0);
            app.oiImage.YTickLabel = '';
            app.oiImage.ZTick = [0 0.5 1];
            app.oiImage.ZTickLabel = '';
            app.oiImage.Tag = 'oiImage';
            app.editGamma = uieditfield(app.figure1, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Tooltip = 'Set display gamma -- data not altered';
            app.editGamma.Value = '1';
            app.txtDisplayGamma = uilabel(app.figure1);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.text32 = uilabel(app.figure1);
            app.text32.Tag = 'text32';
            app.text32.Tooltip = 'Set display rendering, no data effect';
            app.text32.Text = 'Render';
            app.pulldownSelectOI = uidropdown(app.figure1);
            app.pulldownSelectOI.Items = {'New'};
            app.pulldownSelectOI.ValueChangedFcn = createCallbackFcn(app, @pulldownSelectOI_Callback, true);
            app.pulldownSelectOI.Tag = 'SelectOptImg';
            app.pulldownSelectOI.Tooltip = 'Select optical image';
            app.pulldownSelectOI.Value = 'New';
            app.popupRender = uidropdown(app.figure1);
            app.popupRender.Items = {'Standard RGB', 'Gray Scale', 'HDR', 'Clip highlights'};
            app.popupRender.ValueChangedFcn = createCallbackFcn(app, @popupRender_Callback, true);
            app.popupRender.Tag = 'popupDisplay';
            app.popupRender.Value = 'Standard RGB';
            app.btnPrev = uibutton(app.figure1, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Icon = 'leftArrow.png';
            app.btnPrev.Text = '';
            app.btnNext = uibutton(app.figure1, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Icon = 'rightArrow.png';
            app.btnNext.Text = '';
            app.UITable = uitable(app.figure1);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @oiDescription, true);
            app.OpticssettingsPanel = uipanel(app.figure1);
            app.OpticssettingsPanel.Title = 'Optics settings';
            app.editFocalLength = uieditfield(app.OpticssettingsPanel, 'text');
            app.editFocalLength.ValueChangedFcn = createCallbackFcn(app, @editFocalLength_Callback, true);
            app.editFocalLength.Tag = 'editFocalLength';
            app.editFocalLength.Tooltip = 'Set focal length';
            app.editFocalLength.Value = '.05';
            app.txtFocalLength = uilabel(app.OpticssettingsPanel);
            app.txtFocalLength.Tag = 'txtFocalLength';
            app.txtFocalLength.Tooltip = 'Focal distance for collimated input (1/dioptric power)';
            app.txtFocalLength.Text = 'Focal Length (mm)';
            app.editFnumber = uieditfield(app.OpticssettingsPanel, 'text');
            app.editFnumber.ValueChangedFcn = createCallbackFcn(app, @editFnumber_Callback, true);
            app.editFnumber.Tag = 'editFnumber';
            app.editFnumber.Tooltip = 'Set F# of optics';
            app.editFnumber.Value = '2';
            app.popOpticsModel = uidropdown(app.OpticssettingsPanel);
            app.popOpticsModel.Items = {'Diffraction-limited', 'Shift-invariant', 'Ray trace', 'iset3d'};
            app.popOpticsModel.ValueChangedFcn = createCallbackFcn(app, @popOpticsModel_Callback, true);
            app.popOpticsModel.Tag = 'popOpticsModel';
            app.popOpticsModel.Value = 'Diffraction-limited';
            app.txtFnumber = uilabel(app.OpticssettingsPanel);
            app.txtFnumber.Tag = 'txtFnumber';
            app.txtFnumber.Tooltip = 'F# = Focal Length / Aperture';
            app.txtFnumber.Text = 'F-number';
            app.btnSimulate = uibutton(app.OpticssettingsPanel, 'push');
            app.btnSimulate.ButtonPushedFcn = createCallbackFcn(app, @btnSimulate_Callback, true);
            app.btnSimulate.Tag = 'btnSimulate';
            app.btnSimulate.Tooltip = 'Perform calculation';
            app.btnSimulate.Text = 'Compute Optical Image';
            app.Cos4thSwitchLabel = uilabel(app.OpticssettingsPanel);
            app.Cos4thSwitchLabel.Text = 'Cos4th';
            app.Cos4thSwitch = uiswitch(app.OpticssettingsPanel, 'slider');
            app.Cos4thSwitch.ValueChangedFcn = createCallbackFcn(app, @switchRelativeIllumination, true);
            app.Cos4thSwitch.Tooltip = {'Turn on/off relative illumination in oiCompute'};
            app.Cos4thSwitch.Value = 'On';
            app.editDiffuserBlur = uieditfield(app.OpticssettingsPanel, 'text');
            app.editDiffuserBlur.ValueChangedFcn = createCallbackFcn(app, @editDiffuserBlur_Callback, true);
            app.editDiffuserBlur.Tag = 'editDiffuserBlur';
            app.editDiffuserBlur.Tooltip = {'Full-width half max'};
            app.txtBlurSD = uilabel(app.OpticssettingsPanel);
            app.txtBlurSD.Tag = 'txtBlurSD';
            app.txtBlurSD.Tooltip = {'Full-width half max'};
            app.txtBlurSD.Text = 'FWHM (um)';
            app.txtDiffuser = uilabel(app.OpticssettingsPanel);
            app.txtDiffuser.Tag = 'txtDiffuser';
            app.txtDiffuser.Tooltip = {'Full-width half max'};
            app.txtDiffuser.Text = 'Anti-alias';
            app.popDiffuser = uidropdown(app.OpticssettingsPanel);
            app.popDiffuser.Items = {'Skip', 'Blur', 'Birefringent'};
            app.popDiffuser.ValueChangedFcn = createCallbackFcn(app, @popDiffuser_Callback, true);
            app.popDiffuser.Tag = 'popDiffuser';
            app.popDiffuser.Tooltip = {''};
            app.popDiffuser.Value = 'Skip';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = oiWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)oiWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end