classdef sceneWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                      matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        menuFileLoad                 matlab.ui.container.Menu
        menuFileSave                 matlab.ui.container.Menu
        menuSaveImage                matlab.ui.container.Menu
        menuFileRefresh              matlab.ui.container.Menu
        menuFileClose                matlab.ui.container.Menu
        menuEdit                     matlab.ui.container.Menu
        menuEditSceneName            matlab.ui.container.Menu
        menuEditNewScene             matlab.ui.container.Menu
        menuCopyScene                matlab.ui.container.Menu
        menuEditDelete               matlab.ui.container.Menu
        menuEditDeleteSome           matlab.ui.container.Menu
        menuSpectral                 matlab.ui.container.Menu
        menuSPDdiv                   matlab.ui.container.Menu
        menuSPDMult                  matlab.ui.container.Menu
        menuEditSetIlluminant        matlab.ui.container.Menu
        menuResampleWave             matlab.ui.container.Menu
        menuEditAdjustMonochrome     matlab.ui.container.Menu
        menuEditTransform            matlab.ui.container.Menu
        menuEditScaleSize            matlab.ui.container.Menu
        editCrop                     matlab.ui.container.Menu
        menuEditTranspose            matlab.ui.container.Menu
        menuEditRotate               matlab.ui.container.Menu
        menuEditRotCW                matlab.ui.container.Menu
        menuEditRotCCW               matlab.ui.container.Menu
        menuEditFlip                 matlab.ui.container.Menu
        menuEditFlipHorizontal       matlab.ui.container.Menu
        menuEditFlipVertical         matlab.ui.container.Menu
        menuEditFontSize             matlab.ui.container.Menu
        menuEditClearWindow          matlab.ui.container.Menu
        menuEditZoom                 matlab.ui.container.Menu
        menuEditViewer               matlab.ui.container.Menu
        PlotMenu                     matlab.ui.container.Menu
        menuPlotRadiance             matlab.ui.container.Menu
        menuPlotRadianceE            matlab.ui.container.Menu
        menuPlotRadImGrid            matlab.ui.container.Menu
        menuPlotIllumPhotons         matlab.ui.container.Menu
        menuPlotIlluminant           matlab.ui.container.Menu
        menuPlotIlluminantImage      matlab.ui.container.Menu
        menuPlotReflectance          matlab.ui.container.Menu
        menuPlotWavebandImage        matlab.ui.container.Menu
        menuPlotImTrueSize           matlab.ui.container.Menu
        menuPlotMultipleRGB          matlab.ui.container.Menu
        menuPlotDepth                matlab.ui.container.Menu
        menuPlotDepthContour         matlab.ui.container.Menu
        menuScene                    matlab.ui.container.Menu
        menuSceneMacbeth             matlab.ui.container.Menu
        menuSceneMacbethTungsten     matlab.ui.container.Menu
        menuSceneMacbethD50          matlab.ui.container.Menu
        menuSceneMacbethD65          matlab.ui.container.Menu
        menuSceneMacbethC            matlab.ui.container.Menu
        menuSceneMacbethFluorescent  matlab.ui.container.Menu
        menuSceneMacbethVisIR        matlab.ui.container.Menu
        menuSceneLstar               matlab.ui.container.Menu
        menuScenesTest               matlab.ui.container.Menu
        menuScenesSweep              matlab.ui.container.Menu
        menuHarmonic                 matlab.ui.container.Menu
        menuSceneFreqOrient          matlab.ui.container.Menu
        menuSceneSlantedBar          matlab.ui.container.Menu
        menuSceneZonePlate           matlab.ui.container.Menu
        menuSceneMackay              matlab.ui.container.Menu
        menuSceneCheckerboard        matlab.ui.container.Menu
        menuScenePointArray          matlab.ui.container.Menu
        menuSceneGridLines           matlab.ui.container.Menu
        menuSceneTestLine            matlab.ui.container.Menu
        menuSceneRadialLines         matlab.ui.container.Menu
        menuUniform                  matlab.ui.container.Menu
        menuSceneUniformPhoton       matlab.ui.container.Menu
        menuSceneUniformEE           matlab.ui.container.Menu
        menuUniformD65               matlab.ui.container.Menu
        menuSceneUniformEESpecify    matlab.ui.container.Menu
        menuSceneUniformBBspecify    matlab.ui.container.Menu
        menuScenesRamp               matlab.ui.container.Menu
        menuSceneNoise               matlab.ui.container.Menu
        menuSceneFile                matlab.ui.container.Menu
        menuSceneMultiSpec           matlab.ui.container.Menu
        menuSceneChooseRGB           matlab.ui.container.Menu
        menuFileChooseFileMono       matlab.ui.container.Menu
        menuAn                       matlab.ui.container.Menu
        menuAnalyzeLine              matlab.ui.container.Menu
        menuAnalyzeLineH             matlab.ui.container.Menu
        menuAnalyzeLineV             matlab.ui.container.Menu
        menuAnalyzeLFFTH             matlab.ui.container.Menu
        menuAnalyzeLFFTv             matlab.ui.container.Menu
        menuAnalyzeLineWave          matlab.ui.container.Menu
        menuAnalyzeLWH               matlab.ui.container.Menu
        menuAnalyzeLWV               matlab.ui.container.Menu
        menuAnalyzeROI               matlab.ui.container.Menu
        menuLuminance                matlab.ui.container.Menu
        menuAnalyzeChromaticity      matlab.ui.container.Menu
        menPlotLumLin                matlab.ui.container.Menu
        PlotLuminance                matlab.ui.container.Menu
        menuAnIlluminantCCT          matlab.ui.container.Menu
        menuHelp                     matlab.ui.container.Menu
        menuHelpSceneProgrammers     matlab.ui.container.Menu
        menuHelpProgGuide            matlab.ui.container.Menu
        menuHelpAppNotes             matlab.ui.container.Menu
        sceneImage                   matlab.ui.control.UIAxes
        editGamma                    matlab.ui.control.EditField
        txtDisplayGamma              matlab.ui.control.Label
        txtMessage                   matlab.ui.control.Label
        text21                       matlab.ui.control.Label
        popupSelectScene             matlab.ui.control.DropDown
        popupDisplay                 matlab.ui.control.DropDown
        boxScene                     matlab.ui.container.ButtonGroup
        editLuminance                matlab.ui.control.EditField
        txtLum                       matlab.ui.control.Label
        txtCDM2                      matlab.ui.control.Label
        editHorFOV                   matlab.ui.control.EditField
        txtFOV                       matlab.ui.control.Label
        txtDeg                       matlab.ui.control.Label
        editDistance                 matlab.ui.control.EditField
        txtDist                      matlab.ui.control.Label
        txtM                         matlab.ui.control.Label
        boxDescription               matlab.ui.container.ButtonGroup
        txtSceneDescription          matlab.ui.control.Label
        btnNext                      matlab.ui.control.Button
        btnPrev                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function EditMenu_Callback(app, hObject, eventdata, handles)
            return
        end
        
        function btnInterpolate_Callback(app, hObject, eventdata, handles)
            % btnInterp - eliminate
            %
            % Read the data in the row and col edit fields.  Re-sample the current data
            % in the photons field so that it has the desired number of rows and
            % columns.
            %
            %
            [val,scene] = vcGetSelectedObject('SCENE');
            sz = sceneGet(scene,'size');
            r0 = sz(1); c0 = sz(2);
            
            % Call GUI to set new row and col dimensions.
            rc = sceneSetRowCol;
            figure(gcbf);  % Return control to this figure.
            newRow = rc(1); newCol = rc(2);
            % These are the desired values determined by sceneSetRowCol
            
            % Find the appropriate scale factor.
            sFactor = [newRow/r0, newCol/c0];
            scene = sceneInterpolate(scene,sFactor);
            vcReplaceObject(scene,val);
            sceneRefresh(app);
            
            return
        end
        
        function editCol_CreateFcn(app, hObject, eventdata, handles)
            
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            return
        end
        
        function editRow_Callback(app, hObject, eventdata, handles)
            
            [val,scene] = vcGetSelectedObject('SCENE');
            scene.nRows = str2double(get(hObject,'String'));
            vcReplaceObject(scene,val);
            
            return
        end
        
        function editRow_CreateFcn(app, hObject, eventdata, handles)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            return
        end
        
        function menuFileRef_Callback(app, hObject, eventdata, handles)
            % Possible deprecated for the call below:  Refresh callback.
            sceneRefresh(app);
            return
        end
        
        function menuPlotIlluminantComment_Callback(app, hObject, eventdata, handles)
            % Deprecated - Plot | Illuminant comment
            scene = vcGetObject('SCENE');
            disp(sceneGet(scene,'illuminantComment'));
            return
        end
        
        function popupImScale_Callback(app, hObject, eventdata, handles)
            % Rescale the scene data spatially.  To be eliminated
            
            contents = get(hObject,'String');
            str = contents{get(hObject,'Value')};
            switch lower(str)
                case 'x 1'
                    return;
                case 'x 2'
                    sFactor = 2;
                case 'x 4'
                    sFactor = 4;
                case 'x 1/2'
                    sFactor = 1/2;
                case 'x 1/4'
                    sFactor = 1/4;
                otherwise
                    error('Unknown scale factor');
            end
            [val,scene] = vcGetSelectedObject('SCENE');
            scene = sceneInterpolate(scene,sFactor);
            vcReplaceObject(scene,val);
            sceneRefresh(app);
            
            return
        end
        
        function popupImScale_CreateFcn(app, hObject, eventdata, handles)
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
            return
        end
        
        function sceneRefresh(app)
            % Calls the main update routine for the scene window.
            sceneSetEditsAndButtons(app)
        end
        
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.figure1.CloseRequestFcn = 'sceneClose';
            app.figure1.KeyPressFcn = 'sceneKeyPress';
        end

        
    end
    
    methods (Access = public)
        
        function sceneAdd(app,scene)
            % app.sceneAdd(scene);
            %
            ieAddObject(scene);
            sceneRefresh(app);
        end
        
        function refresh(app)
            sceneRefresh(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sceneWindow_OpeningFcn(app, varargin)
            
            % Permits calling as sceneWindow(scene);
            if ~isempty(varargin) ...
                    && isstruct(varargin{1}) ...
                    && strcmp(varargin{1}.type,'scene')
                scene = varargin{1};
                ieAddObject(scene);
            end
            
            sceneOpen(app);
            sceneRefresh(app);
            
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                wPos = ISETprefs.wPos;
                if ~isempty(wPos{2})
                    screenSize = get(0,'ScreenSize');
                    app.figure1.Position = round([screenSize(3:4), screenSize(3:4)].*wPos{2});
                end
            end
            
        end

        % Menu selected function: FileMenu
        function FileMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
        end

        % Menu selected function: PlotLuminance
        function PlotLuminance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,scene] = vcGetSelectedObject('SCENE');
            
            % Check that the luminance field has been calculated
            if ~checkfields(scene,'data','luminance')
                [lum, meanL] = sceneCalculateLuminance(scene);
                scene = sceneSet(scene,'luminance',lum);
                scene = sceneSet(scene,'meanLuminance',meanL);
                vcReplaceAndSelectObject(scene,val);
            end
            
            % Plots log10 or linear luminance
            % Should be replaced by scenePlot call
            scenePlot(scene,'luminance mesh log');
            
            return
        end

        % Menu selected function: PlotMenu
        function PlotMenu_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the + on the right of the selection popup
            s  = ieSessionGet('selected','scene');
            nS = ieSessionGet('nobjects','scene');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('scene',s);
            sceneRefresh(app);
            return
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the - on the left of the selection popup
            s  = ieSessionGet('selected','scene');
            nS = ieSessionGet('nobjects','scene');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('scene',s);
            sceneRefresh(app);
            return
        end

        % Menu selected function: editCrop
        function editCrop_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,scene] = vcGetSelectedObject('SCENE');
            [scene,rect] = sceneCrop(scene);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    % Get the illuminant photons out and put them in the main photon
                    % slot.
                    illuminantSPD = sceneGet(scene,'illuminant photons');
                    sceneI = sceneSet(scene,'photons',illuminantSPD);
                    
                    % Crop them
                    sceneI = sceneCrop(sceneI,rect);
                    illuminantSPD = sceneGet(sceneI,'photons');
                    
                    % Stuff them back into the illuminant slot
                    scene = sceneSet(scene,'illuminant photons',illuminantSPD);
                otherwise
            end
            
            vcReplaceObject(scene,val);
            sceneRefresh(app);
            
            return
        end

        % Value changed function: editDistance
        function editDistance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            % Should be set(SCENE,'editDistance',value);
            [scene,val] = vcGetObject('SCENE');
            if ~isempty(scene)
                scene.distance = str2double(get(hObject,'String'));
                scene.consistency = 0;
                vcReplaceObject(scene,val);
            end
            sceneRefresh(app);
            return
        end

        % Value changed function: editGamma
        function editGamma_Callback(app, event)
            sceneRefresh(app);
        end

        % Value changed function: editHorFOV
        function editHorFOV_Callback(app, event)
            % Edit Horizontal Field of view
            [scene,val] = ieGetObject('SCENE');
            if ~isempty(scene)
                scene = sceneSet(scene,'fov',str2double(app.editHorFOV.Value));
                ieReplaceObject(scene,val);
            end
            sceneRefresh(app);
        end

        % Value changed function: editLuminance
        function editLuminance_Callback(app, event)
            % Edit luminance box
            
            [scene, val] = ieGetObject('SCENE');
            if ~isempty(scene)
                meanL = str2double(app.editLuminance.Value); % str2double(get(hObject,'String'));
                scene = sceneAdjustLuminance(scene,meanL);
                ieReplaceObject(scene,val);
            end
            sceneRefresh(app);
        end

        % Menu selected function: menPlotLumLin
        function menPlotLumLin_Callback(app, event)
            % Plot | Lum | Mesh Lin
            [scene, val] = ieGetObject('SCENE');
            
            if ~checkfields(scene,'data','luminance')
                [scene.data.luminance, scene.data.meanL] = sceneCalculateLuminance(scene);
                ieReplaceObject(scene,val);
            end
            
            % Plots log10 or linear luminance as a mesh.
            % Should be replaced by scenePlot call
            scenePlot(scene,'luminance mesh linear');
        end

        % Menu selected function: menuAnIlluminantCCT
        function menuAnIlluminantCCT_Callback(app, event)
            % Analyze | Illuminant CCT
            
            % Analyze | Illuminant CCT
            scene = ieGetObject('scene');
            wave  = sceneGet(scene,'wave');
            spd   = sceneGet(scene,'illuminant energy');
            
            % This size makes the title visible
            str = sprintf('       ---------  Correlated color temp %.0f  ---------',spd2cct(wave,spd));
            msgbox(str,'Illuminant');
        end

        % Menu selected function: menuAn
        function menuAn_Callback(app, event)
            
        end

        % Menu selected function: menuAnalyzeChromaticity
        function menuAnalyzeChromaticity_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'chromaticity roi');
        end

        % Menu selected function: menuAnalyzeLFFTH
        function menuAnalyzeLFFTH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance fft hline');
        end

        % Menu selected function: menuAnalyzeLFFTv
        function menuAnalyzeLFFTv_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance fft vline');
        end

        % Menu selected function: menuAnalyzeLWH
        function menuAnalyzeLWH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'radiance hline');
        end

        % Menu selected function: menuAnalyzeLWV
        function menuAnalyzeLWV_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'radiance vline');
        end

        % Menu selected function: menuAnalyzeLineH
        function menuAnalyzeLineH_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance hline');
        end

        % Menu selected function: menuAnalyzeLineV
        function menuAnalyzeLineV_Callback(app, event)
            scenePlot(ieGetObject('SCENE'),'luminance vline');
        end

        % Menu selected function: menuAnalyzeLineWave
        function menuAnalyzeLineWave_Callback(app, event)
            % Analyze LinWave
        end

        % Menu selected function: menuAnalyzeLine
        function menuAnalyzeLine_Callback(app, event)
            % Analyze Line
        end

        % Menu selected function: menuAnalyzeROI
        function menuAnalyzeROI_Callback(app, event)
            % Analyze | ROI
            return
        end

        % Menu selected function: menuCopyScene
        function menuCopyScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,scene] = vcGetSelectedObject('scene');
            
            newName = ieReadString('New scene name','new-scene');
            if isempty(newName),  return;
            else                  scene = sceneSet(scene,'name',newName);
            end
            
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuEditAdjustMonochrome
        function menuEditAdjustMonochrome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Adjust SPD | Adjust Monochrome Wavelength
            
            s = vcGetObject('scene');
            w = sceneGet(s,'wavelength');
            newWave = ieReadNumber('Enter new wavelength',w,'%.0f');
            s = sceneSet(s,'wave',newWave);
            
            vcReplaceAndSelectObject(s);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuEditClearWindow
        function menuEditClearWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            ieInWindowMessage('',ieSessionGet('sceneWindowHandles'),[]);
            return
        end

        % Menu selected function: menuEditDeleteSome
        function menuEditDeleteSome_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete Some Scenes
            vcDeleteSomeObjects('scene');
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Delete Current Scene
            vcDeleteSelectedObject('SCENE');
            sceneRefresh(app);
            
        end

        % Menu selected function: menuEditFlipHorizontal
        function menuEditFlipHorizontal_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Flip | Horizontal
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageFlip(sceneGet(scene,'photons'),'leftRight');
            scene       = sceneSet(scene,'photons',photons);
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageFlip(sceneGet(scene,'illuminant photons'),'leftRight');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            
            vcReplaceObject(scene,val); sceneWindow();
            return
        end

        % Menu selected function: menuEditFlipVertical
        function menuEditFlipVertical_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Flip | Vertical
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageFlip(sceneGet(scene,'photons'),'upDown');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageFlip(sceneGet(scene,'illuminant photons'),'upDown');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            vcReplaceObject(scene,val); sceneWindow();
            return
        end

        % Menu selected function: menuEditFlip
        function menuEditFlip_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Flip
            return
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Edit | Change Font Size
            ieFontSizeSet(app);
        end

        % Menu selected function: menuEditNewScene
        function menuEditNewScene_Callback(app, event)
            % Edit | New Scene
            scene = sceneCreate;
            ieAddObject(scene);
            sceneRefresh(app);
        end

        % Menu selected function: menuEditRotCCW
        function menuEditRotCCW_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Rotate | CounterClockWise
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageRotate(sceneGet(scene,'photons'),'ccw');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageRotate(sceneGet(scene,'illuminant photons'),'ccw');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            vcReplaceObject(scene,val); sceneWindow();
            return
        end

        % Menu selected function: menuEditRotCW
        function menuEditRotCW_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Rotate | ClockWise
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageRotate(sceneGet(scene,'photons'),'cw');
            scene       = sceneSet(scene,'photons',photons);
            
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageRotate(sceneGet(scene,'illuminant photons'),'cw');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            vcReplaceObject(scene,val); sceneWindow();
            return
        end

        % Menu selected function: menuEditRotate
        function menuEditRotate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Rotate
            return
        end

        % Menu selected function: menuEditScaleSize
        function menuEditScaleSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuEditScaleSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            scene = vcGetObject('scene');
            
            % Call GUI to set ne�w row and col dimensions.
            sFactor = ieReadNumber('Spatial scale',2,'%.0f');
            if isempty(sFactor), disp('User canceled'); return; end
            
            % rc = sceneSetRowCol;
            figure(gcbf);  % Return control to this figure.
            scene = sceneInterpolate(scene,sFactor);
            vcReplaceObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuEditSceneName
        function menuEditSceneName_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,scene] = vcGetSelectedObject('SCENE');
            
            newName = ieReadString('New scene name','new-scene');
            if isempty(newName),  return;
            else    scene = sceneSet(scene,'name',newName);
            end
            
            vcReplaceAndSelectObject(scene,val)
            sceneRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Menu selected function: menuEditSetIlluminant
        function menuEditSetIlluminant_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Adjust SPD | Change Illuminant
            
            scene = vcGetObject('scene');
            scene = sceneAdjustIlluminant(scene);
            
            % Replace and refresh
            vcReplaceObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuEditTransform
        function menuEditTransform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to menuEditTransform (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            return
        end

        % Menu selected function: menuEditTranspose
        function menuEditTranspose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Transform | Transpose
            [val,scene] = vcGetSelectedObject('SCENE');
            photons     = imageTranspose(sceneGet(scene,'photons'));
            scene       = sceneSet(scene,'photons',photons);
            illF        = sceneGet(scene,'illuminant format');
            switch illF
                case 'spatial spectral'
                    photons     = imageTranspose(sceneGet(scene,'illuminant photons'),'leftRight');
                    scene       = sceneSet(scene,'illuminant photons',photons);
                otherwise
            end
            vcReplaceObject(scene,val); sceneWindow();
            return
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = vcGetSelectedObject('scene');
            img = sceneGet(scene,'photons');
            rgb = imageSPD(img,sceneGet(scene,'wavelength'));
            ieViewer(rgb);
            
            return
        end

        % Menu selected function: menuEditZoom
        function menuEditZoom_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            zoom
            return
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFileChooseFileMono
        function menuFileChooseFileMono_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Choose | Monochrome
            %
            scene = sceneFromFile([],'monochrome');
            if isempty(scene), return; end
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sceneClose;
            return
        end

        % Menu selected function: menuFileLoad
        function menuFileLoad_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcImportObject('SCENE');
            sceneRefresh(app);
            
        end

        % Menu selected function: menuFileRefresh
        function menuFileRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Menu:  File | Refresh .
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [~,scene] = vcGetSelectedObject('SCENE');
            vcSaveObject(scene);
            
        end

        % Menu selected function: menuHarmonic
        function menuHarmonic_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % val = vcNewObjectValue('SCENE');
            scene = sceneCreate('harmonic');
            ieAddObject(scene);
            sceneRefresh(app);
            
        end

        % Menu selected function: menuHelpAppNotes
        function menuHelpAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');
            
        end

        % Menu selected function: menuHelpProgGuide
        function menuHelpProgGuide_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | ISET functions (online)
            ieManualViewer('iset functions');
            
        end

        % Menu selected function: menuHelpSceneProgrammers
        function menuHelpSceneProgrammers_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Scene Programmers (online)
            ieManualViewer('scene functions');
            
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuLuminance
        function menuLuminance_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | ROI Summary | Luminance
            scene = vcGetObject('scene');
            scenePlot(scene,'luminance roi');
            return
        end

        % Menu selected function: menuPlotDepthContour
        function menuPlotDepthContour_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Depth Contour
            
            scene = vcGetObject('scene');
            if isempty(oiGet(scene,'depth map'))
                handles = ieSessionGet('scene handle');
                ieInWindowMessage('No depth data.',handles,3);
            else
                scenePlot(scene,'depth map contour');
            end
            
        end

        % Menu selected function: menuPlotDepth
        function menuPlotDepth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            %[hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Depth Map
            scene = vcGetObject('scene');
            
            if isempty(sceneGet(scene,'depth map'))
                ieInWindowMessage('No depth map data.',app,3);
            else
                scenePlot(scene,'depth map');
            end
            
        end

        % Menu selected function: menuPlotIllumPhotons
        function menuPlotIllumPhotons_Callback(app, event)
            
            % Plot | Illuminant (photons)
            scenePlot(vcGetObject('scene'),'illuminant photons roi');
            
        end

        % Menu selected function: menuPlotIlluminantImage
        function menuPlotIlluminantImage_Callback(app, event)
            
            %  Plot | Illuminant image
            scenePlot(vcGetObject('scene'),'illuminant image');
            
        end

        % Menu selected function: menuPlotIlluminant
        function menuPlotIlluminant_Callback(app, event)
            
            % Plot | Illuminant (energy)
            scenePlot(vcGetObject('scene'),'illuminant energy roi');
            
        end

        % Menu selected function: menuPlotImTrueSize
        function menuPlotImTrueSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Image (RGB)
            % Shows the image in the window in a separate window, with just the image
            % at true size. The spatial scale is 1 to 1 with the data (true size).
            
            % Get the necessary display data
            scene = vcGetObject('scene');
            displayFlag = get(handles.popupDisplay,'Value');
            gam = str2double(get(handles.editGamma,'String'));
            
            % Call same routine as shows the image in the GUI, but put the image in a
            % new graph window.  The image displays at true size.  To change the size,
            % you can call truesize(gcf,[row,col])
            vcNewGraphWin;
            sceneShowImage(scene,displayFlag,gam);
            
            return
        end

        % Menu selected function: menuPlotMultipleRGB
        function menuPlotMultipleRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %Plot | Multiple image (RGB)
            % Multiple figures with images of the session scene data
            imageMultiview('scene');
            return
        end

        % Callback function
        function menuPlotNewGraphWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | New Graph Window
            vcNewGraphWin;
            return
        end

        % Menu selected function: menuPlotRadImGrid
        function menuPlotRadImGrid_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Radiance image (grid)
            scenePlot(vcGetObject('SCENE'), 'radianceimagewithgrid');
        end

        % Menu selected function: menuPlotRadianceE
        function menuPlotRadianceE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Plot | Radiance (Energy)
            scenePlot(vcGetObject('scene'),'radiance energy roi');
        end

        % Menu selected function: menuPlotReflectance
        function menuPlotReflectance_Callback(app, event)
            
            % Plot | Reflectance
            scenePlot(vcGetObject('scene'),'reflectance');
        end

        % Menu selected function: menuPlotWavebandImage
        function menuPlotWavebandImage_Callback(app, event)
            
            % Plot | Waveband image
            scenePlot(vcGetObject('SCENE'), 'radiance waveband image');
        end

        % Menu selected function: menuResampleWave
        function menuResampleWave_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,scene] = vcGetSelectedObject('SCENE');
            scene = sceneInterpolateW(scene,[]);
            vcReplaceObject(scene,val);
            sceneRefresh(app, hObject,eventdata,handles);
            return
        end

        % Menu selected function: menuSPDMult
        function menuSPDMult_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Adjust SPD | Multiply
            [val,scene] = vcGetSelectedObject('SCENE');
            scene = sceneSPDScale(scene,[],'multiply');
            vcReplaceObject(scene,val);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSPDdiv
        function menuSPDdiv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,scene] = vcGetSelectedObject('SCENE');
            scene = sceneSPDScale(scene,[],'divide');
            vcReplaceObject(scene,val);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSaveImage
        function menuSaveImage_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % File | Save (.png)
            scene = ieGetObject('SCENE');
            sceneSaveImage(scene,[]);
            return
        end

        % Menu selected function: menuSceneCheckerboard
        function menuSceneCheckerboard_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('checkerBoard');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneChooseRGB
        function menuSceneChooseRGB_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Choose | RGB
            %
            scene = sceneFromFile([],'rgb');
            if isempty(scene), return; end
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneFile
        function menuSceneFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuSceneFreqOrient
        function menuSceneFreqOrient_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('freqorientpattern');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneGridLines
        function menuSceneGridLines_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Patterns | PointArray (D65)
            
            scene = sceneCreate('GridLines',[],[],'d65');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneLstar
        function menuSceneLstar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth Charts | L* chart
            % Creates vertical bars with equal L* steps
            scene =  sceneCreate('lstar');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethC
        function menuSceneMacbethC_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % val = vcNewObjectValue('SCENE');
            scene =  sceneCreate('macbethC');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethD50
        function menuSceneMacbethD50_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth | D 50
            scene =  sceneCreate('macbethD50');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethD65
        function menuSceneMacbethD65_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth | D65
            scene =  sceneCreate('macbethD65');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethFluorescent
        function menuSceneMacbethFluorescent_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth | Fluorescent
            scene =  sceneCreate('macbethFluorescent');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethTungsten
        function menuSceneMacbethTungsten_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth | Tungsten
            scene =  sceneCreate('macbethTungsten');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneMacbethVisIR
        function menuSceneMacbethVisIR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Macbeth Charts | Visible-InfraRed
            wave = ieReadNumber('Enter waves','380:4:1068','%.0f');
            scene =  sceneCreate('macbethEE_IR',[],wave);
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuSceneMacbeth
        function menuSceneMacbeth_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuSceneMackay
        function menuSceneMackay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            val = vcNewObjectValue('SCENE');
            scene = sceneCreate('mackay');
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuSceneMultiSpec
        function menuSceneMultiSpec_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Choose | Multispectral
            %
            scene = sceneFromFile([],'multispectral');
            if isempty(scene), return; end
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneNoise
        function menuSceneNoise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            val = vcNewObjectValue('SCENE');
            scene = sceneCreate('noise');
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuScenePointArray
        function menuScenePointArray_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Patterns | PointArray (D65)
            scene = sceneCreate('pointarray',[],[],'d65');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneRadialLines
        function menuSceneRadialLines_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('radialLines');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneSlantedBar
        function menuSceneSlantedBar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('slantedBar');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneTestLine
        function menuSceneTestLine_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Patterns | Line (D65 vert)
            % Impulse with a D65 spectral curve
            %
            
            [val,scene] = vcGetSelectedObject('SCENE');
            scene = sceneCreate('impulse1dd65',256);
            ieAddObject(scene);
            sceneRefresh(app, hObject,eventdata,handles);
            
            return
        end

        % Menu selected function: menuSceneUniformBBspecify
        function menuSceneUniformBBspecify_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Uniform | Blackbody (specify)
            % sz = 32;  % Spatial samples
            
            % Read the size
            prompt = {'Image size','Color Temp','Wave (nm)'};
            def = {'32','5000','400:700'};
            answer = inputdlg(prompt,'Uniform blackbody',1,def);
            if isempty(answer), disp('User canceled'); return;
            else
                sz = str2double(answer{1});
                cTemp = str2double(answer{2});
                wave = eval(answer{3});
            end
            
            scene = sceneCreate('uniformbb',sz,cTemp,wave);
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuSceneUniformEESpecify
        function menuSceneUniformEESpecify_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Uniform | Equal Energy (specify)
            sz = 32;  % Spatial samples
            wavelength = ieReadNumber('Enter waves','380:4:1068','%.0f');
            if isempty(wavelength), disp('User canceled.'); return; end
            
            scene = sceneCreate('uniformeeSpecify',sz,wavelength);
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneUniformEE
        function menuSceneUniformEE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Uniform | Equal Energy
            val = vcNewObjectValue('SCENE');
            scene = sceneCreate('uniformee');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuSceneUniformPhoton
        function menuSceneUniformPhoton_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Uniform | Equal Photon
            val = vcNewObjectValue('SCENE');
            scene = sceneCreate('uniformequalphoton');
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuSceneZonePlate
        function menuSceneZonePlate_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('zonePlate');
            ieAddObject(scene);
            sceneRefresh(app);
            return
        end

        % Menu selected function: menuScene
        function menuScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuScenesRamp
        function menuScenesRamp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            val = vcNewObjectValue('SCENE');
            scene = sceneCreate('ramp');
            ieAddObject(scene);
            sceneRefresh(app);
            
            return
        end

        % Menu selected function: menuScenesSweep
        function menuScenesSweep_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            scene = sceneCreate('sweep');
            ieAddObject(scene);
            sceneRefresh(app);
        end

        % Menu selected function: menuScenesTest
        function menuScenesTest_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuSpectral
        function menuSpectral_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuUniformD65
        function menuUniformD65_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Scene | Uniform | D65
            scene = sceneCreate('uniformD65');
            ieAddObject(scene);
            sceneRefresh(app);
            
        end

        % Menu selected function: menuUniform
        function menuUniform_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuPlotRadiance
        function menuPlotRadiance_Callback(app, event)
            
            % Plot | Radiance (Quanta)
            scenePlot(ieGetObject('scene'),'radiance photons roi');
            
        end

        % Value changed function: popupDisplay
        function popupDisplay_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % We read the state of this popup inside of sceneSetEditsAndButtons.  The
            % value it has (contents{get(hObject,'Value')}) determines the displayFlag
            % value.  1 means Standard RGB (the default) and we will be adding other
            % display modes for NIR/SWIR and so forth over time.
            sceneRefresh(app);
        end

        % Value changed function: popupSelectScene
        function popupSelectScene_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            if strcmp(app.popupSelectScene.Value,'New Scene')
                disp('Should create an empty, new scene???')
                % Or maybe bring up a menu and have the person select a
                % scene type?
                return;
            else
                % This might become a function???  ALso, we should check
                % the val for sense here.
                val = find(strcmp(app.popupSelectScene.Items,app.popupSelectScene.Value));
                vcSetSelectedObject('SCENE',val-1);
            end
            sceneRefresh(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'ISET-Scene';
            app.figure1.Tag = 'figure1';
            app.FileMenu = uimenu(app.figure1);
            app.FileMenu.MenuSelectedFcn = createCallbackFcn(app, @FileMenu_Callback, true);
            app.FileMenu.Text = 'File';
            app.FileMenu.Tag = 'FileMenu';
            app.menuFileLoad = uimenu(app.FileMenu);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad_Callback, true);
            app.menuFileLoad.Text = 'Load scene data (.mat)';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuFileSave = uimenu(app.FileMenu);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Text = 'Save scene data (.mat)';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuSaveImage = uimenu(app.FileMenu);
            app.menuSaveImage.MenuSelectedFcn = createCallbackFcn(app, @menuSaveImage_Callback, true);
            app.menuSaveImage.Separator = 'on';
            app.menuSaveImage.Text = 'Save display (png)';
            app.menuSaveImage.Tag = 'menuSaveImage';
            app.menuFileRefresh = uimenu(app.FileMenu);
            app.menuFileRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileRefresh.Separator = 'on';
            app.menuFileRefresh.Text = 'Refresh';
            app.menuFileRefresh.Tag = 'menuFileRefresh';
            app.menuFileClose = uimenu(app.FileMenu);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditSceneName = uimenu(app.menuEdit);
            app.menuEditSceneName.MenuSelectedFcn = createCallbackFcn(app, @menuEditSceneName_Callback, true);
            app.menuEditSceneName.Text = '(Re-) name scene';
            app.menuEditSceneName.Tag = 'menuEditSceneName';
            app.menuEditNewScene = uimenu(app.menuEdit);
            app.menuEditNewScene.MenuSelectedFcn = createCallbackFcn(app, @menuEditNewScene_Callback, true);
            app.menuEditNewScene.Separator = 'on';
            app.menuEditNewScene.Text = 'New scene';
            app.menuEditNewScene.Tag = 'menuEditNewScene';
            app.menuCopyScene = uimenu(app.menuEdit);
            app.menuCopyScene.MenuSelectedFcn = createCallbackFcn(app, @menuCopyScene_Callback, true);
            app.menuCopyScene.Text = 'Copy scene';
            app.menuCopyScene.Tag = 'menuCopyScene';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current scene';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.menuEditDeleteSome = uimenu(app.menuEdit);
            app.menuEditDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @menuEditDeleteSome_Callback, true);
            app.menuEditDeleteSome.Text = 'Delete some scenes';
            app.menuEditDeleteSome.Tag = 'menuEditDeleteSome';
            app.menuSpectral = uimenu(app.menuEdit);
            app.menuSpectral.MenuSelectedFcn = createCallbackFcn(app, @menuSpectral_Callback, true);
            app.menuSpectral.Separator = 'on';
            app.menuSpectral.Text = 'Adjust SPD';
            app.menuSpectral.Tag = 'menuSpectral';
            app.menuSPDdiv = uimenu(app.menuSpectral);
            app.menuSPDdiv.MenuSelectedFcn = createCallbackFcn(app, @menuSPDdiv_Callback, true);
            app.menuSPDdiv.Text = 'Divide';
            app.menuSPDdiv.Tag = 'menuSPDdiv';
            app.menuSPDMult = uimenu(app.menuSpectral);
            app.menuSPDMult.MenuSelectedFcn = createCallbackFcn(app, @menuSPDMult_Callback, true);
            app.menuSPDMult.Text = 'Multiply';
            app.menuSPDMult.Tag = 'menuSPDMult';
            app.menuEditSetIlluminant = uimenu(app.menuSpectral);
            app.menuEditSetIlluminant.MenuSelectedFcn = createCallbackFcn(app, @menuEditSetIlluminant_Callback, true);
            app.menuEditSetIlluminant.Text = 'Change illuminant';
            app.menuEditSetIlluminant.Tag = 'menuEditSetIlluminant';
            app.menuResampleWave = uimenu(app.menuSpectral);
            app.menuResampleWave.MenuSelectedFcn = createCallbackFcn(app, @menuResampleWave_Callback, true);
            app.menuResampleWave.Separator = 'on';
            app.menuResampleWave.Text = 'Resample wavelength';
            app.menuResampleWave.Tag = 'menuResampleWave';
            app.menuEditAdjustMonochrome = uimenu(app.menuSpectral);
            app.menuEditAdjustMonochrome.MenuSelectedFcn = createCallbackFcn(app, @menuEditAdjustMonochrome_Callback, true);
            app.menuEditAdjustMonochrome.Text = 'Adjust monochrome wavelength';
            app.menuEditAdjustMonochrome.Tag = 'menuEditAdjustMonochrome';
            app.menuEditTransform = uimenu(app.menuEdit);
            app.menuEditTransform.MenuSelectedFcn = createCallbackFcn(app, @menuEditTransform_Callback, true);
            app.menuEditTransform.Text = 'Transform image';
            app.menuEditTransform.Tag = 'menuEditTransform';
            app.menuEditScaleSize = uimenu(app.menuEditTransform);
            app.menuEditScaleSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditScaleSize_Callback, true);
            app.menuEditScaleSize.Text = 'Scale size';
            app.menuEditScaleSize.Tag = 'menuEditScaleSize';
            app.editCrop = uimenu(app.menuEditTransform);
            app.editCrop.MenuSelectedFcn = createCallbackFcn(app, @editCrop_Callback, true);
            app.editCrop.Text = 'Crop';
            app.editCrop.Tag = 'editCrop';
            app.menuEditTranspose = uimenu(app.menuEditTransform);
            app.menuEditTranspose.MenuSelectedFcn = createCallbackFcn(app, @menuEditTranspose_Callback, true);
            app.menuEditTranspose.Text = 'Transpose';
            app.menuEditTranspose.Tag = 'menuEditTranspose';
            app.menuEditRotate = uimenu(app.menuEditTransform);
            app.menuEditRotate.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotate_Callback, true);
            app.menuEditRotate.Text = 'Rotate';
            app.menuEditRotate.Tag = 'menuEditRotate';
            app.menuEditRotCW = uimenu(app.menuEditRotate);
            app.menuEditRotCW.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotCW_Callback, true);
            app.menuEditRotCW.Text = 'Clockwise';
            app.menuEditRotCW.Tag = 'menuEditRotCW';
            app.menuEditRotCCW = uimenu(app.menuEditRotate);
            app.menuEditRotCCW.MenuSelectedFcn = createCallbackFcn(app, @menuEditRotCCW_Callback, true);
            app.menuEditRotCCW.Text = 'Counterclockwise';
            app.menuEditRotCCW.Tag = 'menuEditRotCCW';
            app.menuEditFlip = uimenu(app.menuEditTransform);
            app.menuEditFlip.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlip_Callback, true);
            app.menuEditFlip.Text = 'Flip';
            app.menuEditFlip.Tag = 'menuEditFlip';
            app.menuEditFlipHorizontal = uimenu(app.menuEditFlip);
            app.menuEditFlipHorizontal.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlipHorizontal_Callback, true);
            app.menuEditFlipHorizontal.Text = 'Horizontal';
            app.menuEditFlipHorizontal.Tag = 'menuEditFlipHorizontal';
            app.menuEditFlipVertical = uimenu(app.menuEditFlip);
            app.menuEditFlipVertical.MenuSelectedFcn = createCallbackFcn(app, @menuEditFlipVertical_Callback, true);
            app.menuEditFlipVertical.Text = 'Vertical';
            app.menuEditFlipVertical.Tag = 'menuEditFlipVertical';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditClearWindow = uimenu(app.menuEdit);
            app.menuEditClearWindow.MenuSelectedFcn = createCallbackFcn(app, @menuEditClearWindow_Callback, true);
            app.menuEditClearWindow.Text = 'Clear window message';
            app.menuEditClearWindow.Tag = 'menuEditClearWindow';
            app.menuEditZoom = uimenu(app.menuEdit);
            app.menuEditZoom.MenuSelectedFcn = createCallbackFcn(app, @menuEditZoom_Callback, true);
            app.menuEditZoom.Separator = 'on';
            app.menuEditZoom.Text = 'Zoom';
            app.menuEditZoom.Tag = 'menuEditZoom';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.PlotMenu = uimenu(app.figure1);
            app.PlotMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotMenu_Callback, true);
            app.PlotMenu.Text = 'Plot';
            app.PlotMenu.Tag = 'PlotMenu';
            app.menuPlotRadiance = uimenu(app.PlotMenu);
            app.menuPlotRadiance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadiance_Callback, true);
            app.menuPlotRadiance.Text = 'Radiance (photons)';
            app.menuPlotRadiance.Tag = 'plotRadiance';
            app.menuPlotRadianceE = uimenu(app.PlotMenu);
            app.menuPlotRadianceE.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadianceE_Callback, true);
            app.menuPlotRadianceE.Text = 'Radiance (energy)';
            app.menuPlotRadianceE.Tag = 'menuPlotRadianceE';
            app.menuPlotRadImGrid = uimenu(app.PlotMenu);
            app.menuPlotRadImGrid.MenuSelectedFcn = createCallbackFcn(app, @menuPlotRadImGrid_Callback, true);
            app.menuPlotRadImGrid.Text = 'Radiance  image (grid)';
            app.menuPlotRadImGrid.Tag = 'menuPlotRadImGrid';
            app.menuPlotIllumPhotons = uimenu(app.PlotMenu);
            app.menuPlotIllumPhotons.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIllumPhotons_Callback, true);
            app.menuPlotIllumPhotons.Separator = 'on';
            app.menuPlotIllumPhotons.Text = 'Illuminant (photons)';
            app.menuPlotIllumPhotons.Tag = 'menuPlotIllumPhotons';
            app.menuPlotIlluminant = uimenu(app.PlotMenu);
            app.menuPlotIlluminant.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIlluminant_Callback, true);
            app.menuPlotIlluminant.Text = 'Illuminant (energy)';
            app.menuPlotIlluminant.Tag = 'menuPlotIlluminant';
            app.menuPlotIlluminantImage = uimenu(app.PlotMenu);
            app.menuPlotIlluminantImage.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIlluminantImage_Callback, true);
            app.menuPlotIlluminantImage.Text = 'Illuminant image';
            app.menuPlotIlluminantImage.Tag = 'menuPlotIlluminantImage';
            app.menuPlotReflectance = uimenu(app.PlotMenu);
            app.menuPlotReflectance.MenuSelectedFcn = createCallbackFcn(app, @menuPlotReflectance_Callback, true);
            app.menuPlotReflectance.Separator = 'on';
            app.menuPlotReflectance.Text = 'Reflectance';
            app.menuPlotReflectance.Tag = 'menuPlotReflectance';
            app.menuPlotWavebandImage = uimenu(app.PlotMenu);
            app.menuPlotWavebandImage.MenuSelectedFcn = createCallbackFcn(app, @menuPlotWavebandImage_Callback, true);
            app.menuPlotWavebandImage.Text = 'Waveband image';
            app.menuPlotWavebandImage.Tag = 'menuPlotWavebandImage';
            app.menuPlotImTrueSize = uimenu(app.PlotMenu);
            app.menuPlotImTrueSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlotImTrueSize_Callback, true);
            app.menuPlotImTrueSize.Text = 'Image (RGB)';
            app.menuPlotImTrueSize.Tag = 'menuPlotImTrueSize';
            app.menuPlotMultipleRGB = uimenu(app.PlotMenu);
            app.menuPlotMultipleRGB.MenuSelectedFcn = createCallbackFcn(app, @menuPlotMultipleRGB_Callback, true);
            app.menuPlotMultipleRGB.Text = 'Multiple image (RGB)';
            app.menuPlotMultipleRGB.Tag = 'menuPlotMultipleRGB';
            app.menuPlotDepth = uimenu(app.PlotMenu);
            app.menuPlotDepth.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepth_Callback, true);
            app.menuPlotDepth.Separator = 'on';
            app.menuPlotDepth.Text = 'Depth map';
            app.menuPlotDepth.Tag = 'menuPlotDepth';
            app.menuPlotDepthContour = uimenu(app.PlotMenu);
            app.menuPlotDepthContour.MenuSelectedFcn = createCallbackFcn(app, @menuPlotDepthContour_Callback, true);
            app.menuPlotDepthContour.Text = 'Depth contour';
            app.menuPlotDepthContour.Tag = 'menuPlotDepthContour';
            app.menuScene = uimenu(app.figure1);
            app.menuScene.MenuSelectedFcn = createCallbackFcn(app, @menuScene_Callback, true);
            app.menuScene.Text = 'Scene';
            app.menuScene.Tag = 'menuScene';
            app.menuSceneMacbeth = uimenu(app.menuScene);
            app.menuSceneMacbeth.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbeth_Callback, true);
            app.menuSceneMacbeth.Text = 'Macbeth charts';
            app.menuSceneMacbeth.Tag = 'menuSceneMacbeth';
            app.menuSceneMacbethTungsten = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethTungsten.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethTungsten_Callback, true);
            app.menuSceneMacbethTungsten.Text = 'Tungsten';
            app.menuSceneMacbethTungsten.Tag = 'menuSceneMacbethTungsten';
            app.menuSceneMacbethD50 = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethD50.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethD50_Callback, true);
            app.menuSceneMacbethD50.Text = 'D 50';
            app.menuSceneMacbethD50.Tag = 'menuSceneMacbethD50';
            app.menuSceneMacbethD65 = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethD65.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethD65_Callback, true);
            app.menuSceneMacbethD65.Text = 'D 65';
            app.menuSceneMacbethD65.Tag = 'menuSceneMacbethD65';
            app.menuSceneMacbethC = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethC.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethC_Callback, true);
            app.menuSceneMacbethC.Text = 'Illuminant C';
            app.menuSceneMacbethC.Tag = 'menuSceneMacbethC';
            app.menuSceneMacbethFluorescent = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethFluorescent.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethFluorescent_Callback, true);
            app.menuSceneMacbethFluorescent.Text = 'Fluorescent';
            app.menuSceneMacbethFluorescent.Tag = 'menuSceneMacbethFluorescent';
            app.menuSceneMacbethVisIR = uimenu(app.menuSceneMacbeth);
            app.menuSceneMacbethVisIR.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMacbethVisIR_Callback, true);
            app.menuSceneMacbethVisIR.Text = 'Visible-Infrared';
            app.menuSceneMacbethVisIR.Tag = 'menuSceneMacbethVisIR';
            app.menuSceneLstar = uimenu(app.menuSceneMacbeth);
            app.menuSceneLstar.MenuSelectedFcn = createCallbackFcn(app, @menuSceneLstar_Callback, true);
            app.menuSceneLstar.Separator = 'on';
            app.menuSceneLstar.Text = 'Lstar chart';
            app.menuSceneLstar.Tag = 'menuSceneLstar';
            app.menuScenesTest = uimenu(app.menuScene);
            app.menuScenesTest.MenuSelectedFcn = createCallbackFcn(app, @menuScenesTest_Callback, true);
            app.menuScenesTest.Text = 'Patterns';
            app.menuScenesTest.Tag = 'menuScenesTest';
            app.menuScenesSweep = uimenu(app.menuScenesTest);
            app.menuScenesSweep.MenuSelectedFcn = createCallbackFcn(app, @menuScenesSweep_Callback, true);
            app.menuScenesSweep.Text = 'Sweep frequency';
            app.menuScenesSweep.Tag = 'menuScenesSweep';
            app.menuHarmonic = uimenu(app.menuScenesTest);
            app.menuHarmonic.MenuSelectedFcn = createCallbackFcn(app, @menuHarmonic_Callback, true);
            app.menuHarmonic.Text = 'Harmonic';
            app.menuHarmonic.Tag = 'menuHarmonic';
            app.menuSceneFreqOrient = uimenu(app.menuScenesTest);
            app.menuSceneFreqOrient.MenuSelectedFcn = createCallbackFcn(app, @menuSceneFreqOrient_Callback, true);
            app.menuSceneFreqOrient.Text = 'Freq-orientation';
            app.menuSceneFreqOrient.Tag = 'menuSceneFreqOrient';
            app.menuSceneSlantedBar = uimenu(app.menuScenesTest);
            app.menuSceneSlantedBar.MenuSelectedFcn = createCallbackFcn(app, @menuSceneSlantedBar_Callback, true);
            app.menuSceneSlantedBar.Separator = 'on';
            app.menuSceneSlantedBar.Text = 'Slanted bar (ISO 12233, ephot)';
            app.menuSceneSlantedBar.Tag = 'menuSceneSlantedBar';
            app.menuSceneZonePlate = uimenu(app.menuScenesTest);
            app.menuSceneZonePlate.MenuSelectedFcn = createCallbackFcn(app, @menuSceneZonePlate_Callback, true);
            app.menuSceneZonePlate.Text = 'Zone plate (Circular, ephot)';
            app.menuSceneZonePlate.Tag = 'menuSceneZonePlate';
            app.menuSceneMackay = uimenu(app.menuScenesTest);
            app.menuSceneMackay.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMackay_Callback, true);
            app.menuSceneMackay.Text = 'Mackay (rings/rays, ephot)';
            app.menuSceneMackay.Tag = 'menuSceneMackay';
            app.menuSceneCheckerboard = uimenu(app.menuScenesTest);
            app.menuSceneCheckerboard.MenuSelectedFcn = createCallbackFcn(app, @menuSceneCheckerboard_Callback, true);
            app.menuSceneCheckerboard.Text = 'Checkerboard (ephot)';
            app.menuSceneCheckerboard.Tag = 'menuSceneCheckerboard';
            app.menuScenePointArray = uimenu(app.menuScenesTest);
            app.menuScenePointArray.MenuSelectedFcn = createCallbackFcn(app, @menuScenePointArray_Callback, true);
            app.menuScenePointArray.Separator = 'on';
            app.menuScenePointArray.Text = 'Point array (D65)';
            app.menuScenePointArray.Tag = 'menuScenePointArray';
            app.menuSceneGridLines = uimenu(app.menuScenesTest);
            app.menuSceneGridLines.MenuSelectedFcn = createCallbackFcn(app, @menuSceneGridLines_Callback, true);
            app.menuSceneGridLines.Text = 'Grid lines (D65)';
            app.menuSceneGridLines.Tag = 'menuSceneGridLines';
            app.menuSceneTestLine = uimenu(app.menuScenesTest);
            app.menuSceneTestLine.MenuSelectedFcn = createCallbackFcn(app, @menuSceneTestLine_Callback, true);
            app.menuSceneTestLine.Text = 'Line (D65,vert)';
            app.menuSceneTestLine.Tag = 'menuSceneTestLine';
            app.menuSceneRadialLines = uimenu(app.menuScenesTest);
            app.menuSceneRadialLines.MenuSelectedFcn = createCallbackFcn(app, @menuSceneRadialLines_Callback, true);
            app.menuSceneRadialLines.Text = 'Radial lines';
            app.menuSceneRadialLines.Tag = 'menuSceneRadialLines';
            app.menuUniform = uimenu(app.menuScene);
            app.menuUniform.MenuSelectedFcn = createCallbackFcn(app, @menuUniform_Callback, true);
            app.menuUniform.Text = 'Uniform';
            app.menuUniform.Tag = 'menuUniform';
            app.menuSceneUniformPhoton = uimenu(app.menuUniform);
            app.menuSceneUniformPhoton.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformPhoton_Callback, true);
            app.menuSceneUniformPhoton.Text = 'Equal photon (400-700)';
            app.menuSceneUniformPhoton.Tag = 'menuSceneUniformPhoton';
            app.menuSceneUniformEE = uimenu(app.menuUniform);
            app.menuSceneUniformEE.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformEE_Callback, true);
            app.menuSceneUniformEE.Text = 'Equal energy (400-700)';
            app.menuSceneUniformEE.Tag = 'menuSceneUniformEE';
            app.menuUniformD65 = uimenu(app.menuUniform);
            app.menuUniformD65.MenuSelectedFcn = createCallbackFcn(app, @menuUniformD65_Callback, true);
            app.menuUniformD65.Text = 'D65 (400-700)';
            app.menuUniformD65.Tag = 'menuUniformD65';
            app.menuSceneUniformEESpecify = uimenu(app.menuUniform);
            app.menuSceneUniformEESpecify.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformEESpecify_Callback, true);
            app.menuSceneUniformEESpecify.Separator = 'on';
            app.menuSceneUniformEESpecify.Text = 'Equal energy (specify wave)';
            app.menuSceneUniformEESpecify.Tag = 'menuSceneUniformEESpecify';
            app.menuSceneUniformBBspecify = uimenu(app.menuUniform);
            app.menuSceneUniformBBspecify.MenuSelectedFcn = createCallbackFcn(app, @menuSceneUniformBBspecify_Callback, true);
            app.menuSceneUniformBBspecify.Text = 'Blackbody (specify)';
            app.menuSceneUniformBBspecify.Tag = 'menuSceneUniformBBspecify';
            app.menuScenesRamp = uimenu(app.menuUniform);
            app.menuScenesRamp.MenuSelectedFcn = createCallbackFcn(app, @menuScenesRamp_Callback, true);
            app.menuScenesRamp.Separator = 'on';
            app.menuScenesRamp.Text = 'Ramps';
            app.menuScenesRamp.Tag = 'menuScenesRamp';
            app.menuSceneNoise = uimenu(app.menuUniform);
            app.menuSceneNoise.MenuSelectedFcn = createCallbackFcn(app, @menuSceneNoise_Callback, true);
            app.menuSceneNoise.Separator = 'on';
            app.menuSceneNoise.Text = 'Random noise';
            app.menuSceneNoise.Tag = 'menuSceneNoise';
            app.menuSceneFile = uimenu(app.menuScene);
            app.menuSceneFile.MenuSelectedFcn = createCallbackFcn(app, @menuSceneFile_Callback, true);
            app.menuSceneFile.Text = 'Choose File';
            app.menuSceneFile.Tag = 'menuSceneFile';
            app.menuSceneMultiSpec = uimenu(app.menuSceneFile);
            app.menuSceneMultiSpec.MenuSelectedFcn = createCallbackFcn(app, @menuSceneMultiSpec_Callback, true);
            app.menuSceneMultiSpec.Text = 'Multispectral';
            app.menuSceneMultiSpec.Tag = 'menuSceneMultiSpec';
            app.menuSceneChooseRGB = uimenu(app.menuSceneFile);
            app.menuSceneChooseRGB.MenuSelectedFcn = createCallbackFcn(app, @menuSceneChooseRGB_Callback, true);
            app.menuSceneChooseRGB.Text = 'RGB';
            app.menuSceneChooseRGB.Tag = 'menuSceneChooseRGB';
            app.menuFileChooseFileMono = uimenu(app.menuSceneFile);
            app.menuFileChooseFileMono.MenuSelectedFcn = createCallbackFcn(app, @menuFileChooseFileMono_Callback, true);
            app.menuFileChooseFileMono.Text = 'Monochrome';
            app.menuFileChooseFileMono.Tag = 'menuFileChooseFileMono';
            app.menuAn = uimenu(app.figure1);
            app.menuAn.MenuSelectedFcn = createCallbackFcn(app, @menuAn_Callback, true);
            app.menuAn.Text = 'Analyze';
            app.menuAn.Tag = 'menuAn';
            app.menuAnalyzeLine = uimenu(app.menuAn);
            app.menuAnalyzeLine.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLine_Callback, true);
            app.menuAnalyzeLine.Text = 'Line (luminance)';
            app.menuAnalyzeLine.Tag = 'menuAnalyzeLine';
            app.menuAnalyzeLineH = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLineH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineH_Callback, true);
            app.menuAnalyzeLineH.Text = 'Horizontal';
            app.menuAnalyzeLineH.Tag = 'menuAnalyzeLineH';
            app.menuAnalyzeLineV = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineV_Callback, true);
            app.menuAnalyzeLineV.Text = 'Vertical';
            app.menuAnalyzeLineV.Tag = 'menuAnalyzeLineV';
            app.menuAnalyzeLFFTH = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLFFTH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLFFTH_Callback, true);
            app.menuAnalyzeLFFTH.Text = 'FFT Horizontal';
            app.menuAnalyzeLFFTH.Tag = 'menuAnalyzeLFFTH';
            app.menuAnalyzeLFFTv = uimenu(app.menuAnalyzeLine);
            app.menuAnalyzeLFFTv.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLFFTv_Callback, true);
            app.menuAnalyzeLFFTv.Text = 'FFT Vertical';
            app.menuAnalyzeLFFTv.Tag = 'menuAnalyzeLFFTv';
            app.menuAnalyzeLineWave = uimenu(app.menuAn);
            app.menuAnalyzeLineWave.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLineWave_Callback, true);
            app.menuAnalyzeLineWave.Text = 'Line x wave';
            app.menuAnalyzeLineWave.Tag = 'menuAnalyzeLineWave';
            app.menuAnalyzeLWH = uimenu(app.menuAnalyzeLineWave);
            app.menuAnalyzeLWH.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLWH_Callback, true);
            app.menuAnalyzeLWH.Text = 'Horizontal';
            app.menuAnalyzeLWH.Tag = 'menuAnalyzeLWH';
            app.menuAnalyzeLWV = uimenu(app.menuAnalyzeLineWave);
            app.menuAnalyzeLWV.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeLWV_Callback, true);
            app.menuAnalyzeLWV.Text = 'Vertical';
            app.menuAnalyzeLWV.Tag = 'menuAnalyzeLWV';
            app.menuAnalyzeROI = uimenu(app.menuAn);
            app.menuAnalyzeROI.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeROI_Callback, true);
            app.menuAnalyzeROI.Text = 'ROI Summary';
            app.menuAnalyzeROI.Tag = 'menuAnalyzeROI';
            app.menuLuminance = uimenu(app.menuAnalyzeROI);
            app.menuLuminance.MenuSelectedFcn = createCallbackFcn(app, @menuLuminance_Callback, true);
            app.menuLuminance.Text = 'Luminance (Y)';
            app.menuLuminance.Tag = 'menuLuminance';
            app.menuAnalyzeChromaticity = uimenu(app.menuAnalyzeROI);
            app.menuAnalyzeChromaticity.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeChromaticity_Callback, true);
            app.menuAnalyzeChromaticity.Text = 'Chromaticity (xy)';
            app.menuAnalyzeChromaticity.Tag = 'menuAnalyzeChromaticity';
            app.menPlotLumLin = uimenu(app.menuAn);
            app.menPlotLumLin.MenuSelectedFcn = createCallbackFcn(app, @menPlotLumLin_Callback, true);
            app.menPlotLumLin.Separator = 'on';
            app.menPlotLumLin.Text = 'Luminance mesh (linear)';
            app.menPlotLumLin.Tag = 'menPlotLumLin';
            app.PlotLuminance = uimenu(app.menuAn);
            app.PlotLuminance.MenuSelectedFcn = createCallbackFcn(app, @PlotLuminance_Callback, true);
            app.PlotLuminance.Text = 'Luminance  mesh (log10)';
            app.PlotLuminance.Tag = 'PlotLuminance';
            app.menuAnIlluminantCCT = uimenu(app.menuAn);
            app.menuAnIlluminantCCT.MenuSelectedFcn = createCallbackFcn(app, @menuAnIlluminantCCT_Callback, true);
            app.menuAnIlluminantCCT.Separator = 'on';
            app.menuAnIlluminantCCT.Text = 'Illuminant CCT';
            app.menuAnIlluminantCCT.Tag = 'menuAnIlluminantCCT';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpSceneProgrammers = uimenu(app.menuHelp);
            app.menuHelpSceneProgrammers.MenuSelectedFcn = createCallbackFcn(app, @menuHelpSceneProgrammers_Callback, true);
            app.menuHelpSceneProgrammers.Text = 'Scene functions';
            app.menuHelpSceneProgrammers.Tag = 'menuHelpSceneProgrammers';
            app.menuHelpProgGuide = uimenu(app.menuHelp);
            app.menuHelpProgGuide.MenuSelectedFcn = createCallbackFcn(app, @menuHelpProgGuide_Callback, true);
            app.menuHelpProgGuide.Text = 'ISET functions';
            app.menuHelpProgGuide.Tag = 'menuHelpProgGuide';
            app.menuHelpAppNotes = uimenu(app.menuHelp);
            app.menuHelpAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuHelpAppNotes_Callback, true);
            app.menuHelpAppNotes.Text = 'Documentation (web)';
            app.menuHelpAppNotes.Tag = 'menuHelpAppNotes';
            app.sceneImage = uiaxes(app.figure1);
            app.sceneImage.XLim = [0 1];
            app.sceneImage.YLim = [0 1];
            app.sceneImage.ZLim = [0 1];
            app.sceneImage.CLim = [0 1];
            app.sceneImage.ALim = [0 1];
            app.sceneImage.XTickLabel = '';
            app.sceneImage.YTick = zeros(1,0);
            app.sceneImage.YTickLabel = '';
            app.sceneImage.ZTick = [0 0.5 1];
            app.sceneImage.ZTickLabel = '';
            app.sceneImage.Tag = 'sceneImage';
            app.editGamma = uieditfield(app.figure1, 'text');
            app.editGamma.ValueChangedFcn = createCallbackFcn(app, @editGamma_Callback, true);
            app.editGamma.Tag = 'editGamma';
            app.editGamma.Value = '1';
            app.txtDisplayGamma = uilabel(app.figure1);
            app.txtDisplayGamma.Tag = 'txtDisplayGamma';
            app.txtDisplayGamma.Tooltip = 'Set rendering gamma for this display, no data effect';
            app.txtDisplayGamma.Text = 'Gamma';
            app.txtMessage = uilabel(app.figure1);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.text21 = uilabel(app.figure1);
            app.text21.Tag = 'text21';
            app.text21.Tooltip = 'Set display rendering, no data effect';
            app.text21.Text = 'Display';
            app.popupSelectScene = uidropdown(app.figure1);
            app.popupSelectScene.Items = {'New Scene'};
            app.popupSelectScene.ValueChangedFcn = createCallbackFcn(app, @popupSelectScene_Callback, true);
            app.popupSelectScene.Tag = 'popupSelectScene';
            app.popupSelectScene.Tooltip = 'Select scene';
            app.popupSelectScene.Value = 'New Scene';
            app.popupDisplay = uidropdown(app.figure1);
            app.popupDisplay.Items = {'Standard RGB', 'Gray Scale', 'HDR', 'Clip highlights'};
            app.popupDisplay.ValueChangedFcn = createCallbackFcn(app, @popupDisplay_Callback, true);
            app.popupDisplay.Tag = 'popupDisplay';
            app.popupDisplay.Value = 'Standard RGB';
            app.boxScene = uibuttongroup(app.figure1);
            app.boxScene.Tag = 'boxScene';
            app.editLuminance = uieditfield(app.boxScene, 'text');
            app.editLuminance.ValueChangedFcn = createCallbackFcn(app, @editLuminance_Callback, true);
            app.editLuminance.Tag = 'editLuminance';
            app.editLuminance.Tooltip = 'Mean image luminance';
            app.txtLum = uilabel(app.boxScene);
            app.txtLum.Tag = 'txtLum';
            app.txtLum.Tooltip = 'Mean luminance';
            app.txtLum.Text = 'Luminance';
            app.txtCDM2 = uilabel(app.boxScene);
            app.txtCDM2.Tag = 'txtCDM2';
            app.txtCDM2.Tooltip = 'Candelas per meter squared';
            app.txtCDM2.Text = 'cd/m2';
            app.editHorFOV = uieditfield(app.boxScene, 'text');
            app.editHorFOV.ValueChangedFcn = createCallbackFcn(app, @editHorFOV_Callback, true);
            app.editHorFOV.Tag = 'editHorFOV';
            app.editHorFOV.Tooltip = 'Horizontal field of view';
            app.txtFOV = uilabel(app.boxScene);
            app.txtFOV.Tag = 'txtFOV';
            app.txtFOV.Tooltip = 'Field of View (horizontal)';
            app.txtFOV.Text = 'FOV (width)';
            app.txtDeg = uilabel(app.boxScene);
            app.txtDeg.Tag = 'txtDeg';
            app.txtDeg.Tooltip = 'Degrees of visual angle';
            app.txtDeg.Text = 'deg';
            app.editDistance = uieditfield(app.boxScene, 'text');
            app.editDistance.ValueChangedFcn = createCallbackFcn(app, @editDistance_Callback, true);
            app.editDistance.Tag = 'editDistance';
            app.editDistance.Tooltip = 'Distance to image plane';
            app.txtDist = uilabel(app.boxScene);
            app.txtDist.Tag = 'txtDist';
            app.txtDist.Tooltip = 'Distance to image plane';
            app.txtDist.Text = 'Distance';
            app.txtM = uilabel(app.boxScene);
            app.txtM.Tag = 'txtM';
            app.txtM.Tooltip = 'Meters';
            app.txtM.Text = 'm';
            app.boxDescription = uibuttongroup(app.figure1);
            app.boxDescription.Tag = 'boxDescription';
            app.txtSceneDescription = uilabel(app.boxDescription);
            app.txtSceneDescription.Tag = 'txtSceneDescription';
            app.txtSceneDescription.Tooltip = 'Scene description';
            app.txtSceneDescription.Text = 'Scene Description';
            app.btnNext = uibutton(app.figure1, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Text = '->';
            app.btnPrev = uibutton(app.figure1, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Text = '<-';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sceneWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)sceneWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end