classdef sensorDesignCFA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                       matlab.ui.Figure
        menuFile                      matlab.ui.container.Menu
        menuSaveCFA                   matlab.ui.container.Menu
        menuFileSaveFilters           matlab.ui.container.Menu
        fileFontSize                  matlab.ui.container.Menu
        menuRefresh                   matlab.ui.container.Menu
        menuClose                     matlab.ui.container.Menu
        menuEdit                      matlab.ui.container.Menu
        menuScaleQE_Callback_1        matlab.ui.container.Menu
        menuEditEquateTransmittances  matlab.ui.container.Menu
        axes1                         matlab.ui.control.UIAxes
        text1                         matlab.ui.control.Label
        popName1                      matlab.ui.control.DropDown
        popName2                      matlab.ui.control.DropDown
        popName4                      matlab.ui.control.DropDown
        popName3                      matlab.ui.control.DropDown
        popBlockSize                  matlab.ui.control.DropDown
        txtBlockSize                  matlab.ui.control.Label
        popEdit                       matlab.ui.control.DropDown
        btnScale                      matlab.ui.control.Button
    end

    
    methods (Access = private)
        function resetColorOrder(app, handles)
            %
            % Gather up the filternames.
            
            [val,isa] = vcGetSelectedObject('ISA');
            filterNames = sensorGet(isa,'filternames');
            
            contents = get(handles.popBlockSize,'String');
            blockSize = contents{get(handles.popBlockSize,'Value')};
            
            switch lower(blockSize)
                case '1 (monochrome)'
                    pattern(1) = get(handles.popName1,'Value');
                case '2x2'
                    pattern(1,1) = get(handles.popName1,'Value');
                    pattern(2,1) = get(handles.popName2,'Value');
                    pattern(1,2) = get(handles.popName3,'Value');
                    pattern(2,2) = get(handles.popName4,'Value');
                otherwise
                    error('Unknown block style');
            end
            
            isa = sensorSet(isa,'pattern',pattern);
            vcReplaceObject(isa,val);
            
            return
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sensorDesignCFA_OpeningFcn(app, varargin)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            
            % Choose default command line output for sensorDesignCFA
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % [val,isa] = vcGetSelectedObject('ISA');
            % filterNames = cellstr(sensorGet(isa,'filternames')');
            
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Button pushed function: btnScale
        function btnScale_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            menuScaleQE_Callback(hObject, eventdata, handles)
            return
        end

        % Menu selected function: fileFontSize
        function fileFontSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to fileFontSize (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            fSize = ieFontSizeSet(handles.figure1);
            set(handles.text1,'fontsize',fSize + 4);
            set(handles.txtBlockSize,'fontsize',fSize + 2);
            
            
            return
        end

        % Menu selected function: menuClose
        function menuClose_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Clean up the filter names and the filter spectra.  If the block was set
            % up to monochrome, get rid of stuff.  If there are any unused filters, get
            % rid of them.
            %
            
            [val,isa] = vcGetSelectedObject('ISA');
            
            contents = get(handles.popBlockSize,'String');
            blockSize = contents{get(handles.popBlockSize,'Value')};
            
            filterSpectra = sensorGet(isa,'filterspectra');
            filterNames   = sensorGet(isa,'filternames');
            nFilters      = sensorGet(isa,'nfilters');
            pattern       = sensorGet(isa,'pattern');
            
            switch lower(blockSize)
                case '1 (monochrome)'
                    toKeep = pattern(1);
                    filterSpectra = filterSpectra(:,toKeep);
                    newFilterNames{1} = filterNames{toKeep};
                    filterNames = newFilterNames;
                    pattern = 1;
            
                case '2x2'
            
                    % See which sensors are used in p attern
                    usedFilters = unique(sort(pattern(:)));
                    if length(usedFilters) == sensorGet(isa,'nfilters')
                        % do nothing
                    elseif length(usedFilters) < nFilters
                        warndlg('Removing unused filters from ISA');
            
                        % Remove the unused spectra
                        filterSpectra = filterSpectra(:,usedFilters);
            
                        % Move the names
                        for ii=1:length(usedFilters)
                            newFilterNames{ii} = filterNames{usedFilters(ii)};
                        end
                        filterNames = newFilterNames;
            
                        % Adjust the pattern by reducing the value of any terms greater than a removed filter.
                        for ii=1:nFilters
                            m = find(~[usedFilters - ii]);
                            if isempty(m)
                                l = pattern >= ii;
                                if ~isempty(l), pattern(l) = pattern(l) - 1; end
                            end
                        end
            
                    else
                        error('The pattern is specifying filters that are not present.');
                    end
            
                otherwise
                    error('Unknown block size setting.');
            end
            
            % isa = sensorSet(isa,'unitblock',sensorUnitBlock(isa));
            isa = sensorSet(isa,'filterspectra',filterSpectra);
            isa = sensorSet(isa,'filterNames',filterNames);
            
            % Pattern's shape now codes the unit block shape
            isa = sensorSet(isa,'pattern',pattern);
            
            % This part could be avoided on a cancel, rather than save and close
            isa = sensorClearData(isa);
            isa.consistency = 0;            % False.  Force recomputation.
            vcReplaceObject(isa,val);
            
            % Close this figure and bring forward the sensor image window and refresh its parameters
            close(gcbf);
            hObject = sensorImageWindow;
            handles = guihandles(hObject);
            sensorImageWindow('CALLBACK','sensorRefresh',hObject,[],handles);
            
            return
        end

        % Menu selected function: menuEditEquateTransmittances
        function menuEditEquateTransmittances_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Equalizes the area under the filter curves and sets the overall peak to
            % 1.0.
            
            [val,isa] = vcGetSelectedObject('ISA');
            
            filters = sensorGet(isa,'filterspectra');
            filters = sensorEquateTransmittances(filters);
            
            isa = sensorSet(isa,'filterSpectra',filters);
            vcReplaceObject(isa,val);
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuFileSaveFilters
        function menuFileSaveFilters_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,isa] = vcGetSelectedObject('ISA');
            ieSaveColorFilters(isa);
            
            return
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            return
        end

        % Menu selected function: menuRefresh
        function menuRefresh_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorDesignCFARefresh(handles);
            return
        end

        % Menu selected function: menuSaveCFA
        function menuSaveCFA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            [val,ISA] = vcGetSelectedObject('ISA');
            sensorCfaSave(ISA);
            return
        end

        % Menu selected function: menuScaleQE_Callback_1
        function menuScaleQE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % Set the peak QE in each of the three color filters
            
            [val,isa] = vcGetSelectedObject('ISA');
            cf = sensorGet(isa,'filterspectra');
            filterNames = sensorGet(isa,'filternames');
            currentPeaks = max(cf);
            for ii=1:size(cf,2)
                prompt{ii} = sprintf('Set %s peak',char(filterNames{ii}));
                def{ii}=num2str(currentPeaks(ii));
            end
            
            dlgTitle='Scale peak transmission'; lineNo=1;
            answer=inputdlg(prompt,dlgTitle,lineNo,def);
            if isempty(answer), return; end
            
            for ii=1:length(answer), newPeaks(ii) = str2num(answer{ii}); end
            cf = cf*diag(newPeaks./currentPeaks);
            isa = sensorSet(isa,'filterspectra',cf);
            
            vcReplaceObject(isa,val);
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Value changed function: popBlockSize
        function popBlockSize_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorDesignCFARefresh(handles);
            return
        end

        % Value changed function: popEdit
        function popEdit_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            
            [val,isa] = vcGetSelectedObject('ISA');
            contents = get(hObject,'String');
            
            switch contents{get(hObject,'Value')}
            
                case 'Add Filter'
                    isa = sensorAddFilter(isa);
            
                case 'Replace Filter'
                    isa = sensorReplaceFilter(isa);
            
                case 'Delete Filter'
                    isa = sensorDeleteFilter(isa);
            
                otherwise
                    error('bad selection.');
            end
            
            vcReplaceObject(isa,val);
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Value changed function: popName1
        function popName1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            % These routines reset the entries in the pattern variable.  We find the
            % name of the selected filter for each position (in this case position 1),
            % and then we place the filter number in that position in isa.cfa.pattern.
            
            % This is the selected filter name
            contents = get(hObject,'String');
            c = contents{get(hObject,'Value')};
            resetColorOrder(app, handles);
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Value changed function: popName2
        function popName2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            contents = get(hObject,'String');
            c = contents{get(hObject,'Value')};
            resetColorOrder(app, handles);
            sensorDesignCFARefresh(handles);
            return
        end

        % Value changed function: popName3
        function popName3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            contents = get(hObject,'String');
            c = contents{get(hObject,'Value')};
            resetColorOrder(app, handles);
            sensorDesignCFARefresh(handles);
            
            return
        end

        % Value changed function: popName4
        function popName4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            contents = get(hObject,'String');
            c = contents{get(hObject,'Value')};
            resetColorOrder(app, handles);
            sensorDesignCFARefresh(handles);
            return
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'sensorDesignCFA';
            app.figure1.Tag = 'figure1';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Separator = 'on';
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuSaveCFA = uimenu(app.menuFile);
            app.menuSaveCFA.MenuSelectedFcn = createCallbackFcn(app, @menuSaveCFA_Callback, true);
            app.menuSaveCFA.Text = 'Save CFA';
            app.menuSaveCFA.Tag = 'menuSaveCFA';
            app.menuFileSaveFilters = uimenu(app.menuFile);
            app.menuFileSaveFilters.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveFilters_Callback, true);
            app.menuFileSaveFilters.Text = 'Save Filters';
            app.menuFileSaveFilters.Tag = 'menuFileSaveFilters';
            app.fileFontSize = uimenu(app.menuFile);
            app.fileFontSize.MenuSelectedFcn = createCallbackFcn(app, @fileFontSize_Callback, true);
            app.fileFontSize.Separator = 'on';
            app.fileFontSize.Text = 'Font Size';
            app.fileFontSize.Tag = 'fileFontSize';
            app.menuRefresh = uimenu(app.menuFile);
            app.menuRefresh.MenuSelectedFcn = createCallbackFcn(app, @menuRefresh_Callback, true);
            app.menuRefresh.Separator = 'on';
            app.menuRefresh.Text = 'Refresh';
            app.menuRefresh.Tag = 'menuRefresh';
            app.menuClose = uimenu(app.menuFile);
            app.menuClose.MenuSelectedFcn = createCallbackFcn(app, @menuClose_Callback, true);
            app.menuClose.Text = 'Apply and Close';
            app.menuClose.Tag = 'menuClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuScaleQE_Callback_1 = uimenu(app.menuEdit);
            app.menuScaleQE_Callback_1.MenuSelectedFcn = createCallbackFcn(app, @menuScaleQE_Callback, true);
            app.menuScaleQE_Callback_1.Text = 'Set Peak Transmittance';
            app.menuScaleQE_Callback_1.Tag = 'menuScaleQE_Callback_1';
            app.menuEditEquateTransmittances = uimenu(app.menuEdit);
            app.menuEditEquateTransmittances.MenuSelectedFcn = createCallbackFcn(app, @menuEditEquateTransmittances_Callback, true);
            app.menuEditEquateTransmittances.Text = 'Equate Filter Transmittances';
            app.menuEditEquateTransmittances.Tag = 'menuEditEquateTransmittances';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Design CFA';
            app.popName1 = uidropdown(app.figure1);
            app.popName1.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName1.ValueChangedFcn = createCallbackFcn(app, @popName1_Callback, true);
            app.popName1.Tag = 'popName1';
            app.popName1.Value = 'r';
            app.popName2 = uidropdown(app.figure1);
            app.popName2.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName2.ValueChangedFcn = createCallbackFcn(app, @popName2_Callback, true);
            app.popName2.Tag = 'popName2';
            app.popName2.Value = 'r';
            app.popName4 = uidropdown(app.figure1);
            app.popName4.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName4.ValueChangedFcn = createCallbackFcn(app, @popName4_Callback, true);
            app.popName4.Tag = 'popName4';
            app.popName4.Value = 'r';
            app.popName3 = uidropdown(app.figure1);
            app.popName3.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'w'};
            app.popName3.ValueChangedFcn = createCallbackFcn(app, @popName3_Callback, true);
            app.popName3.Tag = 'popName3';
            app.popName3.Value = 'r';
            app.popBlockSize = uidropdown(app.figure1);
            app.popBlockSize.Items = {'2x2', '1 (Monochrome)'};
            app.popBlockSize.ValueChangedFcn = createCallbackFcn(app, @popBlockSize_Callback, true);
            app.popBlockSize.Tag = 'popBlockSize';
            app.popBlockSize.Value = '2x2';
            app.txtBlockSize = uilabel(app.figure1);
            app.txtBlockSize.Tag = 'txtBlockSize';
            app.txtBlockSize.Text = 'Block Size';
            app.popEdit = uidropdown(app.figure1);
            app.popEdit.Items = {'Add Filter', 'Replace Filter', 'Delete Filter'};
            app.popEdit.ValueChangedFcn = createCallbackFcn(app, @popEdit_Callback, true);
            app.popEdit.Tag = 'popEdit';
            app.popEdit.Value = 'Add Filter';
            app.btnScale = uibutton(app.figure1, 'push');
            app.btnScale.ButtonPushedFcn = createCallbackFcn(app, @btnScale_Callback, true);
            app.btnScale.Tag = 'btnScale';
            app.btnScale.Text = 'Set Peak Transmittance';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorDesignCFA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)sensorDesignCFA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end