classdef sensorWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                        matlab.ui.Figure
        menuFile                       matlab.ui.container.Menu
        menuFileLoad                   matlab.ui.container.Menu
        menuSensorImport               matlab.ui.container.Menu
        menuFileLoadVoltsMat           matlab.ui.container.Menu
        menuFileSave                   matlab.ui.container.Menu
        menuSensorExport               matlab.ui.container.Menu
        menuFileSaveVoltsMat           matlab.ui.container.Menu
        menuSaveImage                  matlab.ui.container.Menu
        menuFileSpecsheet              matlab.ui.container.Menu
        menuFileResh                   matlab.ui.container.Menu
        menuFileClose                  matlab.ui.container.Menu
        menuEdit                       matlab.ui.container.Menu
        menuEditName                   matlab.ui.container.Menu
        menuEditCreate                 matlab.ui.container.Menu
        menuCopySensor                 matlab.ui.container.Menu
        menuEditDelete                 matlab.ui.container.Menu
        editDeleteSome                 matlab.ui.container.Menu
        editCropMenu                   matlab.ui.container.Menu
        menuEditResampleWave           matlab.ui.container.Menu
        sensorEditClearData            matlab.ui.container.Menu
        menuEditFontSize               matlab.ui.container.Menu
        menuEditViewer                 matlab.ui.container.Menu
        LoadSaveSensorMenu             matlab.ui.container.Menu
        menuPlot                       matlab.ui.container.Menu
        menuImDSNU                     matlab.ui.container.Menu
        menuImPRNU                     matlab.ui.container.Menu
        menuImShotNoise                matlab.ui.container.Menu
        menuPlotSpectra                matlab.ui.container.Menu
        menuPlotColorFilters           matlab.ui.container.Menu
        menuPlotIR                     matlab.ui.container.Menu
        plotSpecCFApattern             matlab.ui.container.Menu
        menuPlotPDSpectralQE           matlab.ui.container.Menu
        menuPlotSpecResp               matlab.ui.container.Menu
        menuPlotPixelSR                matlab.ui.container.Menu
        menuPlotSensorImageTSize       matlab.ui.container.Menu
        plotMccOverlay                 matlab.ui.container.Menu
        menuPlotHumanCone              matlab.ui.container.Menu
        menuSensor                     matlab.ui.container.Menu
        LoadMenu                       matlab.ui.container.Menu
        CFAMenu                        matlab.ui.container.Menu
        ColorfiltersMenu               matlab.ui.container.Menu
        PhotodetectorQEMenu            matlab.ui.container.Menu
        IRfilterMenu                   matlab.ui.container.Menu
        menuPDSize                     matlab.ui.container.Menu
        menuSensorMicrolens            matlab.ui.container.Menu
        menuDesignCFA                  matlab.ui.container.Menu
        menuSensorHumanCones           matlab.ui.container.Menu
        humanCones631                  matlab.ui.container.Menu
        humanConesKLMS1631             matlab.ui.container.Menu
        menuSensorCDS                  matlab.ui.container.Menu
        menuSensorColFPN               matlab.ui.container.Menu
        menuSensorPixelVignetting      matlab.ui.container.Menu
        menuSensorSetComputeGrid       matlab.ui.container.Menu
        menuAnalyze                    matlab.ui.container.Menu
        LinesMenu                      matlab.ui.container.Menu
        menuHorizontal                 matlab.ui.container.Menu
        menuHorLineVolts               matlab.ui.container.Menu
        menuHorLineE                   matlab.ui.container.Menu
        menuHorLineDV                  matlab.ui.container.Menu
        menuVertical                   matlab.ui.container.Menu
        menuAnLineV                    matlab.ui.container.Menu
        menuVertLineE                  matlab.ui.container.Menu
        menuVertLineDV                 matlab.ui.container.Menu
        TwoLinesMenu                   matlab.ui.container.Menu
        HorizontalMenu                 matlab.ui.container.Menu
        VoltsMenu                      matlab.ui.container.Menu
        ElectronsMenu                  matlab.ui.container.Menu
        DigitalcountMenu               matlab.ui.container.Menu
        VerticalMenu                   matlab.ui.container.Menu
        VoltsMenu_2                    matlab.ui.container.Menu
        ElectronsMenu_2                matlab.ui.container.Menu
        DigitalcountMenu_2             matlab.ui.container.Menu
        menuAnROIStats                 matlab.ui.container.Menu
        menuAnBasic                    matlab.ui.container.Menu
        menuAnBasicE                   matlab.ui.container.Menu
        MeanSDdvMenu                   matlab.ui.container.Menu
        menuAnHistogramVolts           matlab.ui.container.Menu
        menuAnPixHistQ                 matlab.ui.container.Menu
        HistogramdvMenu                matlab.ui.container.Menu
        menuAnPO                       matlab.ui.container.Menu
        menuAnalyzePOVignetting        matlab.ui.container.Menu
        menuAnalyzeMicroLens           matlab.ui.container.Menu
        menuAnPixOptLoadUL             matlab.ui.container.Menu
        menuAnPixOptSaveUl             matlab.ui.container.Menu
        menuAnPOShowUL                 matlab.ui.container.Menu
        menuAnSNR                      matlab.ui.container.Menu
        menuAnPixelSNR                 matlab.ui.container.Menu
        menuAnSensorSNR                matlab.ui.container.Menu
        menuPixelLuxSec                matlab.ui.container.Menu
        menuSensorLuxSec               matlab.ui.container.Menu
        menuISOSat                     matlab.ui.container.Menu
        menuAnExposureValue            matlab.ui.container.Menu
        menuAnPhotExp                  matlab.ui.container.Menu
        menuAnColor                    matlab.ui.container.Menu
        menuAnColorRB                  matlab.ui.container.Menu
        menuAnColorRG                  matlab.ui.container.Menu
        CCManalysisMenu                matlab.ui.container.Menu
        menuAnComputeFromScene         matlab.ui.container.Menu
        menuHelp                       matlab.ui.container.Menu
        menuHelpSensorOnline           matlab.ui.container.Menu
        menuHelpPixelOnline            matlab.ui.container.Menu
        menuHelpISETOnlineManual       matlab.ui.container.Menu
        menuAppNotes                   matlab.ui.container.Menu
        SensorPanel                    matlab.ui.container.Panel
        ResizeDropDown                 matlab.ui.control.DropDown
        ResizeDropDownLabel            matlab.ui.control.Label
        sensorTable                    matlab.ui.control.Table
        btnComputeImage                matlab.ui.control.Button
        popQuantization                matlab.ui.control.DropDown
        popISA                         matlab.ui.control.DropDown
        txtMVperSec                    matlab.ui.control.Label
        txtPRNU                        matlab.ui.control.Label
        text25                         matlab.ui.control.Label
        txtFPN                         matlab.ui.control.Label
        editGainFPN                    matlab.ui.control.EditField
        editOffsetFPN                  matlab.ui.control.EditField
        txtRow                         matlab.ui.control.Label
        editISAcols                    matlab.ui.control.EditField
        editISARows                    matlab.ui.control.EditField
        ExposurePanel                  matlab.ui.container.Panel
        AutoESwitch                    matlab.ui.control.Switch
        AutoESwitchLabel               matlab.ui.control.Label
        popupExpMode                   matlab.ui.control.DropDown
        editExpFactor                  matlab.ui.control.EditField
        editNExposures                 matlab.ui.control.EditField
        txtExposureUnits               matlab.ui.control.Label
        editExpTime                    matlab.ui.control.EditField
        btnShowCFAExpDurations         matlab.ui.control.Button
        PixelPanel                     matlab.ui.container.Panel
        txtReadNoise_2                 matlab.ui.control.Label
        pixelTable                     matlab.ui.control.Table
        text21                         matlab.ui.control.Label
        text20                         matlab.ui.control.Label
        text19                         matlab.ui.control.Label
        txtDkCurrent                   matlab.ui.control.Label
        text12                         matlab.ui.control.Label
        editVoltageSwing               matlab.ui.control.EditField
        editConvGain                   matlab.ui.control.EditField
        txtConvGain                    matlab.ui.control.Label
        txtDarkCurrent                 matlab.ui.control.Label
        editDarkCurrent                matlab.ui.control.EditField
        txtReadNoise                   matlab.ui.control.Label
        editReadNoise                  matlab.ui.control.EditField
        Panel                          matlab.ui.container.Panel
        txtBracketExposure             matlab.ui.control.Label
        Panel_2                        matlab.ui.container.Panel
        imageCFA                       matlab.ui.control.Image
        sliderSelectBracketedExposure  matlab.ui.control.Slider
        MaxbrightSwitch                matlab.ui.control.Switch
        MaxbrightSwitchLabel           matlab.ui.control.Label
        GammaEditField                 matlab.ui.control.EditField
        GammaEditFieldLabel            matlab.ui.control.Label
        txtMessage                     matlab.ui.control.Label
        btnPrev                        matlab.ui.control.Button
        btnNext                        matlab.ui.control.Button
        popupSelect                    matlab.ui.control.DropDown
        imgMain                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property % Description
    end
    
    methods (Access = private)
        %---------
        function createNewSensor(app)
            % New ISA
            % Defaults to current values, except new color, as per sensorCreate.
            
            % newISA = ieGetObject('ISA');
            newVal = vcNewObjectValue('ISA');
            
            sensorArrayNames = get(handles.popISA,'String');
            thisArrayName = sensorArrayNames{get(handles.popISA,'Value')};
            switch lower(thisArrayName)
                case {'bayer-grbg'}
                    newISA = sensorCreate('bayer-grbg');
                case {'bayer-rggb'}
                    newISA = sensorCreate('bayer-rggb');
                case {'bayer-bggr'}
                    newISA = sensorCreate('bayer-bggr');
                case 'bayer-ycmy'
                    newISA = sensorCreate('bayer-ycmy');
                case {'four color'}
                    newISA = sensorCreate('fourcolor');
                case 'monochrome'
                    newISA = sensorCreate('monochrome');
                otherwise
                    warning('Creating default sensor.')
                    newISA = sensorCreate('default');
            end
            
            vcReplaceAndSelectObject(newISA,newVal);
            app.refresh;
        end
        
        %---------
        function sensor = sensorAdjustBracketTimes(app,sensor)
            % Examine the bracket settings edit boxes and adjust the exposure times
            %
            % Another display mode is needed for multiple exposure times that are not
            % necessarily in bracketed mode.
            
            % This can be called by editing the exp time box or the nExposures or the
            % scale factor on exposures.
            %
            % If it is called from the exp time box, then we get the sensor passed in.
            % Otherwise we use the default sensor.
            if ieNotDefined('sensor'), sensor = ieGetObject('sensor'); end
            
            % Require odd exposure number.  Update the edit box with the right number
            nExposures = str2double(app.editNExposures.Value);
            if ~isodd(nExposures), nExposures = nExposures + 1; end
            app.editNExposures.Value = num2str(nExposures);
            
            % Create the exposure list from GUI data
            sFactor         = str2double(app.editExpFactor.Value);
            % check to see if we haven't set a value, so things work
            % when the user first turns on bracketing
            if isnan(sFactor), sFactor = 2; end
            
            centralExposure = sensorGet(sensor,'Geometric Mean Exposure Time');
            nBelow          = floor(nExposures/2);
            shortExposure   = centralExposure/(sFactor^nBelow);
            expTimes = zeros(1,nExposures);
            for ii=1:nExposures
                expTimes(ii) = shortExposure*sFactor^(ii-1);
            end
            
            % Update the sensor
            sensor        = sensorSet(sensor,'Exp Time',expTimes);
            exposurePlane = floor(nExposures/2) + 1;
            sensor = sensorSet(sensor,'Exposure Plane',exposurePlane);
            
            % Slider (lower left) for bracketed exposures now set in
            % sensorEditsAndButtons
        end
        
        %---------
        function sensor = sensorAdjustCFATimes(~,sensor)
            % Adjust the sensor exposure time slot to match the CFA size, putting the
            % sensor into the CFA exposure mode.
            %
            if ieNotDefined('sensor'), sensor = ieGetObject('sensor'); end
            
            mSize  = size(sensorGet(sensor,'pattern'));
            eTimes = sensorGet(sensor,'expTimes');
            
            % If the eTimes has the wrong size, use the geometric mean of the current
            % eTime values and assign it to a matrix of the right size.
            if ~isequal(size(eTimes),mSize)
                eTimes = ones(mSize)*sensorGet(sensor,'geometric Mean Exposure time');
                sensor = sensorSet(sensor,'expTime',eTimes);
            end
        end
        
        %---------
        function sensorDelete(app)
            
            vcDeleteSelectedObject('sensor');
            [~,val] = ieGetObject('sensor');
            if isempty(val)
                sensor = sensorCreate('default');
                ieReplaceObject(sensor,1);
            end
            app.refresh;
        end
        
    end
    
    methods (Access = public)
        
        %---------
        % From the command line?  Move to public.
        function sensorClose(app)
            % If we are closing a selected window in the database, remove
            % it from the database.
            sensorW= ieSessionGet('sensor window');
            if isequal(sensorW,app)
                disp('Clearing this sensor window from the database.')
                ieSessionSet('sensor window',[]);
            end
            delete(app);
        end
        
        %---------
        function sensorDisplayData(app, sensor)
            iePTable(sensor,'uitable',app.sensorTable,'format','embed');
        end
        
        %---------
        function pixelDisplayData(app, pixel)
            iePTable(pixel,'uitable',app.pixelTable,'format','embed');
        end
        %---------
        function refresh(app)
            %Update the sensor image window interface
            %
            %   refresh(app);
            %
            % Update the sensor image window contents with data using data in the
            % currently selected sensor (image sensor array) or another one that is passed
            % in.
            %
            % Copyright ImagEval Consultants, LLC, 2003.
            
            %% Set up window
            
            % Get an existing sensor or make one up.  Not sure we should make one up.
            sensor = ieGetObject('sensor');
            if isempty(sensor)
                sensor = sensorCreate;
                ieAddObject(sensor);
            end
            
            % app.figure1.Visible = 'off';
            ieInWindowMessage('',app,[]);
            
            %% Sensor (sensor) properties
            app.editISARows.Value = num2str(sensorGet(sensor,'rows'));
            app.editISAcols.Value = num2str(sensorGet(sensor,'cols'));
            
            % Sets the units. This should become a function
            t = sensorGet(sensor,'geometric Mean Exposure Time');  % In seconds
            u = log10(t);
            if u >= 0
                str = sprintf('%.2f',sensorGet(sensor,'geometricMeanExposureTime'));
                app.txtExposureUnits.Text = '(sec)';
            elseif u >= -3
                str = sprintf('%.2f',sensorGet(sensor,'geometricMeanExposureTime')*10^3);
                app.txtExposureUnits.Text = '(ms)';
            else
                str = sprintf('%.2f',sensorGet(sensor,'geometricMeanExposureTime')*10^6);
                app.txtExposureUnits.Text = '(us)';
            end
            app.editExpTime.Value = str;
            
            % Set the slider in the case of bracketed exposures.
            nExposures = sensorGet(sensor,'nExposures');
            if nExposures > 1
                app.sliderSelectBracketedExposure.Limits = [1-eps,nExposures];
                app.sliderSelectBracketedExposure.Value = sensorGet(sensor,'Exposure Plane');
                
                % ss = 1/(nExposures-1);  % Was slider steps
                app.sliderSelectBracketedExposure.MajorTicks = (1:nExposures);
                app.editNExposures.Value = num2str(nExposures);
                
                eTimes = sensorGet(sensor,'exposure time');
                app.editExpFactor.Value = num2str(eTimes(2)/eTimes(1));
            end
            
            str = sprintf('%3.1f',sensorGet(sensor,'gain'));
            app.editGainFPN.Value = str;
            
            % The offset is stored in volts.  It is displayed in millivolts.
            str = sprintf('%3.1f',sensorGet(sensor,'offset')*1000);
            app.editOffsetFPN.Value= str;
            
            %% Pixel properties
            thisPixel = sensorGet(sensor,'pixel');
            
            %  Dark Voltage displayed in millivolts
            str = sprintf('%2.1f',pixelGet(thisPixel,'darkvolt')*10^3);   %
            app.editDarkCurrent.Value = str;
            
            str = sprintf('%.01f',pixelGet(thisPixel,'conversiongain')*(10^6));
            app.editConvGain.Value = str;
            str = sprintf('%4.1f',pixelGet(thisPixel,'readnoisemillivolts'));   % Read noise displayed in mV
            app.editReadNoise.Value = str;
            str = sprintf('%2.2f',pixelGet(thisPixel,'voltage swing'));
            app.editVoltageSwing.Value = str;
            
            %% Header strings
            app.sensorDisplayData(sensor);
            app.pixelDisplayData(sensorGet(sensor,'pixel'));
            
            % Auto exposure switch
            if sensorGet(sensor,'autoexposure'), app.AutoESwitch.Value = 'On';
            else,                                app.AutoESwitch.Value = 'Off';
            end
            
            exposureMethod = sensorGet(sensor,'exposureMethod');
            switch exposureMethod(1:3)
                case 'sin'  % singleExposure
                    app.popupExpMode.Value = app.popupExpMode.Items{1};
                    app.AutoESwitch.Visible = true;
                    app.editExpTime.Visible = true;
                    app.editNExposures.Visible = false;
                    app.editExpFactor.Visible = false;
                    app.sliderSelectBracketedExposure.Visible = false;
                    app.txtBracketExposure.Visible = false;
                    app.btnShowCFAExpDurations.Visible = false;
                    
                case {'bra','bur'}  % bracketed or burst exposure
                    app.popupExpMode.Value = app.popupExpMode.Items{2};
                    app.AutoESwitch.Visible = 'off';
                    app.editExpTime.Visible = 'on';
                    app.editNExposures.Visible = 'on';
                    app.editExpFactor.Visible = 'on';
                    app.sliderSelectBracketedExposure.Visible = 'on';
                    app.txtBracketExposure.Visible = 'on';
                    app.btnShowCFAExpDurations.Visible = 'off';
                    
                case 'cfa'  % cfaExposure
                    app.popupExpMode.Value = app.popupExpMode.Items{3};
                    app.AutoESwitch.Visible = true;
                    app.editExpTime.Visible = 'off';
                    app.editNExposures.Visible = 'off';
                    app.editExpFactor.Visible = 'off';
                    app.sliderSelectBracketedExposure.Visible = 'off';
                    app.txtBracketExposure.Visible = 'off';
                    app.btnShowCFAExpDurations.Visible = 'on';
                    
                otherwise
                    error('Unknown exposure method %s\n',exposureMethod);
            end
            
            %% Select popup contents
            nameList = vcGetObjectNames('sensor');
            selectList = {'New'};
            for ii=1:length(nameList)
                selectList{ii+1} = sprintf('%d-%s',ii,nameList{ii});
            end
            
            % Sometimes we get an error here, but not all the time.  I don't know why.
            app.popupSelect.Items = selectList;
            app.popupSelect.Value = app.popupSelect.Items{vcGetSelectedObject('sensor')+1};
            
            %% Start of eliminating code below.  Just show the CFA
            sensorShowCFA(sensor,app);
            
            % Make the pop up selection match the sensor type
            switch ieParamFormat(sensorGet(sensor,'cfa name'))
                case 'bayerrgb'
                    app.popISA.Value = app.popISA.Items{1};
                case 'bayercmy'
                    app.popISA.Value = app.popISA.Items{2};
                case 'rgbw'
                    app.popISA.Value = app.popISA.Items{3};
                case 'monochrome'
                    app.popISA.Value = app.popISA.Items{4};
                otherwise
                    % Other
                    app.popISA.Value = app.popISA.Items{5};
            end
            
            % Display the quantization method
            switch lower(sensorGet(sensor,'quantization method'))
                case 'analog'
                    app.popQuantization.Value = app.popQuantization.Items{1};
                case 'linear'
                    switch sensorGet(sensor,'nbits')
                        case 4
                            app.popQuantization.Value = app.popQuantization.Items{2};
                        case 8
                            app.popQuantization.Value = app.popQuantization.Items{3};
                        case 10
                            app.popQuantization.Value = app.popQuantization.Items{4};
                        case 12
                            app.popQuantization.Value = app.popQuantization.Items{5};
                        otherwise
                            warning('Unknown quantization'); %#ok<WNTAG>
                            app.popQuantization.Value =  app.popQuantization.Items{1};
                    end
                otherwise
                    app.popQuantization.Value = app.popQuantization.Items{1};
            end
            
            %% Display the image.
            gam = str2double(app.GammaEditField.Value);
            switch lower(app.MaxbrightSwitch.Value)
                case 'on'
                    scaleMax = 1;
                case 'off'
                    scaleMax = 0;
            end
            
            % Show the image
            sensorShowImage(sensor,gam,scaleMax,app);
            
            % Make sure the window is visible
            figure(app.figure1);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function sensorWindow_OpeningFcn(app, varargin)
            
            % Start out invisible.  We turn it on later.
            app.figure1.Visible = false;
            app.sensorTable.Visible = false;
            app.pixelTable.Visible = false;
            app.imgMain.Visible = false;

            % See if simplifying interactions makes thing faster
            if getpref('ISET', 'fastAxes', false)
                app.imgMain.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
                app.imgMain.Toolbar = [];
            end

            % Window position preference
            ISETprefs = getpref('ISET');
            if isfield(ISETprefs,'wPos')
                s = get(0);
                wPos = ISETprefs.wPos;
                if numel(wPos{4}) >= 2
                    if wPos{4}(1) < 1 % we have old-style
                        app.figure1.Position(1:2) = round(wPos{4}(1:2) .* s.ScreenSize(3:4));
                    else
                        app.figure1.Position(1:2) = wPos{4}(1:2);
                    end
                end
            end
            
            % Initialize sensorWindow, and see if it is already in the
            % vcSESSION database.
            dbApp = ieSessionGet('sensor window');
            if isempty(dbApp) || ~isvalid(dbApp)
                % Store this app in the database
                ieSessionSet('sensor window',app);
            end
           
            % Adjust the name
            str = datetime('now','Format','hh:mm:ss');
            app.figure1.Name = sprintf('Sensor %s',str);
            
            % Read the currently selected sensor and refresh the
            % window entries.
            app.refresh;
            
            % Read and set the default font size
            ieFontInit(app.figure1);

            % Show table fonts slightly smaller than surrounding. We
            % can set row striping, but for now just use main
            % background color
            app.pixelTable.FontSize = getpref('ISET', 'fontSize', 12) - 3;
            app.pixelTable.FontName = 'Courier';
            app.pixelTable.BackgroundColor = [app.figure1.Color;app.figure1.Color];
            
            app.sensorTable.FontSize = getpref('ISET', 'fontSize', 12) - 3;
            app.sensorTable.FontName = 'Courier';
            app.sensorTable.BackgroundColor = [app.figure1.Color;app.figure1.Color];
            
            % Make it all visible
            app.figure1.Visible = true;
            app.imgMain.Visible = true;
            
            app.sensorTable.Visible = true;
            app.pixelTable.Visible = true;
            
        end

        % Button pushed function: btnComputeImage
        function btnComputeImage_Callback(app, event)
            % Button press computes the image from the current oi
            oi = ieGetObject('OI');
            if isempty(oi)
                % No oi
                ieInWindowMessage('No optical image.',app);
                return;
            elseif isempty(oiGet(oi,'photons'))
                % or the oi was not yet computed.
                ieInWindowMessage('No optical image photons.',app);
                return;
            else
                % Good to go.  Clear the message.
                ieInWindowMessage('',app)
            end
            
            [sensor,val] = ieGetObject('ISA');
            sensor = sensorCompute(sensor,oi);
            
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Button pushed function: btnNext
        function btnNext_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the + on the right of the selection popup
            s  = ieSessionGet('selected','sensor');
            nS = ieSessionGet('nobjects','sensor');
            s = min(s + 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ISA',s);
            app.refresh;
        end

        % Button pushed function: btnPrev
        function btnPrev_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Push button with the - on the left of the selection popup
            s  = ieSessionGet('selected','sensor');
            nS = ieSessionGet('nobjects','sensor');
            s = min(s - 1,nS);
            s = max(s,1);
            vcSetSelectedObject('ISA',s);
            app.refresh;
        end

        % Button pushed function: btnShowCFAExpDurations
        function btnShowCFAExpDurations_Callback(app, event)
            % Bring up a gui that shows the CFA exposures
            sensor = ieGetObject('sensor');
            
            % Make sure we are in the CFA format
            sensor = sensorAdjustCFATimes(app,sensor);
            
            % Put the exposureData matrix into the base workspace in ms
            fmt    = '%.1f';
            prompt = 'Time (ms)';
            defMatrix = sensorGet(sensor,'expTime')*1e3;
            saturation = 0.3;
            filterRGB = sensorFilterRGB(sensor,saturation);
            ieReadSmallMatrix(size(defMatrix),defMatrix,fmt,prompt,[],'msExposureData',filterRGB);
            
            % Read base space data in seconds.  What is this????
            secExposureData = evalin('base','msExposureData')*1e-3;
            
            % Put the sensor back
            sensor = sensorSet(sensor,'expTime',secExposureData);
            ieReplaceObject(sensor);
        end

        % Value changed function: editConvGain
        function editConvGain_Callback(app, event)
            [sensor,val] = ieGetObject('sensor');
            
            % Interface is in microvolts per electron, typical value is 10-100 uV/e-
            % We store the value in standard units:  V/e-
            sensor = sensorSet(sensor,'pixel conversion gain',str2double(app.editConvGain.Value)*10^(-6));
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: editDarkCurrent
        function editDarkCurrent_Callback(app, event)
            %  The name of this routine should be changed.  We used to specify dark
            %  current, but now we specify dark voltage
            %  This should be editDarkVoltage_Callback().
            %
            % We specify dark voltage (no longer current) in mV/pixel/sec.
            % The parameters darkcurrent, darkcurrentdensity are now derived from this
            % and other sensor properties.
            [sensor,val] = ieGetObject('sensor');
            
            % Value entered is in millivolts.  Value stored is in Volts.
            dk = str2double(app.editDarkCurrent.Value)*10^-3;
            sensor =  sensorSet(sensor,'pixel dark voltage',dk);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Menu selected function: editDeleteSome
        function editDeleteSome_Callback(app, event)
            % Edit delete some sensors
            %
            vcDeleteSomeObjects('sensor');
            app.refresh;
            
        end

        % Value changed function: editExpFactor
        function editExpFactor_Callback(app, event)
            % Set the scale factor between exposures in bracketing mode
            sensor = ieGetObject('sensor');
            sensor = sensorAdjustBracketTimes(app,sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Value changed function: editExpTime
        function editExpTime_Callback(app, event)
            % If the person edits the exposure time, we assume that we must
            % turn the auto-exposure mode off.
            
            [sensor,val] = ieGetObject('sensor');
            
            % Interface is in several possible time units. We read in the those units,
            % but we store exposure time in seconds.
            str = app.txtExposureUnits.Text;
            switch str
                case '(sec)'
                    sFactor = 1;
                case '(us)'
                    sFactor = 1e-6;
                case '(ms)'
                    % (ms)
                    sFactor = 1e-3;
                otherwise
                    sFactor = 1e-3;
                    warning('unexpected time string %s\n',str);
            end
            
            % The time and the scale factor
            sensor = sensorSet(sensor,'expTime',str2double(app.editExpTime.Value)*sFactor);
            
            % If the expTime is in bracketed mode, create the vector
            switch app.popupExpMode.Value
                case 'Single'
                case 'Bracketing'
                    sensor = sensorAdjustBracketTimes(app,sensor);
                case 'CFA Exposure'
                otherwise
                    error('Unknown exposure mode %s',app.popupExpMode.Value);
            end
            
            % Turn off auto-exposure because the user set the time.
            sensor = sensorSet(sensor,'autoexposure','off');
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: editGainFPN
        function editGainFPN_Callback(app, event)
            [sensor,val] = ieGetObject('ISA');
            
            % Standard deviation of the slope of the photoresponse function.
            % These numbers are stored as a percentage, just as in the interface.
            % When we compute the standard deviation of the slope, we calculate
            % gainImage = 1 + randn()*(sigmaGainFPN/100)
            %
            % This produces a normal random variable with a standard deviation equal to
            % the percent in the window.
            %
            gainSD = str2double(get(hObject,'String'));
            sensor = sensorSet(sensor,'gainSD',gainSD);
            sensor = sensorSet(sensor,'gainFPNimage',[]);
            
            ieReplaceObject(sensor,val);
            
            app.refresh;
            
        end

        % Value changed function: GammaEditField
        function editGam_Callback(app, event)
            app.refresh;
        end

        % Value changed function: editISARows
        function editISARows_Callback(app, event)
            % Edit number of sensor rows
            [sensor,val] = ieGetObject('ISA');
            
            targetSize = str2double(app.editISARows.Value);
            sensor = sensorSet(sensor,'rows',targetSize);
            str = sprintf('%.0f',sensorGet(sensor,'rows'));
            app.editISARows.Value = str;
            
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
            
        end

        % Value changed function: editISAcols
        function editISAcols_Callback(app, event)
            % Edit box - adjust number of columns
            [sensor,val] = ieGetObject('ISA');
            
            targetSize = str2double(app.editISAcols.Value);
            sensor = sensorSet(sensor,'cols',targetSize);
            str = sprintf('%.0f',sensorGet(sensor,'cols'));
            app.editISAcols.Value = str;
            
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: editNExposures
        function editNExposures_Callback(app, event)
            % Set the number of exposures in the bracketing mode
            sensor = ieGetObject('sensor');
            sensor = sensorAdjustBracketTimes(app,sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Value changed function: editOffsetFPN
        function editOffsetFPN_Callback(app, event)
            % DSNU measurement in mv
            [sensor,val] = ieGetObject('ISA');
            
            % SD displayed in millivolts, stored in volts
            sd = str2double(app.editOffsetFPN.Value)*10^-3;
            sensor = sensorSet(sensor,'offset sd',sd);
            sensor = sensorSet(sensor,'offset fpn image',[]);
            
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: editReadNoise
        function editReadNoise_Callback(app, event)
            [sensor,val] = ieGetObject('ISA');
            
            rn = str2double(app.editReadNoise.Value);   % Display is mV, stored in Volts.
            sensor = sensorSet(sensor,'pixel readnoisemillivolts',rn);
            
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: editVoltageSwing
        function editVoltageSwing_Callback(app, event)
            [sensor,val] = ieGetObject('ISA');
            sensor = sensorSet(sensor,'pixel voltage swing',str2double(app.editVoltageSwing.Value));
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Menu selected function: humanCones631
        function humanCones631_Callback(app, event)
            % Sensor | Human cones | Cones-631
            params.sz = [128,192];
            params.rgbDensities = [0.0 .6 .3 .1]; % Empty, L,M,S
            params.coneAperture = [2.5 2.5]*1e-6;     % In meters
            pixel = [];
            sensor = sensorCreate('human',pixel,params);
            vcAddAndSelectObject(sensor);
            app.refresh;
        end

        % Menu selected function: humanConesKLMS1631
        function humanConesKLMS1631_Callback(app, event)
            % Sensor | Human cones | Cones-631
            params.sz = [128,192];
            params.rgbDensities = [0.1 .6 .3 .1]; % Empty, L,M,S
            params.coneAperture = [2.5 2.5]*1e-6;     % In meters
            pixel = [];
            sensor = sensorCreate('human',pixel,params);
            vcAddAndSelectObject(sensor);
            app.refresh;
        end

        % Menu selected function: menuAnBasicE
        function menuAnBasicE_Callback(app, event)
            sensorStats(ieGetObject('sensor'),'basic','electrons');
        end

        % Menu selected function: menuAnBasic
        function menuAnBasicV_Callback(app, event)
            sensorStats(ieGetObject('sensor'),'basic','volts');
        end

        % Menu selected function: CCManalysisMenu
        function menuAnColCCM_Callback(app, event)
            % Analyze | Color | Color Conversion Matrix
            % MCC to XYZ_D65
            sensor = ieGetObject('sensor');
            [L,corners] = sensorCCM(sensor);
            
            fprintf('    ==  MCC to XYZ_D65 matrix  ==\n');
            disp(L)
            
            % Store the selection of the corners
            sensor = sensorSet(sensor,'mcc corner points',corners);
            ieReplaceObject(sensor);
        end

        % Menu selected function: menuAnColorRB
        function menuAnColorRB_Callback(app, event)
            % Analyze | Color | RB Analysis
            sensorPlotColor(ieGetObject('sensor'),'rb');
        end

        % Menu selected function: menuAnColorRG
        function menuAnColorRG_Callback(app, event)
            
            % Analyze | Color | RG Analysis
            sensorPlotColor(ieGetObject('sensor'),'rg');
            
        end

        % Menu selected function: menuAnColor
        function menuAnColor_Callback(app, event)
            
        end

        % Callback function
        function menuAnComputeFromOI_Callback(app, event)
            btnComputeImage_Callback(app);
        end

        % Menu selected function: menuAnComputeFromScene
        function menuAnComputeFromScene_Callback(app, event)
            % Recompute the sensor data starting all the way back with the current
            % scene and optical image.
            scene = ieGetObject('scene');
            if isempty(scene)
                warning('No scene.')
                return;
            end
            
            oi = ieGetObject('oi');
            if isempty(oi)
                warndlg('No OI');
                return;
            end
            
            ieInWindowMessage('Updating OI.',app,[]);
            oi = oiCompute(scene,oi);
            ieReplaceObject(oi);
            ieInWindowMessage('',app);
            
            btnComputeImage_Callback(app);
        end

        % Menu selected function: menuAnExposureValue
        function menuAnExposureValue_Callback(app, event)
            % Analyze | SNR | Exposure Value
            oi     = ieGetObject('oi');
            optics = oiGet(oi,'optics');
            sensor = ieGetObject('sensor');
            EV = exposureValue(optics,sensor);
            
            str = sprintf('Exposure value (log2(f/#^2 / T)):    %.2f',EV);
            ieInWindowMessage(str,app);
            fprintf('\n\n'); disp(str);
        end

        % Menu selected function: menuHorLineVolts
        function menuHorLineVolts_Callback(app, event)
            % Analyze | Line | Horizontal | Volts
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'volts hline',[], ...
                'capture',capture, ...
                'two lines',false);
        end

        % Menu selected function: menuAnLineV
        function menuAnLineV_Callback(app, event)
            % Analyze | Line | Vertical | Electrons
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons vline',[],...
                'capture',capture, ...
                'two lines', false);
        end

        % Menu selected function: LinesMenu
        function LinesMenu_Callback(app, event)
            % Analyze->Line menu
        end

        % Menu selected function: menuAnPOShowUL
        function menuAnPOShowUL_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            sensor = ieGetObject('ISA');
            mlPrint(sensorGet(sensor,'microLens'));
        end

        % Menu selected function: menuAnPO
        function menuAnPO_Callback(app, event)
            % Analysis->Pixel Optics
        end

        % Menu selected function: menuAnPhotExp
        function menuAnPhotExp_Callback(app, event)
            % Analyze | SNR | Photometric Exp
            
            str = sprintf('Photometric exposure (lux-sec): %.2f',...
                photometricExposure(ieGetObject('OI'),ieGetObject('ISA')));
            
            % Display in window message
            ieInWindowMessage(str,app);
            fprintf('\n\n'); disp(str);
            
        end

        % Menu selected function: menuAnPixHistQ
        function menuAnPixHistQ_Callback(app, event)
            %plotSensorHistogram('e');
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons histogram', ...
                [],'capture',capture);
            
        end

        % Menu selected function: menuAnHistogramVolts
        function menuAnPixHistV_Callback(app, event)
            % Analyze | Line | Vertical | Volts
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'volts histogram',[], ...
                'capture',capture);
        end

        % Menu selected function: menuAnPixOptLoadUL
        function menuAnPixOptLoadUL_Callback(app, event)
            % Analyze | Pixel Optics | Load uL
            
            fullName = vcSelectDataFile('stayPut','r');
            if isempty(fullName), disp('User canceled'); return; end
            tmp = load(fullName);
            if isfield(tmp,'ml')
                ISA = ieGetObject('ISA');
                ISA = sensorSet(ISA,'microLens',tmp.ml);
                ieReplaceObject(ISA);
            else
                error('No microlens structure (named ml) in the file.');
            end
            
        end

        % Menu selected function: menuAnPixOptSaveUl
        function menuAnPixOptSaveUl_Callback(app, event)
            % Analyze | Pixel Optics | Save uL
            
            ISA = ieGetObject('ISA');
            ml = sensorGet(ISA,'microLens');
            fullName = vcSelectDataFile('stayPut','w');
            if isempty(fullName), disp('User canceled'); return; end
            save(fullName,'ml');
        end

        % Menu selected function: menuAnPixelSNR
        function menuAnPixelSNR_Callback(app, event)
            % Graph the pixel SNR as a function of voltage swing
            sensorPlot(ieGetObject('sensor'),'pixel snr');
            % plotPixelSNR;
            
        end

        % Menu selected function: menuAnROIStats
        function menuAnROIStats_Callback(app, event)
            % Analysis->ROI statistics
            
        end

        % Menu selected function: menuAnSNR
        function menuAnSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze SNR menu
            return
        end

        % Menu selected function: menuAnSensorSNR
        function menuAnSensorSNR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            %
            plotSensorSNR;
            
        end

        % Menu selected function: menuAnalyzeMicroLens
        function menuAnalyzeMicroLens_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Microlens window
            if isempty(which('microLensWindow'))
                warndlg('No micro lens analysis software on your path.  Contact ImagEval for a license.');
                return;
            else
                % Should test for license here
            end
            
            microLensWindow;
            
        end

        % Menu selected function: menuAnalyzePOVignetting
        function menuAnalyzePOVignetting_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze | Pixel Optics | Relative Illumination
            sensor = ieGetObject('sensor');
            sensorPlot(sensor,'etendue');
            
        end

        % Menu selected function: menuAnalyze
        function menuAnalyze_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Analyze menu
            
        end

        % Menu selected function: menuAppNotes
        function menuAppNotes_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Documentation (web)
            ieManualViewer('imageval code');
            
        end

        % Menu selected function: ColorfiltersMenu
        function menuColorFilters_Callback(app, event)
            % Sensor | Load Color Filters
            sensor = vcGetSelectedObject('sensor');
            sensor = sensorReadFilter('colorfilters',sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Menu selected function: menuCopySensor
        function menuCopySensor_Callback(app, event)
            sensor = ieGetObject('ISA');
            
            newName = ieReadString('New ISA name','new-isa');
            if isempty(newName),  return;
            else,    sensor = sensorSet(sensor,'name',newName);
            end
            
            ieAddObject(sensor);
            app.refresh;
            
        end

        % Menu selected function: menuDesignCFA
        function menuDesignCFA_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % I first use the 'Design CFA' option in the Sensor Menu to design the CFA
            % I desire. I make sure I position the individual color filters
            % appropriately in the 2x2 square as 'RGGB'.
            %
            % Then after I load all the respective transmissivity values for each of
            % the filters, I save the CFA to a file (say we call this file
            % micronRGGB.mat).
            %
            % Now during a simulation run, when I load this CFA using the "Load CFA"
            % option in the Sensor menu, the "Standard CFA" cell in the upper right
            % hand side of the ISET-Sensor Window gets automatically updated as "Other"
            % instead of 'bayer-rggb' as I would expect.
            %
            sensorDesignCFA_App;
        end

        % Callback function
        function menuEditClearMessage_Callback(app, event)
            ieInWindowMessage('',ieSessionGet('sensorWindowHandles'),[]);
        end

        % Menu selected function: menuEditCreate
        function menuEditCreate_Callback(app, event)
            createNewSensor(app, hObject, eventdata, handles);
            app.refresh;
        end

        % Menu selected function: menuEditDelete
        function menuEditDelete_Callback(app, event)
            % Edit | Delete
            sensorDelete(app);
            app.refresh;
        end

        % Menu selected function: menuEditFontSize
        function menuEditFontSize_Callback(app, event)
            % Edit | Change font size
            ieFontSizeSet(app.figure1);
        end

        % Menu selected function: menuEditName
        function menuEditName_Callback(app, event)
            sensor = vcGetSelectedObject('ISA');
            
            newName = ieReadString('New sensor name','new-isa');
            if isempty(newName),  return;
            else,   sensor = sensorSet(sensor,'name',newName);
            end
            
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Menu selected function: menuEditResampleWave
        function menuEditResampleWave_Callback(app, event)
            % Edit | Resample Wavelength
            sensor = ieGetObject('sensor');
            sensor = sensorResampleWave(sensor);
            ieReplaceObject(sensor);
        end

        % Menu selected function: menuEditViewer
        function menuEditViewer_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Edit | Viewer
            sensor = ieGetObject('ISA');
            img = sensorData2Image(sensor,'volts');
            ieViewer(img);
            
        end

        % Callback function
        function menuEditZoom_Callback(app, event)
            % Edit | Zoom
            % Toggle the zoom state.  The zoom state is stored in the status of the
            % checkbox of the zoom menu item.
            if isequal(get(hObject,'checked'),'off')
                zoom('on');
                set(hObject,'checked','on');
            else % Must be on
                zoom('off');
                set(hObject,'checked','off');
            end
            
        end

        % Menu selected function: menuEdit
        function menuEdit_Callback(app, event)
            
        end

        % Callback function
        function menuFFTVert_Callback(app, event)
            % This looks wrong to me
            sensorPlotLine(ieGetObject('sensor'),'v','volts','fft');
        end

        % Callback function
        function menuFFThor_Callback(app, event)
            sensorPlotLine(ieGetObject('sensor'),'h','volts','fft');
        end

        % Menu selected function: menuFileClose
        function menuFileClose_Callback(app, event)
            sensorClose(app);
        end

        % Menu selected function: menuFileLoadVoltsMat
        function menuFileLoadVoltsMat_Callback(app, event)
            % Load Volts (MAT file)
            % Load in new voltage data from a MAT file
            %
            fullName = vcSelectDataFile('stayput','r','mat');
            if isempty(fullName), return; end
            
            tmp = load(fullName,'volts');
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'volts',tmp.volts);
            
            vcReplaceAndSelectObject(sensor);
            app.refresh;
        end

        % Menu selected function: menuFileLoad
        function menuFileLoad_Callback(app, event)
            
        end

        % Menu selected function: menuFileResh
        function menuFileRefresh_Callback(app, event)
            app.refresh;
        end

        % Menu selected function: menuFileSaveVoltsMat
        function menuFileSaveVoltsMat_Callback(app, event)
            % Save Volts (MAT file)
            sensor = ieGetObject('sensor');
            volts = sensorGet(sensor,'volts');
            if isempty(volts), errordlg('No voltage data.'); end
            
            fullName = vcSelectDataFile('stayput','w','mat');
            if ~isempty(fullName), save(fullName,'volts'); end
            
        end

        % Menu selected function: menuFileSave
        function menuFileSave_Callback(app, event)
            
        end

        % Menu selected function: menuFileSpecsheet
        function menuFileSpecsheet_Callback(app, event)
            % File | Spec sheet
            %
            % Ultimately, we should print out an XL spread sheet from this
            % menu item.  For now, we bring up the iePTable in its own
            % window.
            sensor = ieGetObject('sensor');
            iePTable(sensor,'format','window');
            % Some day:
            %    iePTable(sensor,'format','xl');
            
        end

        % Menu selected function: menuFile
        function menuFile_Callback(app, event)
            
        end

        % Menu selected function: menuHelpISETOnlineManual
        function menuHelpISETOnlineManual_Callback(app, event)
            % Help | ISET (online)
            ieManualViewer('iset functions');
        end

        % Menu selected function: menuHelpPixelOnline
        function menuHelpPixelOnline_Callback(app, event)
            % Help | Pixel (online)
            ieManualViewer('pixel functions');
        end

        % Menu selected function: menuHelpSensorOnline
        function menuHelpSensorOnline_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Help | Sensor (online)
            ieManualViewer('sensor functions');
            return
        end

        % Menu selected function: menuHelp
        function menuHelp_Callback(app, event)
            
        end

        % Menu selected function: menuHorLineDV
        function menuHorLineDV_Callback(app, event)
            % sensorPlotLine(ieGetObject('sensor'),'h','dv','space');
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'dv hline',[], ...
                'capture',capture, ...
                'two lines',false);
        end

        % Menu selected function: menuHorLineE
        function menuHorLineE_Callback(app, event)
            % Analyze | Line | Horizontal | Electrons
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons hline',[], ...
                'capture',capture, ...
                'two lines',false);
        end

        % Menu selected function: menuHorizontal
        function menuHorizontal_Callback(app, event)
            
        end

        % Menu selected function: menuISOSat
        function menuISOSat_Callback(app, event)
            speed = ISOspeed('saturation');
            str = sprintf('ISO speed (saturation):    %.0f\n\n',speed);
            str = [str,sprintf('Measured for a uniform D65 optical image.\n\n')];
            str = [str,sprintf('Larger means saturates at lower lux-sec level.\n\n')];
            fprintf('\n\n');
            disp(str)
            
            hdl = ieNewGraphWin;
            p = get(hdl,'Position');
            set(hdl,'Position',[p(1),p(2),p(3)/2,p(4)/2]);
            set(hdl,'Menubar','none')
            text(hdl,0,0.5,str,'fontsize',14); axis off;
        end

        % Menu selected function: menuImDSNU
        function menuImDSNU_Callback(app, event)
            sensorPlot([],'dsnu');
        end

        % Menu selected function: menuImPRNU
        function menuImPRNU_Callback(app, event)
            sensorPlot([],'prnu');
            
        end

        % Menu selected function: menuImShotNoise
        function menuImShotNoise_Callback(app, event)
            sensorPlot([],'shotnoise');
        end

        % Callback function
        function menuInfraRed_Callback(app, event)
            [sensor,val] = ieGetObject('sensor');
            sensor = sensorReadFilter('infrared',sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Menu selected function: CFAMenu
        function menuLoadCFA_Callback(app, event)
            %
            [sensor,val] = ieGetObject('sensor');
            sensor = sensorReadFilter('cfa',sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Menu selected function: PhotodetectorQEMenu
        function menuPDQE_Callback(app, event)
            [sensor,val] = ieGetObject('sensor');
            sensor = sensorReadFilter('pd spectral qe',sensor);
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Menu selected function: menuPDSize
        function menuPDSize_Callback(app, event)
            % Sensor | Design photodetector geometry
            pixelGeometryWindow_App;
        end

        % Menu selected function: menuPixelLuxSec
        function menuPixelLuxSec_Callback(app, event)
            pixelSNRluxsec;
        end

        % Menu selected function: menuPlotColorFilters
        function menuPlotColorFilters_Callback(app, event)
            sensorPlot([],'color filters');
        end

        % Menu selected function: menuPlotHumanCone
        function menuPlotHumanCone_Callback(app, event)
            % Plot | Human Cone
            sensor = ieGetObject('sensor');
            if sensorCheckHuman(sensor), sensorConePlot(sensor)
            else, ieInWindowMessage('Not a human cone sensor',app,3);
            end
            
        end

        % Menu selected function: menuPlotIR
        function menuPlotIR_Callback(app, event)
            sensorPlot([],'irfilter');
        end

        % Callback function
        function menuPlotNewGraphWindow_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            vcNewGraphWin;
            return
        end

        % Menu selected function: menuPlotPDSpectralQE
        function menuPlotPDSpectralQE_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'pixel spectral QE');
            return
        end

        % Menu selected function: menuPlotPixelSR
        function menuPlotPixelSR_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            % [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            sensorPlot([],'pixel spectral sr');
            return
        end

        % Menu selected function: menuPlotSensorImageTSize
        function menuPlotSensorImageTSize_Callback(app, event)
            % Plot | SensorImage (True Size)
            gam      = str2double(app.GammaEditField.Value);
            scaleMax = 1;
            if strcmpi(app.MaxbrightSwitch.Value,'Off')
                scaleMax = 0;
            end
            sensor   = ieGetObject('sensor');
            
            % Get voltages or digital values
            bits     = sensorGet(sensor,'bits');
            if isempty(bits)
                img      = sensorData2Image(sensor,'volts',gam,scaleMax);
            else
                img      = sensorData2Image(sensor,'dv',gam,scaleMax);
            end
            
            if ismatrix(img)
                % imtool needs monochrome images scaled between 0 and 1
                w = ieNewGraphWin; img = img/max(img(:));
                imshow(img); truesize(w);
                set(w,'Name',sensorGet(sensor,'name'));
            else
                ieViewer(img);
            end
        end

        % Menu selected function: menuPlotSpecResp
        function menuPlotSpecResp_Callback(app, event)
            sensor = ieGetObject('sensor');
            sensorPlot(sensor,'sensor spectral qe');
        end

        % Menu selected function: menuPlotSpectra
        function menuPlotSpectra_Callback(app, event)
            
        end

        % Menu selected function: menuPlot
        function menuPlot_Callback(app, event)
            
        end

        % Menu selected function: menuSaveImage
        function menuSaveImage_Callback(app, event)
            % Save Display (RGB Image)
            %
            % An option to save other data types will be needed some day, including DV
            % in particular.
            
            sensor = ieGetObject('sensor');
            gam = str2double(app.GammaEditField.Value);
            scaleMax = 1;
            if strcmpi(app.MaxbrightSwitch.Value,'Off')
                scaleMax = 0;
            end
            
            sensorSaveImage(sensor,[],'volts',gam,scaleMax);
            
        end

        % Menu selected function: menuSensorCDS
        function menuSensorCDS_Callback(app, event)
            %  Set check on CDS menu item.  We should probably display this in the
            %  information box at the right, also.
            
            sensor = ieGetObject('ISA');
            state = app.menuSensorCDS.Checked;
            
            switch lower(state)
                case 'on'
                    sensor = sensorSet(sensor,'cds',0);
                case 'off'
                    sensor = sensorSet(sensor,'cds',1);
            end
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function menuSensorCIFHalfInch_Callback(app, event)
            %
            [sensor, val] = ieGetObject('ISA');
            sensor = sensorRescale(sensor,sensorFormats('cif'),sensorFormats('halfinch'));
            ieReplaceObject(sensor,val);
            app.refresh;
            
        end

        % Callback function
        function menuSensorCIF_Callback(app, event)
            
        end

        % Menu selected function: menuSensorColFPN
        function menuSensorColFPN_Callback(app, event)
            % Sensor | Col FPN
            %
            sensor = ieGetObject('ISA');
            state = app.menuSensorColFPN.Checked;
            
            switch lower(state)
                case 'on'
                    app.menuSensorColFPN.Checked ='off';
                    
                    sensor = sensorSet(sensor,'columnFPN',[]);
                    sensor = sensorSet(sensor,'columnDSNU',[]);
                    sensor = sensorSet(sensor,'columnPRNU',[]);
                    % Should we clear the sensor data here?
                case 'off'
                    % Store the column FPN value.
                    app.menuSensorColFPN.Checked ='on';
                    
                    % Should we pull this out as a separate routine?
                    prompt={'Enter column DSNU (sd in millivolts)', ...
                        'Enter column PRNU (sd. around unity gain)'};
                    def={'1','0.01'};
                    dlgTitle= sprintf('ISET read number');
                    answer = inputdlg(prompt,dlgTitle,2,def);
                    
                    if isempty(answer), return;
                    else
                        colOffsetFPN = eval(answer{1})/1000;     % Read in mV, stored in volts
                        colGainFPN   = eval(answer{2});          % Store as sd around unity gain
                    end
                    
                    % Create and store the column noise parameters and an instance of
                    % the noise itself
                    nCol    = sensorGet(sensor,'cols');
                    colDSNU = randn(1,nCol)*colOffsetFPN;       % Offset noise stored in volts
                    colPRNU = randn(1,nCol)*colGainFPN + 1;             % Column gain noise
                    
                    % Set the parameters.  Could combine the two reads into one.
                    sensor = sensorSet(sensor,'columnFPN',[colOffsetFPN,colGainFPN]);
                    sensor = sensorSet(sensor,'columnDSNU',colDSNU);
                    sensor = sensorSet(sensor,'columnPRNU',colPRNU);
                    
            end
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Menu selected function: menuSensorExport
        function menuSensorExport_Callback(app, event)
            % File | Save | Save sensor (.mat)
            sensor = ieGetObject('ISA');
            vcExportObject(sensor);
        end

        % Menu selected function: menuSensorHumanCones
        function menuSensorHumanCones_Callback(app, event)
            
        end

        % Menu selected function: menuSensorImport
        function menuSensorImport_Callback(app, event)
            % File | Load  | Sensor (.mat)
            %
            newVal = vcImportObject('ISA');
            if isempty(newVal), return; end
            vcSetSelectedObject('ISA',newVal);
            app.refresh;
        end

        % Menu selected function: menuSensorLuxSec
        function menuSensorLuxSec_Callback(app, event)
            sensorSNRluxsec;
        end

        % Menu selected function: menuSensorMicrolens
        function menuSensorMicrolens_Callback(app, event)
            % Sensor | Design Microlens
            if ~exist('microLensWindow.m','file')
                errordlg('You do not have the optics toolkit.  Please contact ImagEval.');
            else
                microLensWindow;
            end
            
        end

        % Menu selected function: menuSensorPixelVignetting
        function menuSensorPixelVignetting_Callback(app, event)
            % Sensor | Pixel OE Method
            %
            % Set check the Pixel OE computation.
            
            sensor = ieGetObject('sensor');
            
            % Set the vignetting
            pvFlag = sensorGet(sensor,'vignetting');
            str = sprintf('Help: 0=skip,1=bare,2=centered,3=optimal');
            pvFlag = ieReadNumber(str,pvFlag,'%.0f');
            if isempty(pvFlag), return; end
            
            sensor = sensorSet(sensor,'vignetting',pvFlag);
            sensor = sensorSet(sensor,'etendue',[]);
            
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function menuSensorQCIFQuartInch_Callback(app, event)
            % Deprecated, I think
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qcif'),sensorFormats('quarterinch'));
            ieReplaceObject(isa,val);
            app.refresh;
            
        end

        % Callback function
        function menuSensorQQCIFQuartInch_Callback(app, event)
            % Deprecated, I think
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqcif'),sensorFormats('quarterinch'));
            ieReplaceObject(isa,val);
            app.refresh;
        end

        % Callback function
        function menuSensorQQCIFSixteenthInch_Callback(app, event)
            % Deprecated, I think
            
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqcif'),sensorFormats('sixteenthinch'));
            ieReplaceObject(isa,val);
            app.refresh;
            
        end

        % Callback function
        function menuSensorQQVGAQuartInch_Callback(app, event)
            % Deprecated, I think
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqvga'),sensorFormats('quarterinch'));
            ieReplaceObject(isa,val);
            app.refresh;
        end

        % Callback function
        function menuSensorQQVGASixteenthInch_Callback(app, event)
            % Deprecated, I think
            
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qqvga'),sensorFormats('sixteenthinch'));
            ieReplaceObject(isa,val);
            app.refresh;
        end

        % Callback function
        function menuSensorQVGAHalfInch_Callback(app, event)
            % Deprecated, I think
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qvga'),sensorFormats('halfinch'));
            ieReplaceObject(isa,val);
            app.refresh;
        end

        % Callback function
        function menuSensorQVGAQuartInch_Callback(app, event)
            % Deprecated, I think
            %
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('qvga'),sensorFormats('quarterinch'));
            ieReplaceObject(isa,val);
            app.refresh;
            
        end

        % Menu selected function: menuSensorSetComputeGrid
        function menuSensorSetComputeGrid_Callback(app, event)
            % Sensor | Set Compute Grid
            % No idea what this is about, really.
            % I think it may matter, though.
            %
            % Sets pixel samples in signalCurrent
            
            sensor = ieGetObject('ISA');
            currentPixelSamples = sensorGet(sensor,'nSamplesPerPixel');
            nPixelSamples = ieReadNumber('Enter odd integer specifying number of samples/pixel (default=1)',currentPixelSamples,'%.0f');
            
            if isempty(nPixelSamples), return;
            elseif nPixelSamples < 1, nPixelSamples = 1;
            elseif ~mod(nPixelSamples,2), nPixelSamples = nPixelSamples+1;
            end
            
            sensor = sensorSet(sensor,'nSamplesPerPixel',nPixelSamples);
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function menuSensorVGAHalfInch_Callback(app, event)
            % Deprecated, I think
            [val,isa] = vcGetSelectedObject('ISA');
            isa = sensorRescale(isa,sensorFormats('vga'),sensorFormats('halfinch'));
            ieReplaceObject(isa,val);
            app.refresh;
        end

        % Callback function
        function menuSensorVGA_Callback(app, event)
            % Sensor->VGA
            
        end

        % Menu selected function: menuSensor
        function menuSensor_Callback(app, event)
            
        end

        % Menu selected function: menuVertLineDV
        function menuVertLineDV_Callback(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'dv vline',[], ...
                'capture',capture, ...
                'two lines',false);
        end

        % Menu selected function: menuVertLineE
        function menuVertLineE_Callback(app, event)
            % Analyze | Line | Vertical | Electrons
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons vline',[],...
                'capture',capture, ...
                'two lines',false);
        end

        % Menu selected function: menuVertical
        function menuVertical_Callback(app, event)
            
        end

        % Menu selected function: plotMccOverlay
        function plotMccOverlay_Callback(app, event)
            % Plot | MCC overlay off
            % Delete the MCC boxes showing the selection
            
            sensor = ieGetObject('sensor');
            macbethDrawRects(sensor,'off');
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Menu selected function: plotSpecCFApattern
        function plotSpecCFApattern_Callback(app, event)
            % Plot | Spectral Information | CFA Pattern
            sensorPlot([],'CFA');
        end

        % Value changed function: popISA
        function popISA_Callback(app, event)
            % Create sensor with one of a set of standard CFA types
            % Called by popup at right that defines the color filter array types.
            %
            % When this change is made, the current data are
            % emptied and the colorOrder, pattern, and unitBlock fields are changed.
            
            % Get the current image sensor array.
            [sensor,val] = ieGetObject('ISA');
            
            % Read the named CFA choice
            thisArrayName = app.popISA.Value;
            
            % Depending on the choice, create a default ISA array so you can have the
            % proper fields for copying into the current array.
            switch ieParamFormat(thisArrayName)
                case 'bayerrgb'
                    newSensor = sensorCreate;  % Default is RGB
                case 'bayercmy'
                    newSensor = sensorCreate('cmy');
                case 'rgbw'
                    newSensor = sensorCreate('rgbw');
                case 'monochrome'
                    newSensor = sensorCreate('monochrome');
                otherwise
                    ieInWindowMessage('No Other sensor created.',app,2);
                    app.refresh;
                    return;
            end
            
            % Copy the newSensor fields into the currently selected sensor
            sensor = sensorSet(sensor,'name',newSensor.name);
            sensor = sensorSet(sensor,'color',newSensor.color);
            sensor = sensorSet(sensor,'color filter array',newSensor.cfa);
            
            % Empty the data field.
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: popQuantization
        function popQuantization_Callback(app, event)
            % Quantization popup.
            [sensor,val] = ieGetObject('ISA');
            
            qMethod = app.popQuantization.Value;
            
            sensor = sensorSet(sensor,'quantization',qMethod);
            
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            
            app.refresh;
            
        end

        % Callback function
        function popScaleSize_Callback(app, event)
            
        end

        % Value changed function: popupExpMode
        function popupExpMode_Callback(app, event)
            % Exposure popup control
            
            sensor = ieGetObject('sensor');
            
            % Determine which case we popped into
            switch app.popupExpMode.Value
                case 'Single'
                    app.AutoESwitch.Visible  = 'on';
                    
                    app.editExpTime.Visible = 'on';
                    app.editNExposures.Visible = 'off';
                    app.editExpFactor.Visible  = 'off';
                    
                    app.sliderSelectBracketedExposure.Visible= 'off';
                    
                    app.btnShowCFAExpDurations.Visible= 'off';
                    app.txtBracketExposure.Visible= 'off';
                    
                    eTime  = sensorGet(sensor,'geometricMeanExposureTime');
                    sensor = sensorSet(sensor,'expTime',eTime);
                    
                case 'Bracketing'
                    app.AutoESwitch.Visible  = 'off';
                    
                    app.editExpTime.Visible = 'on';
                    app.editExpFactor.Visible  = 'on';
                    app.editNExposures.Visible = 'on';
                    
                    app.sliderSelectBracketedExposure.Visible= 'on';
                    app.btnShowCFAExpDurations.Visible= 'off';
                    
                    app.txtBracketExposure.Visible= 'on';
                    
                    % Manage the bracket times
                    sensor = sensorAdjustBracketTimes(app);
                    
                case 'CFA Exposure'
                    app.AutoESwitch.Visible  = 'off';
                    
                    app.editExpTime.Visible = 'off';
                    app.editExpFactor.Visible  = 'off';
                    app.editNExposures.Visible = 'off';
                    
                    app.sliderSelectBracketedExposure.Visible= 'off';
                    app.btnShowCFAExpDurations.Visible= 'on';
                    
                    app.txtBracketExposure.Visible= 'off';
                    
                    sensor = sensorAdjustCFATimes(app);
                    
                otherwise
                    error('Unknown exposure condition %s\n',app.popupExpMode.Value);
            end
            
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Value changed function: popupSelect
        function popupSelect_Callback(app, event)
            % Main popup window at top.  Create a New ISA or select a
            % different one.
            if strcmp(app.popupSelect.Value,'New')
                % Create the default scene
                ieAddObject(sensorCreate);
            else
                % The scenes in Items are numbered for uniqueness of the
                % Value.
                val = find(strcmp(app.popupSelect.Items,app.popupSelect.Value));
                vcSetSelectedObject('sensor',val-1);
            end
            app.refresh;
        end

        % Menu selected function: sensorEditClearData
        function sensorEditClearData_Callback(app, event)
            % Edit | clear data
            [sensor,val] = ieGetObject('ISA');
            sensor = sensorClearData(sensor);
            ieReplaceObject(sensor,val);
            app.refresh;
        end

        % Value changed function: sliderSelectBracketedExposure
        function sliderSelectBracketedExposure_Callback(app, event)
            % Slider on the lower left of the window
            % Chooses which of the exposures to display
            sensor = ieGetObject('sensor');
            
            exposurePlane = app.sliderSelectBracketedExposure.Value;
            sensor = sensorSet(sensor,'exposurePlane',exposurePlane);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Menu selected function: LoadSaveSensorMenu
        function LoadSaveSensorMenuSelected(app, event)
            ieSetProperties('sensor');
        end

        % Value changed function: ResizeDropDown
        function popResize(app, event)
            % The drop down returns between [1/4 to 4]
            
            switch char(app.ResizeDropDown.Value)
                case '1'
                    return;
                case '1/2'
                    s = 0.5;
                case '1/4'
                    s = 0.25;
                case '2'
                    s = 2;
                case '4'
                    s = 4;
                otherwise
                    error('Unrecognized pulldown item: %s',char(app.ResizeDropDown.Value));
            end
            
            % Get the current image sensor array
            [sensor, val] = ieGetObject('ISA');
            
            rowcol = sensorGet(sensor,'size');
            rowcol = round(rowcol*s);
            sensor = sensorSet(sensor,'size',rowcol);
            ieReplaceObject(sensor,val);
            pause(1);
            
            % Reset the drop down to 1
            app.ResizeDropDown.Value = app.ResizeDropDown.Items{3};
            
            % Considering whether to recompute the sensor values here.
            % But not doing it.
            
            % Onward.
            app.refresh;
        end

        % Cell selection callback: pixelTable, sensorTable
        function sensorDescription(app, event)
            % indices = event.Indices;
            sensor = ieGetObject('sensor');
            iePTable(sensor,'format','window');
        end

        % Value changed function: AutoESwitch
        function switchAutoexposure(app, event)
            value = app.AutoESwitch.Value;
            [sensor,val] = ieGetObject('sensor');
            sensor = sensorSet(sensor,'auto exposure',value);
            ieReplaceObject(sensor,val);
        end

        % Value changed function: MaxbrightSwitch
        function switchMaxbright(app, event)
            % value = app.MaxbrightSwitch.Value;
            app.refresh;
        end

        % Close request function: figure1
        function windowClose(app, event)
            sensorClose(app);
        end

        % Menu selected function: VoltsMenu
        function twoLinesHVolts(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'volts hline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: ElectronsMenu
        function twoLinesHElectrons(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons hline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: DigitalcountMenu
        function twoLinesHDV(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'dv hline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: VoltsMenu_2
        function twoLinesVVolts(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'volts vline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: ElectronsMenu_2
        function twoLinesVElectrons(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'electrons vline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: DigitalcountMenu_2
        function twoLinesVDV(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'dv vline',[], ...
                'capture',capture, ...
                'two lines',true);
        end

        % Menu selected function: HistogramdvMenu
        function menuAnPixHistDV_Callback(app, event)
            capture = app.sliderSelectBracketedExposure.Value;
            sensorPlot(ieGetObject('sensor'),'dv histogram',[], ...
                'capture',capture);
        end

        % Menu selected function: MeanSDdvMenu
        function menuAnBasicDV_Callback(app, event)
            sensorStats(ieGetObject('sensor'),'basic','dv');
        end

        % Menu selected function: editCropMenu
        function editCrop(app, event)
            sensor   = ieGetObject('sensor');
            thisName = sensorGet(sensor,'name');
            [~,thisRect] = ieROISelect(sensor);
            sensor = sensorCrop(sensor,thisRect);
            sensor = sensorSet(sensor,'roi',round(thisRect.Position));
            sensor = sensorSet(sensor,'name',sprintf('%s-crop',thisName));
            ieAddObject(sensor);
            app.refresh;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'ISET-Sensor';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @windowClose, true);
            app.figure1.Tag = 'sensorImageWindow';
            app.menuFile = uimenu(app.figure1);
            app.menuFile.MenuSelectedFcn = createCallbackFcn(app, @menuFile_Callback, true);
            app.menuFile.Text = 'File';
            app.menuFile.Tag = 'menuFile';
            app.menuFileLoad = uimenu(app.menuFile);
            app.menuFileLoad.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoad_Callback, true);
            app.menuFileLoad.Text = 'Load';
            app.menuFileLoad.Tag = 'menuFileLoad';
            app.menuSensorImport = uimenu(app.menuFileLoad);
            app.menuSensorImport.MenuSelectedFcn = createCallbackFcn(app, @menuSensorImport_Callback, true);
            app.menuSensorImport.Text = 'Load sensor (.mat)';
            app.menuSensorImport.Tag = 'menuSensorImport';
            app.menuFileLoadVoltsMat = uimenu(app.menuFileLoad);
            app.menuFileLoadVoltsMat.MenuSelectedFcn = createCallbackFcn(app, @menuFileLoadVoltsMat_Callback, true);
            app.menuFileLoadVoltsMat.Separator = 'on';
            app.menuFileLoadVoltsMat.Text = 'Load volts (.mat)';
            app.menuFileLoadVoltsMat.Tag = 'menuFileLoadVoltsMat';
            app.menuFileSave = uimenu(app.menuFile);
            app.menuFileSave.MenuSelectedFcn = createCallbackFcn(app, @menuFileSave_Callback, true);
            app.menuFileSave.Text = 'Save';
            app.menuFileSave.Tag = 'menuFileSave';
            app.menuSensorExport = uimenu(app.menuFileSave);
            app.menuSensorExport.MenuSelectedFcn = createCallbackFcn(app, @menuSensorExport_Callback, true);
            app.menuSensorExport.Text = 'Save sensor (.mat)';
            app.menuSensorExport.Tag = 'menuSensorExport';
            app.menuFileSaveVoltsMat = uimenu(app.menuFileSave);
            app.menuFileSaveVoltsMat.MenuSelectedFcn = createCallbackFcn(app, @menuFileSaveVoltsMat_Callback, true);
            app.menuFileSaveVoltsMat.Text = 'Save volts (.mat)';
            app.menuFileSaveVoltsMat.Tag = 'menuFileSaveVoltsMat';
            app.menuSaveImage = uimenu(app.menuFileSave);
            app.menuSaveImage.MenuSelectedFcn = createCallbackFcn(app, @menuSaveImage_Callback, true);
            app.menuSaveImage.Separator = 'on';
            app.menuSaveImage.Text = 'Save display (png)';
            app.menuSaveImage.Tag = 'menuSaveImage';
            app.menuFileSpecsheet = uimenu(app.menuFile);
            app.menuFileSpecsheet.MenuSelectedFcn = createCallbackFcn(app, @menuFileSpecsheet_Callback, true);
            app.menuFileSpecsheet.Separator = 'on';
            app.menuFileSpecsheet.Text = 'Spec sheet';
            app.menuFileSpecsheet.Tag = 'menuFileSpecsheet';
            app.menuFileResh = uimenu(app.menuFile);
            app.menuFileResh.MenuSelectedFcn = createCallbackFcn(app, @menuFileRefresh_Callback, true);
            app.menuFileResh.Separator = 'on';
            app.menuFileResh.Text = 'Refresh';
            app.menuFileResh.Tag = 'menuFileResh';
            app.menuFileClose = uimenu(app.menuFile);
            app.menuFileClose.MenuSelectedFcn = createCallbackFcn(app, @menuFileClose_Callback, true);
            app.menuFileClose.Separator = 'on';
            app.menuFileClose.Text = 'Close';
            app.menuFileClose.Tag = 'menuFileClose';
            app.menuEdit = uimenu(app.figure1);
            app.menuEdit.MenuSelectedFcn = createCallbackFcn(app, @menuEdit_Callback, true);
            app.menuEdit.Text = 'Edit';
            app.menuEdit.Tag = 'menuEdit';
            app.menuEditName = uimenu(app.menuEdit);
            app.menuEditName.MenuSelectedFcn = createCallbackFcn(app, @menuEditName_Callback, true);
            app.menuEditName.Text = '(Re-) Name sensor';
            app.menuEditName.Tag = 'menuEditName';
            app.menuEditCreate = uimenu(app.menuEdit);
            app.menuEditCreate.MenuSelectedFcn = createCallbackFcn(app, @menuEditCreate_Callback, true);
            app.menuEditCreate.Separator = 'on';
            app.menuEditCreate.Text = 'Create new sensor';
            app.menuEditCreate.Tag = 'menuEditCreate';
            app.menuCopySensor = uimenu(app.menuEdit);
            app.menuCopySensor.MenuSelectedFcn = createCallbackFcn(app, @menuCopySensor_Callback, true);
            app.menuCopySensor.Text = 'Copy current sensor';
            app.menuCopySensor.Tag = 'menuCopySensor';
            app.menuEditDelete = uimenu(app.menuEdit);
            app.menuEditDelete.MenuSelectedFcn = createCallbackFcn(app, @menuEditDelete_Callback, true);
            app.menuEditDelete.Text = 'Delete current sensor';
            app.menuEditDelete.Tag = 'menuEditDelete';
            app.editDeleteSome = uimenu(app.menuEdit);
            app.editDeleteSome.MenuSelectedFcn = createCallbackFcn(app, @editDeleteSome_Callback, true);
            app.editDeleteSome.Text = 'Delete some sensors';
            app.editDeleteSome.Tag = 'editDeleteSome';
            app.editCropMenu = uimenu(app.menuEdit);
            app.editCropMenu.MenuSelectedFcn = createCallbackFcn(app, @editCrop, true);
            app.editCropMenu.Separator = 'on';
            app.editCropMenu.Text = 'Crop';
            app.menuEditResampleWave = uimenu(app.menuEdit);
            app.menuEditResampleWave.MenuSelectedFcn = createCallbackFcn(app, @menuEditResampleWave_Callback, true);
            app.menuEditResampleWave.Text = 'Resample wavelength';
            app.menuEditResampleWave.Tag = 'menuEditResWave';
            app.sensorEditClearData = uimenu(app.menuEdit);
            app.sensorEditClearData.MenuSelectedFcn = createCallbackFcn(app, @sensorEditClearData_Callback, true);
            app.sensorEditClearData.Text = 'Clear data';
            app.sensorEditClearData.Tag = 'sensorEditClearData';
            app.menuEditFontSize = uimenu(app.menuEdit);
            app.menuEditFontSize.MenuSelectedFcn = createCallbackFcn(app, @menuEditFontSize_Callback, true);
            app.menuEditFontSize.Separator = 'on';
            app.menuEditFontSize.Text = 'Change font size';
            app.menuEditFontSize.Tag = 'menuEditFontSize';
            app.menuEditViewer = uimenu(app.menuEdit);
            app.menuEditViewer.MenuSelectedFcn = createCallbackFcn(app, @menuEditViewer_Callback, true);
            app.menuEditViewer.Separator = 'on';
            app.menuEditViewer.Text = 'Viewer';
            app.menuEditViewer.Tag = 'menuEditViewer';
            app.LoadSaveSensorMenu = uimenu(app.menuEdit);
            app.LoadSaveSensorMenu.MenuSelectedFcn = createCallbackFcn(app, @LoadSaveSensorMenuSelected, true);
            app.LoadSaveSensorMenu.Text = 'Load / Save Sensor...';
            app.menuPlot = uimenu(app.figure1);
            app.menuPlot.MenuSelectedFcn = createCallbackFcn(app, @menuPlot_Callback, true);
            app.menuPlot.Text = 'Plot';
            app.menuPlot.Tag = 'menuPlot';
            app.menuImDSNU = uimenu(app.menuPlot);
            app.menuImDSNU.MenuSelectedFcn = createCallbackFcn(app, @menuImDSNU_Callback, true);
            app.menuImDSNU.Text = 'DSNU';
            app.menuImDSNU.Tag = 'menuImDSNU';
            app.menuImPRNU = uimenu(app.menuPlot);
            app.menuImPRNU.MenuSelectedFcn = createCallbackFcn(app, @menuImPRNU_Callback, true);
            app.menuImPRNU.Text = 'PRNU';
            app.menuImPRNU.Tag = 'menuImPRNU';
            app.menuImShotNoise = uimenu(app.menuPlot);
            app.menuImShotNoise.MenuSelectedFcn = createCallbackFcn(app, @menuImShotNoise_Callback, true);
            app.menuImShotNoise.Text = 'Shot noise';
            app.menuImShotNoise.Tag = 'menuImShotNoise';
            app.menuPlotSpectra = uimenu(app.menuPlot);
            app.menuPlotSpectra.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSpectra_Callback, true);
            app.menuPlotSpectra.Separator = 'on';
            app.menuPlotSpectra.Text = 'Spectral information';
            app.menuPlotSpectra.Tag = 'menuPlotSpectra';
            app.menuPlotColorFilters = uimenu(app.menuPlotSpectra);
            app.menuPlotColorFilters.MenuSelectedFcn = createCallbackFcn(app, @menuPlotColorFilters_Callback, true);
            app.menuPlotColorFilters.Text = 'Color filters';
            app.menuPlotColorFilters.Tag = 'menuPlotColorFilters';
            app.menuPlotIR = uimenu(app.menuPlotSpectra);
            app.menuPlotIR.MenuSelectedFcn = createCallbackFcn(app, @menuPlotIR_Callback, true);
            app.menuPlotIR.Text = 'Other filter (e.g., infrared)';
            app.menuPlotIR.Tag = 'menuPlotIR';
            app.plotSpecCFApattern = uimenu(app.menuPlotSpectra);
            app.plotSpecCFApattern.MenuSelectedFcn = createCallbackFcn(app, @plotSpecCFApattern_Callback, true);
            app.plotSpecCFApattern.Text = 'CFA pattern';
            app.plotSpecCFApattern.Tag = 'plotSpecCFApattern';
            app.menuPlotPDSpectralQE = uimenu(app.menuPlotSpectra);
            app.menuPlotPDSpectralQE.MenuSelectedFcn = createCallbackFcn(app, @menuPlotPDSpectralQE_Callback, true);
            app.menuPlotPDSpectralQE.Text = 'PD spectral QE';
            app.menuPlotPDSpectralQE.Tag = 'menuPlotPDSpectralQE';
            app.menuPlotSpecResp = uimenu(app.menuPlotSpectra);
            app.menuPlotSpecResp.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSpecResp_Callback, true);
            app.menuPlotSpecResp.Text = 'Sensor spectral QE';
            app.menuPlotSpecResp.Tag = 'menuPlotSpecResp';
            app.menuPlotPixelSR = uimenu(app.menuPlotSpectra);
            app.menuPlotPixelSR.MenuSelectedFcn = createCallbackFcn(app, @menuPlotPixelSR_Callback, true);
            app.menuPlotPixelSR.Separator = 'on';
            app.menuPlotPixelSR.Text = 'Pixel SR (amps/watt)';
            app.menuPlotPixelSR.Tag = 'menuPlotPixelSR';
            app.menuPlotSensorImageTSize = uimenu(app.menuPlot);
            app.menuPlotSensorImageTSize.MenuSelectedFcn = createCallbackFcn(app, @menuPlotSensorImageTSize_Callback, true);
            app.menuPlotSensorImageTSize.Text = 'Sensor image (true size)';
            app.menuPlotSensorImageTSize.Tag = 'menuPlotSensorImageTSize';
            app.plotMccOverlay = uimenu(app.menuPlot);
            app.plotMccOverlay.MenuSelectedFcn = createCallbackFcn(app, @plotMccOverlay_Callback, true);
            app.plotMccOverlay.Text = 'MCC overlay off';
            app.plotMccOverlay.Tag = 'plotMccOverlay';
            app.menuPlotHumanCone = uimenu(app.menuPlot);
            app.menuPlotHumanCone.MenuSelectedFcn = createCallbackFcn(app, @menuPlotHumanCone_Callback, true);
            app.menuPlotHumanCone.Separator = 'on';
            app.menuPlotHumanCone.Text = 'Human cone';
            app.menuPlotHumanCone.Tag = 'menuPlotHumanCone';
            app.menuSensor = uimenu(app.figure1);
            app.menuSensor.MenuSelectedFcn = createCallbackFcn(app, @menuSensor_Callback, true);
            app.menuSensor.Text = 'Sensor';
            app.menuSensor.Tag = 'menuSensor';
            app.LoadMenu = uimenu(app.menuSensor);
            app.LoadMenu.Text = 'Load';
            app.CFAMenu = uimenu(app.LoadMenu);
            app.CFAMenu.MenuSelectedFcn = createCallbackFcn(app, @menuLoadCFA_Callback, true);
            app.CFAMenu.Text = 'CFA';
            app.ColorfiltersMenu = uimenu(app.LoadMenu);
            app.ColorfiltersMenu.MenuSelectedFcn = createCallbackFcn(app, @menuColorFilters_Callback, true);
            app.ColorfiltersMenu.Text = 'Color filters';
            app.PhotodetectorQEMenu = uimenu(app.LoadMenu);
            app.PhotodetectorQEMenu.MenuSelectedFcn = createCallbackFcn(app, @menuPDQE_Callback, true);
            app.PhotodetectorQEMenu.Text = 'Photodetector QE';
            app.IRfilterMenu = uimenu(app.LoadMenu);
            app.IRfilterMenu.Text = 'IR filter';
            app.menuPDSize = uimenu(app.menuSensor);
            app.menuPDSize.MenuSelectedFcn = createCallbackFcn(app, @menuPDSize_Callback, true);
            app.menuPDSize.Separator = 'on';
            app.menuPDSize.Text = 'Design photodetector geometry';
            app.menuPDSize.Tag = 'menuPDSize';
            app.menuSensorMicrolens = uimenu(app.menuSensor);
            app.menuSensorMicrolens.MenuSelectedFcn = createCallbackFcn(app, @menuSensorMicrolens_Callback, true);
            app.menuSensorMicrolens.Text = 'Design microlens';
            app.menuSensorMicrolens.Tag = 'menuSensorMicrolens';
            app.menuDesignCFA = uimenu(app.menuSensor);
            app.menuDesignCFA.MenuSelectedFcn = createCallbackFcn(app, @menuDesignCFA_Callback, true);
            app.menuDesignCFA.Text = 'Design CFA';
            app.menuDesignCFA.Tag = 'menuDesignCFA';
            app.menuSensorHumanCones = uimenu(app.menuSensor);
            app.menuSensorHumanCones.MenuSelectedFcn = createCallbackFcn(app, @menuSensorHumanCones_Callback, true);
            app.menuSensorHumanCones.Text = 'Human cones';
            app.menuSensorHumanCones.Tag = 'menuSensorHumanCones';
            app.humanCones631 = uimenu(app.menuSensorHumanCones);
            app.humanCones631.MenuSelectedFcn = createCallbackFcn(app, @humanCones631_Callback, true);
            app.humanCones631.Text = 'Cones-LMS-631';
            app.humanCones631.Tag = 'humanCones631';
            app.humanConesKLMS1631 = uimenu(app.menuSensorHumanCones);
            app.humanConesKLMS1631.MenuSelectedFcn = createCallbackFcn(app, @humanConesKLMS1631_Callback, true);
            app.humanConesKLMS1631.Text = 'Cones-KLMS-1631';
            app.humanConesKLMS1631.Tag = 'humanConesKLMS1631';
            app.menuSensorCDS = uimenu(app.menuSensor);
            app.menuSensorCDS.MenuSelectedFcn = createCallbackFcn(app, @menuSensorCDS_Callback, true);
            app.menuSensorCDS.Separator = 'on';
            app.menuSensorCDS.Text = 'CDS On/Off';
            app.menuSensorCDS.Tag = 'menuSensorCDS';
            app.menuSensorColFPN = uimenu(app.menuSensor);
            app.menuSensorColFPN.MenuSelectedFcn = createCallbackFcn(app, @menuSensorColFPN_Callback, true);
            app.menuSensorColFPN.Text = 'Column FPN';
            app.menuSensorColFPN.Tag = 'menuSensorColFPN';
            app.menuSensorPixelVignetting = uimenu(app.menuSensor);
            app.menuSensorPixelVignetting.MenuSelectedFcn = createCallbackFcn(app, @menuSensorPixelVignetting_Callback, true);
            app.menuSensorPixelVignetting.Text = 'Pixel OE method';
            app.menuSensorPixelVignetting.Tag = 'menuSensorPixelVignetting';
            app.menuSensorSetComputeGrid = uimenu(app.menuSensor);
            app.menuSensorSetComputeGrid.MenuSelectedFcn = createCallbackFcn(app, @menuSensorSetComputeGrid_Callback, true);
            app.menuSensorSetComputeGrid.Text = 'Set compute grid';
            app.menuSensorSetComputeGrid.Tag = 'menuSensorSetComputeGrid';
            app.menuAnalyze = uimenu(app.figure1);
            app.menuAnalyze.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyze_Callback, true);
            app.menuAnalyze.Text = 'Analyze';
            app.menuAnalyze.Tag = 'menuAnalyze';
            app.LinesMenu = uimenu(app.menuAnalyze);
            app.LinesMenu.MenuSelectedFcn = createCallbackFcn(app, @LinesMenu_Callback, true);
            app.LinesMenu.Text = 'Line';
            app.LinesMenu.Tag = 'menuAnLine';
            app.menuHorizontal = uimenu(app.LinesMenu);
            app.menuHorizontal.MenuSelectedFcn = createCallbackFcn(app, @menuHorizontal_Callback, true);
            app.menuHorizontal.Text = 'Horizontal';
            app.menuHorizontal.Tag = 'menuHorizontal';
            app.menuHorLineVolts = uimenu(app.menuHorizontal);
            app.menuHorLineVolts.MenuSelectedFcn = createCallbackFcn(app, @menuHorLineVolts_Callback, true);
            app.menuHorLineVolts.Text = 'Volts';
            app.menuHorLineVolts.Tag = 'menuAnLineH';
            app.menuHorLineE = uimenu(app.menuHorizontal);
            app.menuHorLineE.MenuSelectedFcn = createCallbackFcn(app, @menuHorLineE_Callback, true);
            app.menuHorLineE.Text = 'Electrons';
            app.menuHorLineE.Tag = 'menuHorLineE';
            app.menuHorLineDV = uimenu(app.menuHorizontal);
            app.menuHorLineDV.MenuSelectedFcn = createCallbackFcn(app, @menuHorLineDV_Callback, true);
            app.menuHorLineDV.Text = 'Digtal count';
            app.menuHorLineDV.Tag = 'menuHorLineDV';
            app.menuVertical = uimenu(app.LinesMenu);
            app.menuVertical.MenuSelectedFcn = createCallbackFcn(app, @menuVertical_Callback, true);
            app.menuVertical.Text = 'Vertical';
            app.menuVertical.Tag = 'menuVertical';
            app.menuAnLineV = uimenu(app.menuVertical);
            app.menuAnLineV.MenuSelectedFcn = createCallbackFcn(app, @menuAnLineV_Callback, true);
            app.menuAnLineV.Text = 'Volts';
            app.menuAnLineV.Tag = 'menuAnLineV';
            app.menuVertLineE = uimenu(app.menuVertical);
            app.menuVertLineE.MenuSelectedFcn = createCallbackFcn(app, @menuVertLineE_Callback, true);
            app.menuVertLineE.Text = 'Electrons';
            app.menuVertLineE.Tag = 'menuVertLineE';
            app.menuVertLineDV = uimenu(app.menuVertical);
            app.menuVertLineDV.MenuSelectedFcn = createCallbackFcn(app, @menuVertLineDV_Callback, true);
            app.menuVertLineDV.Text = 'Digital count';
            app.menuVertLineDV.Tag = 'menuVertLineDV';
            app.TwoLinesMenu = uimenu(app.menuAnalyze);
            app.TwoLinesMenu.Text = 'Two Lines';
            app.HorizontalMenu = uimenu(app.TwoLinesMenu);
            app.HorizontalMenu.Text = 'Horizontal';
            app.VoltsMenu = uimenu(app.HorizontalMenu);
            app.VoltsMenu.MenuSelectedFcn = createCallbackFcn(app, @twoLinesHVolts, true);
            app.VoltsMenu.Text = 'Volts';
            app.ElectronsMenu = uimenu(app.HorizontalMenu);
            app.ElectronsMenu.MenuSelectedFcn = createCallbackFcn(app, @twoLinesHElectrons, true);
            app.ElectronsMenu.Text = 'Electrons';
            app.DigitalcountMenu = uimenu(app.HorizontalMenu);
            app.DigitalcountMenu.MenuSelectedFcn = createCallbackFcn(app, @twoLinesHDV, true);
            app.DigitalcountMenu.Text = 'Digital count';
            app.VerticalMenu = uimenu(app.TwoLinesMenu);
            app.VerticalMenu.Text = 'Vertical';
            app.VoltsMenu_2 = uimenu(app.VerticalMenu);
            app.VoltsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @twoLinesVVolts, true);
            app.VoltsMenu_2.Text = 'Volts';
            app.ElectronsMenu_2 = uimenu(app.VerticalMenu);
            app.ElectronsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @twoLinesVElectrons, true);
            app.ElectronsMenu_2.Text = 'Electrons';
            app.DigitalcountMenu_2 = uimenu(app.VerticalMenu);
            app.DigitalcountMenu_2.MenuSelectedFcn = createCallbackFcn(app, @twoLinesVDV, true);
            app.DigitalcountMenu_2.Text = 'Digital count';
            app.menuAnROIStats = uimenu(app.menuAnalyze);
            app.menuAnROIStats.MenuSelectedFcn = createCallbackFcn(app, @menuAnROIStats_Callback, true);
            app.menuAnROIStats.Text = 'ROI';
            app.menuAnROIStats.Tag = 'menuAnROIStats';
            app.menuAnBasic = uimenu(app.menuAnROIStats);
            app.menuAnBasic.MenuSelectedFcn = createCallbackFcn(app, @menuAnBasicV_Callback, true);
            app.menuAnBasic.Text = 'Mean, SD (v)';
            app.menuAnBasic.Tag = 'menuAnBasic';
            app.menuAnBasicE = uimenu(app.menuAnROIStats);
            app.menuAnBasicE.MenuSelectedFcn = createCallbackFcn(app, @menuAnBasicE_Callback, true);
            app.menuAnBasicE.Text = 'Mean, SD (e-)';
            app.menuAnBasicE.Tag = 'menuAnBasicE';
            app.MeanSDdvMenu = uimenu(app.menuAnROIStats);
            app.MeanSDdvMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnBasicDV_Callback, true);
            app.MeanSDdvMenu.Text = 'Mean, SD (dv)';
            app.menuAnHistogramVolts = uimenu(app.menuAnROIStats);
            app.menuAnHistogramVolts.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixHistV_Callback, true);
            app.menuAnHistogramVolts.Separator = 'on';
            app.menuAnHistogramVolts.Text = 'Histogram (v)';
            app.menuAnHistogramVolts.Tag = 'menuAnHistogramVolts';
            app.menuAnPixHistQ = uimenu(app.menuAnROIStats);
            app.menuAnPixHistQ.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixHistQ_Callback, true);
            app.menuAnPixHistQ.Text = 'Histogram (e-)';
            app.menuAnPixHistQ.Tag = 'menuAnPixHistQ';
            app.HistogramdvMenu = uimenu(app.menuAnROIStats);
            app.HistogramdvMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixHistDV_Callback, true);
            app.HistogramdvMenu.Text = 'Histogram (dv)';
            app.menuAnPO = uimenu(app.menuAnalyze);
            app.menuAnPO.MenuSelectedFcn = createCallbackFcn(app, @menuAnPO_Callback, true);
            app.menuAnPO.Text = 'Pixel optics';
            app.menuAnPO.Tag = 'menuAnPO';
            app.menuAnalyzePOVignetting = uimenu(app.menuAnPO);
            app.menuAnalyzePOVignetting.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzePOVignetting_Callback, true);
            app.menuAnalyzePOVignetting.Text = 'Relative illumination';
            app.menuAnalyzePOVignetting.Tag = 'menuAnalyzePOVignetting';
            app.menuAnalyzeMicroLens = uimenu(app.menuAnPO);
            app.menuAnalyzeMicroLens.MenuSelectedFcn = createCallbackFcn(app, @menuAnalyzeMicroLens_Callback, true);
            app.menuAnalyzeMicroLens.Separator = 'on';
            app.menuAnalyzeMicroLens.Text = 'Micro lens window';
            app.menuAnalyzeMicroLens.Tag = 'menuAnalyzeMicroLens';
            app.menuAnPixOptLoadUL = uimenu(app.menuAnPO);
            app.menuAnPixOptLoadUL.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixOptLoadUL_Callback, true);
            app.menuAnPixOptLoadUL.Separator = 'on';
            app.menuAnPixOptLoadUL.Text = 'Load uL ';
            app.menuAnPixOptLoadUL.Tag = 'menuAnPixOptLoadUL';
            app.menuAnPixOptSaveUl = uimenu(app.menuAnPO);
            app.menuAnPixOptSaveUl.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixOptSaveUl_Callback, true);
            app.menuAnPixOptSaveUl.Text = 'Save uL';
            app.menuAnPixOptSaveUl.Tag = 'menuAnPixOptSaveUl';
            app.menuAnPOShowUL = uimenu(app.menuAnPO);
            app.menuAnPOShowUL.MenuSelectedFcn = createCallbackFcn(app, @menuAnPOShowUL_Callback, true);
            app.menuAnPOShowUL.Text = 'Show uL properties';
            app.menuAnPOShowUL.Tag = 'menuAnPOShowUL';
            app.menuAnSNR = uimenu(app.menuAnalyze);
            app.menuAnSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnSNR_Callback, true);
            app.menuAnSNR.Text = 'SNR';
            app.menuAnSNR.Tag = 'menuAnSNR';
            app.menuAnPixelSNR = uimenu(app.menuAnSNR);
            app.menuAnPixelSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnPixelSNR_Callback, true);
            app.menuAnPixelSNR.Text = 'Pixel vs. volts';
            app.menuAnPixelSNR.Tag = 'menuAnPixelSNR';
            app.menuAnSensorSNR = uimenu(app.menuAnSNR);
            app.menuAnSensorSNR.MenuSelectedFcn = createCallbackFcn(app, @menuAnSensorSNR_Callback, true);
            app.menuAnSensorSNR.Text = 'Sensor vs. volts';
            app.menuAnSensorSNR.Tag = 'menuAnSensorSNR';
            app.menuPixelLuxSec = uimenu(app.menuAnSNR);
            app.menuPixelLuxSec.MenuSelectedFcn = createCallbackFcn(app, @menuPixelLuxSec_Callback, true);
            app.menuPixelLuxSec.Separator = 'on';
            app.menuPixelLuxSec.Text = 'Pixel vs. lux-Sec';
            app.menuPixelLuxSec.Tag = 'menuPixelLuxSec';
            app.menuSensorLuxSec = uimenu(app.menuAnSNR);
            app.menuSensorLuxSec.MenuSelectedFcn = createCallbackFcn(app, @menuSensorLuxSec_Callback, true);
            app.menuSensorLuxSec.Text = 'Sensor  vs. lux-Sec';
            app.menuSensorLuxSec.Tag = 'menuSensorLuxSec';
            app.menuISOSat = uimenu(app.menuAnSNR);
            app.menuISOSat.MenuSelectedFcn = createCallbackFcn(app, @menuISOSat_Callback, true);
            app.menuISOSat.Separator = 'on';
            app.menuISOSat.Text = 'ISO speed (sat)';
            app.menuISOSat.Tag = 'menuISOSat';
            app.menuAnExposureValue = uimenu(app.menuAnSNR);
            app.menuAnExposureValue.MenuSelectedFcn = createCallbackFcn(app, @menuAnExposureValue_Callback, true);
            app.menuAnExposureValue.Text = 'Exposure value';
            app.menuAnExposureValue.Tag = 'menuAnExposureValue';
            app.menuAnPhotExp = uimenu(app.menuAnSNR);
            app.menuAnPhotExp.MenuSelectedFcn = createCallbackFcn(app, @menuAnPhotExp_Callback, true);
            app.menuAnPhotExp.Text = 'Photometric exp (lux-sec)';
            app.menuAnPhotExp.Tag = 'menuAnPhotExp';
            app.menuAnColor = uimenu(app.menuAnalyze);
            app.menuAnColor.MenuSelectedFcn = createCallbackFcn(app, @menuAnColor_Callback, true);
            app.menuAnColor.Separator = 'on';
            app.menuAnColor.Text = 'Color';
            app.menuAnColor.Tag = 'menuAnColor';
            app.menuAnColorRB = uimenu(app.menuAnColor);
            app.menuAnColorRB.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorRB_Callback, true);
            app.menuAnColorRB.Text = 'RB analysis';
            app.menuAnColorRB.Tag = 'menuAnColorRB';
            app.menuAnColorRG = uimenu(app.menuAnColor);
            app.menuAnColorRG.MenuSelectedFcn = createCallbackFcn(app, @menuAnColorRG_Callback, true);
            app.menuAnColorRG.Text = 'RG analysis';
            app.menuAnColorRG.Tag = 'menuAnColorRG';
            app.CCManalysisMenu = uimenu(app.menuAnColor);
            app.CCManalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @menuAnColCCM_Callback, true);
            app.CCManalysisMenu.Text = 'CCM analysis';
            app.menuAnComputeFromScene = uimenu(app.menuAnalyze);
            app.menuAnComputeFromScene.MenuSelectedFcn = createCallbackFcn(app, @menuAnComputeFromScene_Callback, true);
            app.menuAnComputeFromScene.Text = 'Compute from scene';
            app.menuAnComputeFromScene.Tag = 'menuAnComputeFromScene';
            app.menuHelp = uimenu(app.figure1);
            app.menuHelp.MenuSelectedFcn = createCallbackFcn(app, @menuHelp_Callback, true);
            app.menuHelp.Text = 'Help';
            app.menuHelp.Tag = 'menuHelp';
            app.menuHelpSensorOnline = uimenu(app.menuHelp);
            app.menuHelpSensorOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpSensorOnline_Callback, true);
            app.menuHelpSensorOnline.Text = 'Sensor functions';
            app.menuHelpSensorOnline.Tag = 'menuHelpSensorOnline';
            app.menuHelpPixelOnline = uimenu(app.menuHelp);
            app.menuHelpPixelOnline.MenuSelectedFcn = createCallbackFcn(app, @menuHelpPixelOnline_Callback, true);
            app.menuHelpPixelOnline.Text = 'Pixel functions';
            app.menuHelpPixelOnline.Tag = 'menuHelpPixelOnline';
            app.menuHelpISETOnlineManual = uimenu(app.menuHelp);
            app.menuHelpISETOnlineManual.MenuSelectedFcn = createCallbackFcn(app, @menuHelpISETOnlineManual_Callback, true);
            app.menuHelpISETOnlineManual.Text = 'ISET functions';
            app.menuHelpISETOnlineManual.Tag = 'menuHelpISETOnlineManual';
            app.menuAppNotes = uimenu(app.menuHelp);
            app.menuAppNotes.MenuSelectedFcn = createCallbackFcn(app, @menuAppNotes_Callback, true);
            app.menuAppNotes.Text = 'Documentation (web)';
            app.menuAppNotes.Tag = 'menuAppNotes';
            app.Panel = uipanel(app.figure1);
            app.imgMain = uiaxes(app.Panel);
            app.imgMain.XTick = [];
            app.imgMain.YTick = [];
            app.popupSelect = uidropdown(app.Panel);
            app.popupSelect.Items = {'New', ''};
            app.popupSelect.ValueChangedFcn = createCallbackFcn(app, @popupSelect_Callback, true);
            app.popupSelect.Tag = 'popupSelect';
            app.popupSelect.Tooltip = 'Select sensor or create new';
            app.popupSelect.Value = 'New';
            app.btnNext = uibutton(app.Panel, 'push');
            app.btnNext.ButtonPushedFcn = createCallbackFcn(app, @btnNext_Callback, true);
            app.btnNext.Tag = 'btnNext';
            app.btnNext.Icon = 'rightArrow.png';
            app.btnNext.Tooltip = 'Show next sensor';
            app.btnNext.Text = '';
            app.btnPrev = uibutton(app.Panel, 'push');
            app.btnPrev.ButtonPushedFcn = createCallbackFcn(app, @btnPrev_Callback, true);
            app.btnPrev.Tag = 'btnPrev';
            app.btnPrev.Icon = 'leftArrow.png';
            app.btnPrev.Tooltip = 'Show previous sensor';
            app.btnPrev.Text = '';
            app.txtMessage = uilabel(app.Panel);
            app.txtMessage.Tag = 'txtMessage';
            app.txtMessage.Text = '';
            app.Panel_2 = uipanel(app.Panel);
            app.GammaEditFieldLabel = uilabel(app.Panel_2);
            app.GammaEditFieldLabel.Text = 'Gamma';
            app.GammaEditField = uieditfield(app.Panel_2, 'text');
            app.GammaEditField.ValueChangedFcn = createCallbackFcn(app, @editGam_Callback, true);
            app.GammaEditField.Value = '1';
            app.MaxbrightSwitchLabel = uilabel(app.Panel_2);
            app.MaxbrightSwitchLabel.Text = 'Max bright';
            app.MaxbrightSwitch = uiswitch(app.Panel_2, 'slider');
            app.MaxbrightSwitch.ValueChangedFcn = createCallbackFcn(app, @switchMaxbright, true);
            app.MaxbrightSwitch.Value = 'On';
            app.sliderSelectBracketedExposure = uislider(app.Panel_2);
            app.sliderSelectBracketedExposure.Limits = [1 3];
            app.sliderSelectBracketedExposure.MajorTicks = [];
            app.sliderSelectBracketedExposure.ValueChangedFcn = createCallbackFcn(app, @sliderSelectBracketedExposure_Callback, true);
            app.sliderSelectBracketedExposure.MinorTicks = [];
            app.sliderSelectBracketedExposure.Tag = 'sliderSelectBracketedExposure';
            app.sliderSelectBracketedExposure.Tooltip = {'Selects which exposure to show from bracketed collection'};
            app.sliderSelectBracketedExposure.Value = 1;
            app.imageCFA = uiimage(app.Panel_2);
            app.txtBracketExposure = uilabel(app.Panel);
            app.txtBracketExposure.Tag = 'txtBracketExposure';
            app.txtBracketExposure.Visible = 'off';
            app.txtBracketExposure.Tooltip = 'Select exposure';
            app.txtBracketExposure.Text = 'Exposure';
            app.PixelPanel = uipanel(app.figure1);
            app.PixelPanel.Title = 'Pixel';
            app.editReadNoise = uieditfield(app.PixelPanel, 'text');
            app.editReadNoise.ValueChangedFcn = createCallbackFcn(app, @editReadNoise_Callback, true);
            app.editReadNoise.Tag = 'editReadNoise';
            app.editReadNoise.Tooltip = 'Set read noise';
            app.editReadNoise.Value = '0';
            app.txtReadNoise = uilabel(app.PixelPanel);
            app.txtReadNoise.Tag = 'txtReadNoise';
            app.txtReadNoise.Tooltip = 'read noise std dev in mV (noise floor)';
            app.txtReadNoise.Text = 'Read Noise (sd)';
            app.editDarkCurrent = uieditfield(app.PixelPanel, 'text');
            app.editDarkCurrent.ValueChangedFcn = createCallbackFcn(app, @editDarkCurrent_Callback, true);
            app.editDarkCurrent.Tag = 'editDarkCurrent';
            app.editDarkCurrent.Tooltip = 'Set dark current level';
            app.editDarkCurrent.Value = '0';
            app.txtDarkCurrent = uilabel(app.PixelPanel);
            app.txtDarkCurrent.Tag = 'txtDarkCurrent';
            app.txtDarkCurrent.Tooltip = 'pixel dark voltage';
            app.txtDarkCurrent.Text = 'Dk Voltage';
            app.txtConvGain = uilabel(app.PixelPanel);
            app.txtConvGain.Tag = 'txtConvGain';
            app.txtConvGain.Tooltip = 'pixel conversion gain';
            app.txtConvGain.Text = 'Conv. Gain';
            app.editConvGain = uieditfield(app.PixelPanel, 'text');
            app.editConvGain.ValueChangedFcn = createCallbackFcn(app, @editConvGain_Callback, true);
            app.editConvGain.Tag = 'editConvGain';
            app.editConvGain.Tooltip = 'Set conversion gain';
            app.editConvGain.Value = '1';
            app.editVoltageSwing = uieditfield(app.PixelPanel, 'text');
            app.editVoltageSwing.ValueChangedFcn = createCallbackFcn(app, @editVoltageSwing_Callback, true);
            app.editVoltageSwing.Tag = 'editVoltageSwing';
            app.editVoltageSwing.Tooltip = 'Set voltage swing';
            app.editVoltageSwing.Value = '1';
            app.text12 = uilabel(app.PixelPanel);
            app.text12.Tag = 'text12';
            app.text12.Tooltip = 'also called saturation voltage';
            app.text12.Text = 'Volt Swing';
            app.txtDkCurrent = uilabel(app.PixelPanel);
            app.txtDkCurrent.Tag = 'txtDkCurrent';
            app.txtDkCurrent.Tooltip = 'millivolts/pixel/sec';
            app.txtDkCurrent.Text = 'mV/pixel/sec';
            app.text19 = uilabel(app.PixelPanel);
            app.text19.Tag = 'text19';
            app.text19.Tooltip = 'millivolts';
            app.text19.Text = 'mV';
            app.text20 = uilabel(app.PixelPanel);
            app.text20.Tag = 'text20';
            app.text20.Tooltip = 'microvolt/electron';
            app.text20.Text = 'uV/e-';
            app.text21 = uilabel(app.PixelPanel);
            app.text21.Tag = 'text21';
            app.text21.Tooltip = 'volts';
            app.text21.Text = 'V';
            app.pixelTable = uitable(app.PixelPanel);
            app.pixelTable.ColumnName = {'Parameter'; 'Value'};
            app.pixelTable.RowName = {};
            app.pixelTable.CellSelectionCallback = createCallbackFcn(app, @sensorDescription, true);
            app.txtReadNoise_2 = uilabel(app.PixelPanel);
            app.txtReadNoise_2.Tag = 'txtReadNoise';
            app.txtReadNoise_2.Tooltip = 'read noise std dev in mV (noise floor)';
            app.txtReadNoise_2.Text = 'Dark current';
            app.SensorPanel = uipanel(app.figure1);
            app.SensorPanel.Title = 'Sensor';
            app.ExposurePanel = uipanel(app.SensorPanel);
            app.ExposurePanel.Tag = 'ExposurePanel';
            app.btnShowCFAExpDurations = uibutton(app.ExposurePanel, 'push');
            app.btnShowCFAExpDurations.ButtonPushedFcn = createCallbackFcn(app, @btnShowCFAExpDurations_Callback, true);
            app.btnShowCFAExpDurations.Tag = 'btnShowCFAExpDurations';
            app.btnShowCFAExpDurations.Visible = 'off';
            app.btnShowCFAExpDurations.Tooltip = 'Show pattern of cfa exposures';
            app.btnShowCFAExpDurations.Text = 'CFA Exps';
            app.editExpTime = uieditfield(app.ExposurePanel, 'text');
            app.editExpTime.ValueChangedFcn = createCallbackFcn(app, @editExpTime_Callback, true);
            app.editExpTime.Tag = 'editExpTime';
            app.editExpTime.Tooltip = 'Set (central) exposure time ';
            app.editExpTime.Value = '0';
            app.txtExposureUnits = uilabel(app.ExposurePanel);
            app.txtExposureUnits.Tag = 'txtExposureUnits';
            app.txtExposureUnits.Text = '(ms)';
            app.editNExposures = uieditfield(app.ExposurePanel, 'text');
            app.editNExposures.ValueChangedFcn = createCallbackFcn(app, @editNExposures_Callback, true);
            app.editNExposures.Tag = 'editNExposures';
            app.editNExposures.Visible = 'off';
            app.editNExposures.Tooltip = 'Number of exposure times';
            app.editNExposures.Value = '3';
            app.editExpFactor = uieditfield(app.ExposurePanel, 'text');
            app.editExpFactor.ValueChangedFcn = createCallbackFcn(app, @editExpFactor_Callback, true);
            app.editExpFactor.Tag = 'editExpFactor';
            app.editExpFactor.Visible = 'off';
            app.editExpFactor.Tooltip = 'Scale factor between exposure times';
            app.editExpFactor.Value = '2';
            app.popupExpMode = uidropdown(app.ExposurePanel);
            app.popupExpMode.Items = {'Single', 'Bracketing', 'CFA Exposure'};
            app.popupExpMode.ValueChangedFcn = createCallbackFcn(app, @popupExpMode_Callback, true);
            app.popupExpMode.Tag = 'popupExpMode';
            app.popupExpMode.Tooltip = 'Select exposure mode';
            app.popupExpMode.Value = 'Single';
            app.AutoESwitchLabel = uilabel(app.ExposurePanel);
            app.AutoESwitchLabel.Text = 'Auto E';
            app.AutoESwitch = uiswitch(app.ExposurePanel, 'slider');
            app.AutoESwitch.ValueChangedFcn = createCallbackFcn(app, @switchAutoexposure, true);
            app.AutoESwitch.Tooltip = {'Auto exposure'};
            app.AutoESwitch.Value = 'On';
            app.editISARows = uieditfield(app.SensorPanel, 'text');
            app.editISARows.ValueChangedFcn = createCallbackFcn(app, @editISARows_Callback, true);
            app.editISARows.Tag = 'editISARows';
            app.editISARows.Tooltip = 'set sensor rows';
            app.editISARows.Value = '72';
            app.editISAcols = uieditfield(app.SensorPanel, 'text');
            app.editISAcols.ValueChangedFcn = createCallbackFcn(app, @editISAcols_Callback, true);
            app.editISAcols.Tag = 'editISAcols';
            app.editISAcols.Tooltip = 'set sensor cols';
            app.editISAcols.Value = '88';
            app.txtRow = uilabel(app.SensorPanel);
            app.txtRow.Tag = 'txtRow';
            app.txtRow.Tooltip = 'Adjust the number of pixels in the ISA';
            app.txtRow.Text = 'Sensor pixels  (r,c)';
            app.editOffsetFPN = uieditfield(app.SensorPanel, 'text');
            app.editOffsetFPN.ValueChangedFcn = createCallbackFcn(app, @editOffsetFPN_Callback, true);
            app.editOffsetFPN.Tag = 'editOffsetFPN';
            app.editOffsetFPN.Tooltip = 'Set dark signal non-uniformity level';
            app.editOffsetFPN.Value = '0';
            app.editGainFPN = uieditfield(app.SensorPanel, 'text');
            app.editGainFPN.ValueChangedFcn = createCallbackFcn(app, @editGainFPN_Callback, true);
            app.editGainFPN.Tag = 'editGainFPN';
            app.editGainFPN.Tooltip = 'Set pixel response non-uniformity';
            app.editGainFPN.Value = '0';
            app.txtFPN = uilabel(app.SensorPanel);
            app.txtFPN.Tag = 'txtFPN';
            app.txtFPN.Tooltip = 'Dark signal non-uniformity (Offset Std. Dev.)';
            app.txtFPN.Text = 'DSNU';
            app.text25 = uilabel(app.SensorPanel);
            app.text25.Tag = 'text25';
            app.text25.Tooltip = 'dark signal std. dev in millivolts';
            app.text25.Text = 'mv';
            app.txtPRNU = uilabel(app.SensorPanel);
            app.txtPRNU.Tag = 'txtPRNU';
            app.txtPRNU.Tooltip = 'Photoresponse non-uniformity (Gain Std. Dev.)';
            app.txtPRNU.Text = 'PRNU';
            app.txtMVperSec = uilabel(app.SensorPanel);
            app.txtMVperSec.Tag = 'txtMVperSec';
            app.txtMVperSec.Tooltip = 'percent st. dev. of photoresponse slopes';
            app.txtMVperSec.Text = '%';
            app.popISA = uidropdown(app.SensorPanel);
            app.popISA.Items = {'Bayer RGB', 'Bayer CMY', 'RGBW', 'Monochrome', 'Other'};
            app.popISA.ValueChangedFcn = createCallbackFcn(app, @popISA_Callback, true);
            app.popISA.Tag = 'popISA';
            app.popISA.Tooltip = 'Standard CFA  types';
            app.popISA.Value = 'Bayer RGB';
            app.popQuantization = uidropdown(app.SensorPanel);
            app.popQuantization.Items = {'Analog', '4 Bit', '8 Bit', '10 Bit', '12 Bit'};
            app.popQuantization.ValueChangedFcn = createCallbackFcn(app, @popQuantization_Callback, true);
            app.popQuantization.Tag = 'popQuantization';
            app.popQuantization.Tooltip = 'Set quantization method choice';
            app.popQuantization.Value = 'Analog';
            app.btnComputeImage = uibutton(app.SensorPanel, 'push');
            app.btnComputeImage.ButtonPushedFcn = createCallbackFcn(app, @btnComputeImage_Callback, true);
            app.btnComputeImage.Tag = 'btnComputeImage';
            app.btnComputeImage.Tooltip = 'Compute sensor  image from current optical image';
            app.btnComputeImage.Text = 'Compute Sensor  Image';
            app.sensorTable = uitable(app.SensorPanel);
            app.sensorTable.ColumnName = {'Parameter'; 'Value'};
            app.sensorTable.RowName = {};
            app.sensorTable.CellSelectionCallback = createCallbackFcn(app, @sensorDescription, true);
            app.ResizeDropDownLabel = uilabel(app.SensorPanel);
            app.ResizeDropDownLabel.Text = 'Resize';
            app.ResizeDropDown = uidropdown(app.SensorPanel);
            app.ResizeDropDown.Items = {'1/4', '1/2', '1', '2', '4', ''};
            app.ResizeDropDown.ValueChangedFcn = createCallbackFcn(app, @popResize, true);
            app.ResizeDropDown.Tooltip = {'Scale number of ros and cols, consistent with CFA pattern'};
            app.ResizeDropDown.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = sensorWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)sensorWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end