classdef pixelGeometryWindow_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        btnDone                 matlab.ui.control.Button
        PixeldimensionsumPanel  matlab.ui.container.Panel
        editHeight              matlab.ui.control.EditField
        editWidth               matlab.ui.control.EditField
        txtHeight               matlab.ui.control.Label
        txtWidth                matlab.ui.control.Label
        editFillFactor          matlab.ui.control.EditField
        txtFillFactor           matlab.ui.control.Label
        PixelgeometryLabel      matlab.ui.control.Label
    end

    
    methods (Access = private)
        function refresh(app,varargin)
            % Always applies to the currently selected sensor in the
            % sensorWindow;
            sensor = ieGetObject('sensor');
            if isempty(sensor)
                sensor = sensorCreate;
                ieAddObject(sensor);
            end
            
            % In the past, we either set the photodetector values manually
            % or by assuming they are centered and set by the fill factor
            %
            % Now, we only use the centered position and fill factor approach.
            % Some day we might allow editing the details of the PD
            % position.  But not now.
            % turnOffManual(app);
            % turnOnManual(app);
            
            PIXEL = sensorGet(sensor,'pixel');
            
            % Pixel parameters
            str = sprintf('%.1f',pixelGet(PIXEL,'height','um'));
            app.editHeight.Value = str;
            str = sprintf('%.1f',pixelGet(PIXEL,'width','um'));
            app.editWidth.Value = str;
            
            % Fill factor
            str = sprintf('%.0f',pixelGet(PIXEL,'fillfactor')*100);
            app.editFillFactor.Value = str;
            
            %{
            str = sprintf('%.2f',pixelGet(PIXEL,'widthGap','um'));
            app.editWidthGap.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'heightGap','um'));
            app.editHeightGap.Value = str;
            %}
            
            % Photodetector parameters
            %{
            str = sprintf('%.2f',pixelGet(PIXEL,'pdHeight','um'));
            app.editPDHeight.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdWidth','um'));
            app.editPDWidth.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdXpos','um'));
            app.editPDXPos.Value = str;
            str = sprintf('%.2f',pixelGet(PIXEL,'pdYpos','um'));
            app.editPDYpos.Value = str;
            %}            
        end
        
        function turnOffManual(app)
            % Turn off everything except the fill factor
            % app.txtHeightPosition.Visible = 'off';
            % app.txtWidthPosition.Visible  = 'off';
            
            % Turn on the centered detector based on fill factor.
            app.txtFillFactor.Visible   = 'on';
            app.editFillFactor.Visible = 'on';
            
        end
        
        function turnOnManual(app)
            % Put all the PD variables there.
            app.txtPhotodetector.Visible  = 'on';
            app.txtHeightPosition.Visible = 'on';
            app.txtWidthPosition.Visible  = 'on';
            
            % And turn off the centered detector
            app.txtFillFactor.Visible = 'off';
            app.txtPrct.Visible       = 'off';
            app.editFillFactor.Visible = 'off';            
        end
        
        
        function pixelGeometryClose(app)
            % Bring up a refreshed sensor window with the new parameters
            sensorW = sensorWindow();
            sensorW.refresh;
            
            % Close this window.
            delete(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function pixelGeometryWindow_OpeningFcn(app, varargin)
            % ieFontInit(app);
            
            % Bring this figure to the front
            app.refresh;
        end

        % Callback function: btnDone, figure1
        function btnDone_Callback(app, event)
            app.pixelGeometryClose;
        end

        % Value changed function: editFillFactor
        function editFillFactor_Callback(app, event)
            % Use the fill factor value to set up a centered photodetector position
            sensor = ieGetObject('sensor');
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Callback function
        function editHeightGap_Callback(app, event)

            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel height gap',app.editHeightGap.Value*(1e-6));

            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Value changed function: editHeight
        function editHeight_Callback(app, event)
           
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel height',str2double(app.editHeight.Value)*(1e-6));
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Callback function
        function editPDHeight_Callback(app, event)
          
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel pd height',str2double(app.editPDHeight.Value)*(1e-6));
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function editPDWidth_Callback(app, event)
           
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel pd width',str2double(app.editPDWidth.Value)*(1e-6));
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            app.refresh;
        end

        % Callback function
        function editPDXPos_Callback(app, event)
          
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pd xpos',str2double(app.editPDXPos.Value)*(1e-6));
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function editPDYpos_Callback(app, event)
           
            sensor = ieGetObject('sensor');
            
            sensor = sensorSet(sensor,'pd ypos',str2double(app.editPDYpos.Value)*(1e-6));
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Callback function
        function editWidthGap_Callback(app, event)
            sensor = ieGetObject('sensor');
            
            sensor = sensorSet(sensor,'pixel width gap',str2double(app.editWidthGap.Value)*(1e-6));
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            app.refresh;
            
        end

        % Value changed function: editWidth
        function editWidth_Callback(app, event)
            
            sensor = ieGetObject('sensor');
            sensor = sensorSet(sensor,'pixel width',str2double(app.editWidth.Value)*(1e-6));
            fillfactor = str2double(app.editFillFactor.Value)/100;
            sensor = pixelCenterFillPD(sensor,fillfactor);
            ieReplaceObject(sensor);
            ieReplaceObject(sensor);
            app.refresh;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'pixelGeometryWindow';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.figure1.Tag = 'figure1';
            app.btnDone = uibutton(app.figure1, 'push');
            app.btnDone.ButtonPushedFcn = createCallbackFcn(app, @btnDone_Callback, true);
            app.btnDone.Tag = 'btnDone';
            app.btnDone.Text = 'Done';
            app.PixeldimensionsumPanel = uipanel(app.figure1);
            app.PixeldimensionsumPanel.Title = 'Pixel dimensions (um)';
            app.editHeight = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editHeight.ValueChangedFcn = createCallbackFcn(app, @editHeight_Callback, true);
            app.editHeight.Tag = 'editHeight';
            app.editHeight.Value = '2.8';
            app.editWidth = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editWidth.ValueChangedFcn = createCallbackFcn(app, @editWidth_Callback, true);
            app.editWidth.Tag = 'editWidth';
            app.editWidth.Value = '2.8';
            app.txtHeight = uilabel(app.PixeldimensionsumPanel);
            app.txtHeight.Tag = 'txtHeight';
            app.txtHeight.Text = 'Height';
            app.txtWidth = uilabel(app.PixeldimensionsumPanel);
            app.txtWidth.Tag = 'txtWidth';
            app.txtWidth.Text = 'Width';
            app.editFillFactor = uieditfield(app.PixeldimensionsumPanel, 'text');
            app.editFillFactor.ValueChangedFcn = createCallbackFcn(app, @editFillFactor_Callback, true);
            app.editFillFactor.Tag = 'edit1FillFactor';
            app.editFillFactor.Tooltip = 'Set percentage of pixel area occupied by the detector';
            app.editFillFactor.Value = '75';
            app.txtFillFactor = uilabel(app.PixeldimensionsumPanel);
            app.txtFillFactor.Tag = 'txtFillFactor';
            app.txtFillFactor.Tooltip = 'Percentage of pixel area occupied by the detector';
            app.txtFillFactor.Text = 'Fill factor';
            app.PixelgeometryLabel = uilabel(app.figure1);
            app.PixelgeometryLabel.Text = 'Pixel geometry';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pixelGeometryWindow_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)pixelGeometryWindow_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end