classdef ieSetProperties < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        UITable               matlab.ui.control.Table
        LoadPropertiesButton  matlab.ui.control.Button
        SavePropertiesButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Need a switch for who called us, just do OI for now!
            
            app.UITable.ColumnEditable = [false true];
            
            oi = ieGetObject('oi');
            iePTable(oi,'uitable',app.UITable,'format','embed');


        end

        % Button pushed function: SavePropertiesButton
        function SavePropertiesButtonPushed(app, event)
            ourData = app.UITable.Data;
            
            % see what our JSON data looks like:
            outputString = jsonencode(array2table(ourData));
            [saveFileName, saveFilePath] = uiputfile("*.json","Save Properties");
            saveFile = fopen(fullfile(saveFilePath, saveFileName), "w");
            fprintf(saveFile, outputString);
            fclose(saveFile);
            
        end

        % Button pushed function: LoadPropertiesButton
        function LoadPropertiesButtonPushed(app, event)
                       
            % see what our JSON data looks like:
            [loadFileName, loadFilePath] = uigetfile("*.json","Load Properties");
            stringData = fileread(fullfile(loadFilePath, loadFileName));
            ourData = jsondecode(stringData);
            app.UITable.Data = transpose(struct2cell(ourData));

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.LoadPropertiesButton = uibutton(app.UIFigure, 'push');
            app.LoadPropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPropertiesButtonPushed, true);
            app.LoadPropertiesButton.Text = 'Load Properties ...';
            app.SavePropertiesButton = uibutton(app.UIFigure, 'push');
            app.SavePropertiesButton.ButtonPushedFcn = createCallbackFcn(app, @SavePropertiesButtonPushed, true);
            app.SavePropertiesButton.Text = 'Save Properties...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ieSetProperties

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end