classdef plotSpectralImage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        WavelengthLabel  matlab.ui.control.Label
        waveSlider       matlab.ui.control.Slider
        imageAxis        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        wave % N x 1 description of wavelengths
        data % (row, col, N) radiance or irradiance image data
    end
    
    methods (Access = private)
        
        function spectralImage(app)
            % First draft.  Something like this
            % Find a color and then create the wavelength image in the
            % image.
            
            % Read the Slider and figure out the wavelength
            thisWave  = app.waveSlider.Value;
            img = app.data(:,:,app.wave==thisWave);
            img = img/max(img(:));
            
            % Figure out a reasonable color for this wavelength
            tmp = double(app.wave==thisWave);
            tmp = reshape(tmp,1,1,numel(app.wave));
            rgb = xyz2srgb(ieXYZFromEnergy(tmp,app.wave));
            rgb = squeeze(rgb); rgb = rgb/max(rgb(:));
            sz = size(img);
            % Make an rgb image this color
            thisImg = reshape(img(:)*rgb(:)',sz(1),sz(2),3);

            
            image(app.imageAxis, thisImg);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)
            % Decide if the input is a scene or an oi
            % Store the wavelengths and radiance or irradiance data in the
            % app
            switch obj.type
                case 'scene'
                    app.wave = double(sceneGet(obj,'wave'));
                    app.data = double(sceneGet(obj,'photons'));
                    app.imageAxis.Title.String = 'Radiance (quanta)';
                case 'opticalimage'
                    app.wave = double(oiGet(obj,'wave'));
                    app.data = double(oiGet(obj,'photons'));
                    app.imageAxis.Title.String = 'Irradiance (quanta)';
                otherwise
                    error('Input must be a scene or an optical image.');
            end
            
            % Adjust the slider based on the wavelength range of the obj
            app.waveSlider.Limits = double([app.wave(1),app.wave(end)]);
            app.waveSlider.MajorTicks = linspace(app.wave(1),app.wave(end),5);
            app.waveSlider.MinorTicks = [];

            axis(app.imageAxis,'tight');

            % Put a middle wavelength image into the window
            midWave = round(numel(app.wave)/2);
            app.waveSlider.Value = double(app.wave(midWave));  % Find a method for this number
            
            % From now on, everything is a callback.
            spectralImage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WavelengthLabel = uilabel(app.UIFigure);
            app.WavelengthLabel.Text = 'Wavelength';
            app.waveSlider = uislider(app.UIFigure);
            app.imageAxis = uiaxes(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotSpectralImage(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end