classdef webImageBrowser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ViewerPanel                     matlab.ui.container.Panel
        HeaderPanel                     matlab.ui.container.Panel
        ISETImageFinderLabel            matlab.ui.control.Label
        SelectSourceDropDownLabel       matlab.ui.control.Label
        SelectSourceDropDown            matlab.ui.control.DropDown
        EnterkeywordsLabel              matlab.ui.control.Label
        EnterkeywordsEditField          matlab.ui.control.EditField
        SearchButton                    matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        FooterPanel                     matlab.ui.container.Panel
        ClickonanimagetoopenasanISETSceneLabel  matlab.ui.control.Label
        OpenFolderinImageBrowserButton  matlab.ui.control.Button
        LoadLocalFolderButton           matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        TitleMenu                       matlab.ui.container.Menu
        DescriptionMenu                 matlab.ui.container.Menu
    end

    
    properties (Access= public)
        ImageArray matlab.ui.control.Image
    end
    properties (Access = private)
        fname
        imgGridColumns
        imgGridRows
        currentFolder
        currentImageFiles; % want array of loaded images, but no native image object
        desireThumbnailSize = 100; % pixels in each dimension
        imageSpacer = 10;
        blankimage=zeros(20,20,3); % seems like there should be a better way to clear an image!!
        dataType = ''; % set to the type of data we are likely to be bringing back!
        flickrObj; % our handle to use Flickr
        hyperObj; % our handle to look for Hyperspectral data
        hdrObj; % handle for HDR data
    end
    
    methods (Access = private)
        function ImageClickedCallback(app, event)
            persistent inImageClicked;
            if inImageClicked == true
                uialert(app.UIFigure,"Already loading a scene...", "Extra click?");
            else
                inImageClicked = true;
            end
            switch app.dataType
                case 'Flickr'
                    imageData = app.flickrObj.getImage(event.Source.UserData, 'large');
                    scene = sceneFromFile(imageData,'rgb');
                    scene = sceneSet(scene, 'name', event.Source.UserData.title);
                    scene = sceneAdjustIlluminant(scene,'D65.mat');
                    
                case 'Hyperspectral'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message','Multi-spectral images can be large, so be patient.');
                    % we can't load hyperspectral data directly, as it
                    % doesn't come in as the correct type
                    %imageData = app.hyperObj.getImage(event.Source.UserData, 'large');
                    % for some reason certain images don't get the proper
                    % object
                    try
                        imageDataURL = app.hyperObj.getImageURL(event.Source.UserData, 'large');
                        sceneFile = websave(event.Source.UserData.Name, imageDataURL);
                        scene = sceneFromFile(sceneFile,'multispectral');
                        scene = sceneSet(scene, 'name', event.Source.UserData.Name);
                        delete(sceneFile);
                    catch
                        warning("Unable to resolve image URL.");
                    end
                    close(dlg);
                case 'HDR'
                    dlg = uiprogressdlg(app.UIFigure,'Title','Loading image...','Message','HDR images can be large, so be patient.');
                    % we can't load hyperspectral data directly, as it
                    % doesn't come in as the correct type
                    %imageData = app.hyperObj.getImage(event.Source.UserData, 'large');
                    imageDataURL = app.hdrObj.getImageURL(event.Source.UserData, 'large');
                    sceneFile = websave(event.Source.UserData.Name, imageDataURL);
                    scene = sceneFromFile(sceneFile,'multispectral');
                    scene = sceneSet(scene, 'name', event.Source.UserData.Name);
                    close(dlg);
                    delete(sceneFile); % I think it is okay to remove now
                    
                otherwise % case 'File'
                scene = sceneFromFile(event.Source.ImageSource,'rgb');
                scene = sceneAdjustIlluminant(scene,'D65.mat');
            end
          
            sceneWindow(scene);
            inImageClicked = false; 
        end

        % legacy
        function updateimage(app,imagefile)
            
            try
                im = imread(imagefile);
                app.fname = imagefile;
                app.ClickonanimagetoopenasanISETSceneLabel.Text = imagefile;
            catch ME
                    % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end                     
        end
        
        function [imageWidth, imageHeight] =  calcImageSize(app)
            gridSize = app.ViewerPanel.InnerPosition;
            gridWidth = gridSize(3);
            gridHeight = gridSize(4);
            app.imgGridRows = floor(gridHeight / app.desireThumbnailSize);
            app.imgGridColumns = floor(gridWidth / app.desireThumbnailSize);
            app.imageSpacer = 10; % In Case
            imageWidth = floor(gridWidth / app.imgGridColumns) - app.imageSpacer;
            imageHeight = floor(gridHeight / app.imgGridRows) - app.imageSpacer;
        end
        
        function [imageFiles] = loadImages(app)
            jpegFiles = dir(fullfile(app.currentFolder,'*.jpg'));
            tiffFiles = dir(fullfile(app.currentFolder,'*.tif'));
            pngFiles = dir(fullfile(app.currentFolder,'*.png'));
            
            % there must be an easier way to sort out all image files!!
            %tiffFiles = dir(ourDir,'*.tif');
            imageFiles = [jpegFiles,tiffFiles,pngFiles]; % try to simply concat
%            for i = 1:length(jpegFiles)
%                loadedImages(i) = imread(fullfile(jpegFiles(i).folder, jpegFiles(i).name)); 
%            end
        end
        
        function populateBrowser(app, imageFileList, reload)
                        
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(imageFileList) / app.imgGridColumns);

            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading folder...','Message',app.currentFolder);
                % clear does nothing, doesn't seem to be a way to reset!
                %clear app.ImageArray;
                for i = 1:length(app.ImageArray)
                    % can we empty the thing?
                    app.ImageArray(i).ImageSource = app.blankimage; % fingers crossed
                end
                for i = 1:length(imageFileList)
                    app.ImageArray(i) = uiimage(app.ViewerPanel);
                    % try stashing the photo object or the filepath in the
                    % UserData instead of just the index into the array
                    app.ImageArray(i).UserData = fullfile(imageFileList(i).folder,imageFileList(i).name);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);                    
                    ourImageFile = fullfile(imageFileList(i).folder,imageFileList(i).name); 
                    app.ImageArray(i).Tooltip = imageFileList(i).name;
                    app.ImageArray(i).ImageSource = ourImageFile;
                end
                close(dlg);

            end
            redrawGrid(app, imageWidth, imageHeight, ourImageArray);
        end

        % should be melded with other populates once we figure them out!
        % imageArray is the Photos struct we have gotten back from Flickr
        function flickrPopulateBrowser(app, ourImageArray, reload)
                        
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(ourImageArray.photo) / app.imgGridColumns);

            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading Images from Flickr...','Message','Search Results');

                for i = 1:length(app.ImageArray)
                    % can we empty the thing?
                    app.ImageArray(i).ImageSource = app.blankimage; % fingers crossed
                    app.ImageArray(i).UserData = [];
                    app.ImageArray(i).Visible = false;
                end
                for i = 1:length(ourImageArray.photo)
                    if i > length(app.ImageArray)
                        app.ImageArray(i) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(i).UserData = ourImageArray.photo(i);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);                    
                    app.ImageArray(i).Tooltip = app.flickrObj.getImageTitle(ourImageArray.photo(i));
                    ourImageURL = app.flickrObj.getImageURL(ourImageArray.photo(i), 'thumbnail');
                    app.ImageArray(i).Visible = true;
                    try
                        ourImage = webread(ourImageURL);
                    catch
                        warning("Can't get image!")
                    end
                    if ndims(ourImage) == 2
                        % probably grayscale and image control needs
                        % 'color'
                        ourImage = cat(3, ourImage, ourImage, ourImage);
                    end
                    app.ImageArray(i).ImageSource = ourImage;
                    % NOT KEEPING META DATA AROUND YET, BUT SHOULD SHOW
                    % THUMBNAILS?
                end
                close(dlg);

            end
            redrawGrid(app, imageWidth, imageHeight, length(ourImageArray.photo));
        end

        % should be melded with other populates once we figure them out!
        % imageArray is the Photos struct we have gotten back from Flickr
        function hdrPopulateBrowser(app, ourImageArray, reload)
                        
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(ourImageArray) / app.imgGridColumns);

            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading HDR Scenes ...','Message','Search Results');

                for i = 1:length(app.ImageArray)
                    % can we empty the thing?
                    app.ImageArray(i).ImageSource = app.blankimage; % fingers crossed
                    app.ImageArray(i).UserData = [];
                    app.ImageArray(i).Visible = false;
                end
                for i = 1:length(ourImageArray)
                    if i > length(app.ImageArray)
                        app.ImageArray(i) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(i).UserData = ourImageArray(i);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);                    
                    app.ImageArray(i).Tooltip = app.hdrObj.getImageTitle(ourImageArray(i));
                    ourImageURL = app.hdrObj.getImageURL(ourImageArray(i), 'thumbnail');
                    try
                        ourImage = webread(ourImageURL,'ContentType','image');
                    catch
                        warning(strcat("Can't get image: ", ourImageURL));
                    end
                    if ndims(ourImage) == 2
                        % probably grayscale and image control needs
                        % 'color'
                        ourImage = cat(3, ourImage, ourImage, ourImage);
                    end
                    app.ImageArray(i).ImageSource = ourImage;
                    % NOT KEEPING META DATA AROUND YET, BUT SHOULD SHOW
                    % THUMBNAILS?
                end
                close(dlg);

            end
            redrawGrid(app, imageWidth, imageHeight, length(ourImageArray));
        end
        
        % should be melded with other populates once we figure them out!
        % imageArray is the Photos struct we have gotten back from Flickr
        function hyperPopulateBrowser(app, ourImageArray, reload)
                        
            % we want to reset grid rows to match total available images
            [imageWidth, imageHeight] = calcImageSize(app);
            %over-ride rows for now
            app.imgGridRows = ceil(length(ourImageArray) / app.imgGridColumns);

            if reload % new folder, need to create images
                dlg = uiprogressdlg(app.UIFigure,'Title','Loading Hyperspectral Scenes ...','Message','Search Results');

                for i = 1:length(app.ImageArray)
                    % can we empty the thing?
                    app.ImageArray(i).ImageSource = app.blankimage; % fingers crossed
                    app.ImageArray(i).UserData = [];
                    app.ImageArray(i).Visible = false;
                end
                for i = 1:length(ourImageArray)
                    if i > length(app.ImageArray)
                        app.ImageArray(i) = uiimage(app.ViewerPanel);
                    end
                    app.ImageArray(i).UserData = ourImageArray(i);
                    app.ImageArray(i).ImageClickedFcn = createCallbackFcn(app, @ImageClickedCallback, true);                    
                
                    ourImageURL = app.hyperObj.getImageURL(ourImageArray(i), 'thumbnail');
                    app.ImageArray(i).Tooltip = app.hyperObj.getImageTitle(ourImageArray(i));
                    try
                        ourImage = webread(ourImageURL,'ContentType','image');
                    catch
                        warning(strcat("Can't get image: ", ourImageURL));
                    end
                    if ndims(ourImage) == 2
                        % probably grayscale and image control needs
                        % 'color'
                        ourImage = cat(3, ourImage, ourImage, ourImage);
                    end
                    app.ImageArray(i).ImageSource = ourImage;
                    % NOT KEEPING META DATA AROUND YET, BUT SHOULD SHOW
                    % THUMBNAILS?
                end
                close(dlg);

            end
            redrawGrid(app, imageWidth, imageHeight, length(ourImageArray));
        end

        % djc -- if we now have less images, grid may be too large or start
        % from the bottom left instead of the top left. Annoying:)
        function redrawGrid(app, imageWidth, imageHeight, numImages)
            index = 1;
            for i = app.imgGridRows:-1:1
                for j = 1:app.imgGridColumns
                    if index <= numImages
                        % allocate new image control if needed
                        app.ImageArray(index).Position = [app.imageSpacer+(j-1)*(imageWidth+app.imageSpacer) app.imageSpacer+(i-1)*(imageHeight+app.imageSpacer) imageWidth imageHeight];
                        app.ImageArray(index).Visible = true;
                        index = index + 1;
                    end
                end
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % initialize until we figure out what we're doing
            [imageWidth, imageHeight] = calcImageSize(app);
            % hack to set blankimage to background gray
            app.blankimage(:,:,1:3) = app.ViewerPanel.BackgroundColor(1);

        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Callback function
        function LoadButtonPushed(app, event)
            % legacy for now, as it only loads a single file   
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
               app.fname = [p f];
               updateimage(app, app.fname);
            end
        end

        % Callback function
        function OpeninISETButtonPushed(app, event)
            %default illuminant is wrong sceneAdjustIlluminant instead
            scene = sceneFromFile(app.fname,'rgb');
            ieAddObject(scene); sceneWindow;
        end

        % Button pushed function: OpenFolderinImageBrowserButton
        function OpenFolderinImageBrowserButtonPushed(app, event)
            % djc hack to get a random folder
            if isdeployed
                uialert(app.UIFigure, 'Image Browser not supported in runtime. Sorry.', 'Image Browser not Available');
            else
                imageBrowser(app.currentFolder);            
            end

        end

        % Button pushed function: LoadLocalFolderButton
        function LoadLocalFolderButtonPushed(app, event)
            app.dataType = 'File';
            ourDir = uigetdir(); % can give it a default
            app.currentFolder = ourDir;
            app.currentImageFiles = loadImages(app); 
            
            populateBrowser(app, app.currentImageFiles, true);
        end

        % Callback function
        function UIFigureButtonDown(app, event)
            uialert(app.UIFigure,'Figure Button down called.', "Okay?")
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            uialert(app.UIFigure,'Image Click called.', "Okay?")
            
        end

        % Size changed function: ViewerPanel
        function ViewerPanelSizeChanged(app, event)
            % for some reason the panel doesn't downsize when the window
            % gets smaller, so I think we need to do it manually!
            ourBorder = 30;
            persistent savedPanelPosition;
            persistent savedWindowPosition;
            
            
            if isvalid(app.ViewerPanel) % we also get called after it is deleted
 
                if isempty(savedPanelPosition)
                    savedPanelPosition = [0 0 0 0];
                end
                if isempty(savedWindowPosition)
                    savedWindowPosition = [0 0 0 0];
                end
                panelPosition = app.ViewerPanel.Position;
                windowPosition = app.UIFigure.Position;
                % check if either our w/h or Window w/h have changed:
                if not(isequal(panelPosition(3), savedPanelPosition(3))) || ...
                    not(isequal(panelPosition(4), savedPanelPosition(4))) || ...
                    not(isequal(windowPosition(3), savedWindowPosition(3))) || ...
                    not(isequal(windowPosition(4), savedWindowPosition(4)))
                    savedPanelPosition = panelPosition; % keep to compare next time
                    savedWindowPosition = windowPosition; % keep to compare next time
                    %if position(3) > app.UIFigure.Position(3) - ourBorder
                    position(3) = app.UIFigure.Position(3) - ourBorder; % hack:)
                    app.ViewerPanel.Position(3) = position(3);
                    %end
                    %if position(4) > app.UIFigure.Position(4) - ourBorder
                    %    position(4) = app.UIFigure.Position(4) - ourBorder; % hack:)
                    %    app.ViewerPanel.Position(4) = position(4);
                    %end
                    if ~isempty(app.ImageArray) % first time we get called image controls haven't been created yet
                        % only want to repaint if we have changed the number of
                        % rows or columns
                        prevRows = app.imgGridRows; prevColumns = app.imgGridColumns;
                        [imageWidth, imageHeight] = calcImageSize(app);
                        if app.imgGridColumns ~= prevColumns || ...
                            app.imgGridRows ~= prevRows
                            populateBrowser(app, app.currentImageFiles, false);
                        end
                    end
                end
            end
        end

        % Size changed function: UIFigure
        function UIFigureSizeChanged(app, event)
            position = app.UIFigure.Position;
            % we call this proactively in case we don't get resized
            % Automatically. It caches, so won't redraw if not changed
            ViewerPanelSizeChanged(app, event);
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            switch(app.SelectSourceDropDown.Value)
                case "Flickr"
                    app.dataType = 'Flickr';
                    if ~exist('app.flickrObj', "var")
                        app.flickrObj = webFlickr();
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    resultList = app.flickrObj.search(ourTags);
                    % now we need to populate our browser with the results!
                    if ~isempty(resultList.photos.photo)
                        flickrPopulateBrowser(app, resultList.photos, true);
                    else
                        uialert(app.UIFigure,"No search results from Flickr.","Flickr search...");
                    end
                case "Hyperspectral"
                    app.dataType = 'Hyperspectral';
                    if ~exist('app.hyperObj', "var")
                        app.hyperObj = webData('Hyperspectral');
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    if isempty(ourTags)
                        ourTags = " ";
                    end
                    resultList = app.hyperObj.search(ourTags);
                    if ~isempty(resultList)
                        hyperPopulateBrowser(app, resultList, true);
                    else
                        uialert(app.UIFigure,"No matching Hyperspectral images found.","Hyperspectral search...");
                    end

                case "HDR"
                    app.dataType = 'HDR'
                    if ~exist('app.hdrObj', "var")
                        app.hdrObj = webData('HDR');
                    end
                    ourTags = app.EnterkeywordsEditField.Value;
                    if isempty(ourTags)
                        ourTags = " ";
                    end
                    resultList = app.hdrObj.search(ourTags);
                    if ~isempty(resultList)
                        hdrPopulateBrowser(app, resultList, true);
                    else
                        uialert(app.UIFigure,"No matching HDR images found.","HDR search...");
                    end

                otherwise
                    warning("Service not supported.")
            end
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            
        end

        % Menu selected function: DescriptionMenu
        function DescriptionMenuSelected(app, event)
            
        end

        % Menu selected function: TitleMenu
        function TitleMenuSelected(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'ISET Image Finder';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @UIFigureSizeChanged, true);
            app.ViewerPanel = uipanel(app.UIFigure);
            app.ViewerPanel.SizeChangedFcn = createCallbackFcn(app, @ViewerPanelSizeChanged, true);
            app.HeaderPanel = uipanel(app.UIFigure);
            app.ISETImageFinderLabel = uilabel(app.HeaderPanel);
            app.ISETImageFinderLabel.Text = 'ISET Image Finder';
            app.SelectSourceDropDownLabel = uilabel(app.HeaderPanel);
            app.SelectSourceDropDownLabel.Text = 'Select Source';
            app.SelectSourceDropDown = uidropdown(app.HeaderPanel);
            app.SelectSourceDropDown.Items = {'Flickr', 'Hyperspectral', 'HDR', ''};
            app.SelectSourceDropDown.Tooltip = {'The source or type of images and scenes that can be used by ISET that you want to find.'};
            app.SelectSourceDropDown.Value = 'Flickr';
            app.EnterkeywordsLabel = uilabel(app.HeaderPanel);
            app.EnterkeywordsLabel.Text = 'Enter keywords (,)';
            app.EnterkeywordsEditField = uieditfield(app.HeaderPanel, 'text');
            app.EnterkeywordsEditField.Tooltip = {'Separate keywords with commas. Images containing all keywords will be returned.'};
            app.SearchButton = uibutton(app.HeaderPanel, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.Image = uiimage(app.HeaderPanel);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.Enable = 'off';
            app.Image.Visible = 'off';
            app.Image.Tooltip = {'Ignore me:)'};
            app.FooterPanel = uipanel(app.UIFigure);
            app.ClickonanimagetoopenasanISETSceneLabel = uilabel(app.FooterPanel);
            app.ClickonanimagetoopenasanISETSceneLabel.Text = 'Click on an image to open as an ISET Scene';
            app.OpenFolderinImageBrowserButton = uibutton(app.FooterPanel, 'push');
            app.OpenFolderinImageBrowserButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFolderinImageBrowserButtonPushed, true);
            app.OpenFolderinImageBrowserButton.Tooltip = {'Once you have a local folder open, you can also manipulate them using Matlab''s built-in image browser.'};
            app.OpenFolderinImageBrowserButton.Text = 'Open Folder in Image Browser';
            app.LoadLocalFolderButton = uibutton(app.FooterPanel, 'push');
            app.LoadLocalFolderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLocalFolderButtonPushed, true);
            app.LoadLocalFolderButton.Tooltip = {'You can also browse your local file system for a folder of images.'};
            app.LoadLocalFolderButton.Text = 'Load Local Folder';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            % Assign app.ContextMenu
            app.Image.ContextMenu = app.ContextMenu;
            app.TitleMenu = uimenu(app.ContextMenu);
            app.TitleMenu.MenuSelectedFcn = createCallbackFcn(app, @TitleMenuSelected, true);
            app.TitleMenu.Text = 'Title';
            app.DescriptionMenu = uimenu(app.ContextMenu);
            app.DescriptionMenu.MenuSelectedFcn = createCallbackFcn(app, @DescriptionMenuSelected, true);
            app.DescriptionMenu.Text = 'Description';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = webImageBrowser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end